classdef coffeecupalert < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    LongDivinerRunUIFigure  matlab.ui.Figure
    GridLayout              matlab.ui.container.GridLayout
    DivinerWarningPanel     matlab.ui.container.Panel
    GridLayout2             matlab.ui.container.GridLayout
    OKButton                matlab.ui.control.Button
    NevershowagainCheckBox  matlab.ui.control.CheckBox
    Image                   matlab.ui.control.Image
    Label                   matlab.ui.control.Label
  end


  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.Image.ImageSource = gettbicons('coffee');
      set(app.LongDivinerRunUIFigure,'Resize','off');
      app.NevershowagainCheckBoxValueChanged;
    end

    % Button pushed function: OKButton
    function OKButtonPushed(app, event)
      close(app.LongDivinerRunUIFigure);
    end

    % Value changed function: NevershowagainCheckBox
    function NevershowagainCheckBoxValueChanged(app, event)
      value = app.NevershowagainCheckBox.Value;
      if value
        setplspref('diviner','showlongrun',0);
      else
        setplspref('diviner','showlongrun',1);
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.LongDivinerRunUIFigure = uifigure('Visible', 'off');
      app.LongDivinerRunUIFigure.Name = 'Long Diviner Run';
      app.GridLayout = uigridlayout(app.LongDivinerRunUIFigure);
      app.DivinerWarningPanel = uipanel(app.GridLayout);
      app.DivinerWarningPanel.Title = 'Diviner Warning';
      app.GridLayout2 = uigridlayout(app.DivinerWarningPanel);
      app.OKButton = uibutton(app.GridLayout2, 'push');
      app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
      app.OKButton.Text = 'OK';
      app.NevershowagainCheckBox = uicheckbox(app.GridLayout2);
      app.NevershowagainCheckBox.ValueChangedFcn = createCallbackFcn(app, @NevershowagainCheckBoxValueChanged, true);
      app.NevershowagainCheckBox.Text = 'Never show again';
      app.Image = uiimage(app.GridLayout2);
      app.Label = uilabel(app.GridLayout2);
      app.Label.Text = 'Looks like this run is going to take awhile. Feel free to grab some coffee!';
      % Show the figure after all components are created
      app.LongDivinerRunUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = coffeecupalert

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.LongDivinerRunUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.LongDivinerRunUIFigure)
    end
  end
end