classdef diviner_guifcn < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    Diviner                       matlab.ui.Figure
    FileMenu                      matlab.ui.container.Menu
    LoadDataMenu                  matlab.ui.container.Menu
    CalibrationXMenu              matlab.ui.container.Menu
    CalibrationYMenu              matlab.ui.container.Menu
    LoadCalXandYMenu              matlab.ui.container.Menu
    ValidationXMenu               matlab.ui.container.Menu
    ValidationYMenu               matlab.ui.container.Menu
    LoadValXandYMenu              matlab.ui.container.Menu
    LoadResultsMenu               matlab.ui.container.Menu
    SaveResultsMenu               matlab.ui.container.Menu
    ClearMenu                     matlab.ui.container.Menu
    ClearCalibrationXMenu         matlab.ui.container.Menu
    ClearCalibrationYMenu         matlab.ui.container.Menu
    ClearCalXandYMenu             matlab.ui.container.Menu
    ClearValidationXMenu          matlab.ui.container.Menu
    ClearValidationYMenu          matlab.ui.container.Menu
    ClearValXandYMenu             matlab.ui.container.Menu
    EditMenu                      matlab.ui.container.Menu
    CalibrationMenu               matlab.ui.container.Menu
    OpenXblockMenu                matlab.ui.container.Menu
    PlotXblockMenu                matlab.ui.container.Menu
    OpenYblockMenu                matlab.ui.container.Menu
    PlotYblockMenu                matlab.ui.container.Menu
    EmenuSelectYColumnMenu        matlab.ui.container.Menu
    ValidationMenu                matlab.ui.container.Menu
    OpenXblockMenu_2              matlab.ui.container.Menu
    PlotXblockMenu_2              matlab.ui.container.Menu
    OpenYblockMenu_2              matlab.ui.container.Menu
    PlotYblockMenu_2              matlab.ui.container.Menu
    OptionsMenu                   matlab.ui.container.Menu
    PreferencesMenu               matlab.ui.container.Menu
    DisplayWarningforLongRunMenu  matlab.ui.container.Menu
    longrunYesMenu                matlab.ui.container.Menu
    longrunNoMenu                 matlab.ui.container.Menu
    ToolsMenu                     matlab.ui.container.Menu
    PreprocessingMenu             matlab.ui.container.Menu
    CrossValidationMenu           matlab.ui.container.Menu
    OutlierDetectionMenu          matlab.ui.container.Menu
    ViewMenu                      matlab.ui.container.Menu
    DivinerCacheFolderMenu        matlab.ui.container.Menu
    DivinerCacheFolderforCurrentRunMenu  matlab.ui.container.Menu
    ThemeMenu                     matlab.ui.container.Menu
    LightMenu                     matlab.ui.container.Menu
    DarkMenu                      matlab.ui.container.Menu
    GridLayout                    matlab.ui.container.GridLayout
    SessionSetupPanel             matlab.ui.container.Panel
    GridLayout3                   matlab.ui.container.GridLayout
    CalibrationLabel              matlab.ui.control.Label
    ValidationLabel               matlab.ui.control.Label
    PreprocessingButton           matlab.ui.control.Button
    CrossValidationButton         matlab.ui.control.Button
    CalXButton                    matlab.ui.control.Button
    CalYButton                    matlab.ui.control.Button
    ValXButton                    matlab.ui.control.Button
    ValYButton                    matlab.ui.control.Button
    OutlierDetectionLabel         matlab.ui.control.Label
    OutlierSwitch                 matlab.ui.control.Switch
    OutputPanel                   matlab.ui.container.Panel
    GridLayout2                   matlab.ui.container.GridLayout
    ResultsHTML                   matlab.ui.control.HTML
    OutlierImageButton            matlab.ui.control.Button
    ResultsTableImageButton       matlab.ui.control.Button
    ScoresImageButton             matlab.ui.control.Button
    VariableConsensusImageButton  matlab.ui.control.Button
    InfoPanel                     matlab.ui.container.Panel
    GridLayout4                   matlab.ui.container.GridLayout
    TextArea                      matlab.ui.control.TextArea
    DIVINERLabel                  matlab.ui.control.Label
    ProgressPanel                 matlab.ui.container.Panel
    GridLayout5                   matlab.ui.container.GridLayout
    NextStepText                  matlab.ui.control.Label
    NextStepLabel                 matlab.ui.control.Label
    CurrentStepText               matlab.ui.control.Label
    CurrentStepLabel              matlab.ui.control.Label
    ProgressHTML                  matlab.ui.control.HTML
    HelpImageButton               matlab.ui.control.Button
    BrowseImageButton             matlab.ui.control.Button
    LoadImageButton               matlab.ui.control.Button
    SaveImageButton               matlab.ui.control.Button
    OptionsImageButton            matlab.ui.control.Button
    PCAImageButton                matlab.ui.control.Button
    StartImageButton              matlab.ui.control.Button
    ContextMenu                   matlab.ui.container.ContextMenu
    LoadDataContextMenu           matlab.ui.container.Menu
    PlotDataMenu                  matlab.ui.container.Menu
    EditDataMenu                  matlab.ui.container.Menu
    SaveDataMenu                  matlab.ui.container.Menu
    ClearDataMenu                 matlab.ui.container.Menu
    LoadXandYMenu                 matlab.ui.container.Menu
    ClearXandYMenu                matlab.ui.container.Menu
    SelectYColumnMenu             matlab.ui.container.Menu
  end

  
  properties (Access = private)
    calLoadedBackgroundColor
    valLoadedBackgroundColor
    calUnloadedBackgroundColor
    valUnloadedBackgroundColor
    preproAndCVButtonColor
  end
  
  properties (Access = public, SetObservable = true) 
    buttonsWithContextMenu
    preprocessingInfo
    user_options
    results
    cvguioptions
    steps
    currentTheme
  end
  
  methods (Access = public)
    
    function loaddata(app,buttonHandle)
      if ischar(buttonHandle)
        switch buttonHandle
          case 'xcal'
            buttonHandle = app.CalXButton;
            rawdata = app.results.x_cal;
          case 'ycal'
            buttonHandle = app.CalYButton;
            rawdata = app.results.y_cal;
          case 'xval'
            buttonHandle = app.ValXButton;
            rawdata = app.results.x_val;
          case 'yval'
            buttonHandle = app.ValYButton;
            rawdata = app.results.y_val;
        end
      else
        thisButtonText = buttonHandle.Text;
        
        if any(strcmp(buttonHandle.Tag,{'xval' 'yval'})) && ~isempty(app.results)
          selection = uiconfirm(app.Diviner,['Would you like to load validation and ' ...
            'clear Diviner results? Requires rerunning Diviner after ' ...
            'loading validation data.'],'Load Validation Data?', ...
            'Options',{'Clear Results','Cancel'},...
           'DefaultOption',1,'CancelOption',2);
          if strcmp(selection,'Cancel')
            return;
          else
            app.results = [];
            figure(app.Diviner);
          end
        end
        
        
        dlgsetting = {'dataset'};
        [rawdata,name,location] = lddlgpls(dlgsetting,['Select ' upper(thisButtonText) 'Block Data']);
        if isempty(rawdata)
          return
        end
      end
   
      %buttonHandle.UserData.data = rawdata;
      thisButtonObj = findobj(app.buttonsWithContextMenu,'tag',buttonHandle.Tag); 
      thisButtonObj.enableMenuItem('all','on');
      menusToFix = app.getMenusToFix(buttonHandle.Tag);
      set(menusToFix,'enable','on');
      
      myprops.itemType = buttonHandle.Tag;
      myprops.itemIsCurrent = 1;
      myprops.itemReadOnly = 0;
      myprops.datachangecallback = 'diviner_datachangecallback(myobj.id)';
      myprops.includechangecallback = 'diviner_dataincludechange(myobj.id)';    
      buttonHandle.UserData.sharedID = setshareddata(app.Diviner, rawdata, myprops);
      linkshareddata(buttonHandle.UserData.sharedID,'add',app.Diviner,'diviner_updateshareddata');
      figure(app.Diviner);
    end
    
    function  plotdata(app,buttonHandle)
      plotgui('new',buttonHandle.UserData.sharedID);      
    end
    
    function editdata(app,buttonHandle)
      editds(buttonHandle.UserData.sharedID);
    end
    
    function  savedata(app,buttonHandle)
      thisData = getshareddata(buttonHandle.UserData.sharedID);
      if ~isempty(thisData.name) & length(thisData.name)<63
        name = thisData.name;
      else
        name = 'data';
      end
      str = buttonHandle.Tag;
      [what,where] = svdlgpls(thisData,['Save ' str ' Data'],name);
    end
    
    function  cleardata(app,buttonHandle)
      buttonHandle.UserData.sharedID = [];
      thisButtonObj = findobj(app.buttonsWithContextMenu,'tag',buttonHandle.Tag);
      cmenusToFix = {app.PlotDataMenu app.EditDataMenu app.SaveDataMenu...
        app.ClearDataMenu app.ClearXandYMenu};
      menusToFix = app.getMenusToFix(buttonHandle.Tag);
      if strcmp(buttonHandle.Tag, 'ycal')
        cmenusToFix{end+1} = app.SelectYColumnMenu;
      end
      thisButtonObj.enableMenuItem(cmenusToFix,'off');
      set(menusToFix,'Enable','off');
      app.askToClear;
    end
    
    function  loaddataboth(app,buttonHandle)
      whatBlock = strsplit(buttonHandle.Tag,'_');
      whatBlock = whatBlock{1};
      
      switch whatBlock
        case {'xcal' 'ycal'}
          app.loaddata(app.CalXButton);
          if ~isempty(app.CalXButton.UserData.sharedID)
            app.loaddata(app.CalYButton);
          end
        case {'xval' 'yval'}
          app.loaddata(app.ValXButton);
          if ~isempty(app.ValXButton.UserData.sharedID)
            app.loaddata(app.ValYButton); 
          end
      end
      
    end
    function  cleardataboth(app,buttonHandle)
      whatBlock = strsplit(buttonHandle.Tag,'_');
      whatBlock = whatBlock{1};
      
      switch whatBlock
        case {'xcal' 'ycal'}
          app.cleardata(app.CalXButton);
          app.cleardata(app.CalYButton);
        case {'xval' 'yval'}
          app.cleardata(app.ValXButton);
          app.cleardata(app.ValYButton);
      end
    end
    
    function selectycol(app,buttonHandle)      
      yblk = getshareddata(buttonHandle.UserData.sharedID);
      
      ncols = size(yblk.data,2);
      yinclude = yblk.include{2};
      if length(yinclude) > 1
        yinclude = yinclude(1);
      end
      lbls    = yblk.label{2};
      for j=1:ncols
        if isempty(lbls)
          str{j} = ['Column ' num2str(j)];
        else
          str{j} = [lbls(j,:) num2str(j)];
        end
      end
      
      [selection,ok] = listdlg('ListString',str,'SelectionMode',...
        'single','InitialValue',yinclude,'PromptString',...
        'Select Y-Column to use','Name','Select Y-Column');
      
      if ok & ~isempty(selection)
        if length(yblk.include{2})~=length(selection) | any(yblk.include{2}~=selection)
          yblk.include{2} = selection;
          setshareddata(buttonHandle.UserData.sharedID,yblk);
        end
      end
      figure(app.Diviner);
    end
    
    function blockListenerCallback(app,src,event)
      thisBlock = event.AffectedObject;
      if isa(thisBlock,'matlab.ui.control.Button')
        thisData = getshareddata(thisBlock.UserData.sharedID);
        if ~isempty(thisData)
          switch thisBlock.Tag
            case {'xcal' 'ycal'}
              thisBlock.BackgroundColor = app.calLoadedBackgroundColor;
            case {'xval' 'yval'}
              thisBlock.BackgroundColor = app.valLoadedBackgroundColor;
          end
        else
          switch thisBlock.Tag
            case {'xcal' 'ycal'}
              thisBlock.BackgroundColor = app.calUnloadedBackgroundColor;
            case {'xval' 'yval'}
              thisBlock.BackgroundColor = app.valUnloadedBackgroundColor;
          end 
        end
      end
      startAndStop = [app.StartImageButton];
      %check x cal and y cal buttons
      xcalData = getshareddata(app.CalXButton.UserData.sharedID);
      ycalData = getshareddata(app.CalYButton.UserData.sharedID);
      componentsToEnable = [app.PreprocessingButton ...
        app.CrossValidationButton app.OutlierSwitch ...
        app.PCAImageButton  app.OptionsImageButton app.OptionsMenu...
        app.PreprocessingMenu app.CrossValidationMenu];
      startAndStop_en = 'off';
      en = 'off';
      if ~isempty(xcalData) && ~isempty(ycalData)
        en = 'on';
        if ~isempty(app.preprocessingInfo)
          startAndStop_en = 'on';
          app.updateProgressTracker(1:2)
        else
          app.updateProgressTracker(1)
        end
        set([app.PreprocessingButton app.CrossValidationButton],...
          'Tooltip','Click to open')
      else
        set([app.PreprocessingButton app.CrossValidationButton],...
          'Tooltip','Data must be loaded')
      end
      set(componentsToEnable,'Enable',en);
      set(startAndStop,'Enable',startAndStop_en);
    end
    
    function askToClear(app)
      if ~isempty(app.results)
        myAnswer = uiconfirm(app.Diviner,'Clear current Diviner Results?',...
          'Clear Results',"Icon","question",'Options',...
          {'Clear','Save & Clear','Cancel'},'DefaultOption',1,...
          'CancelOption',3);
        switch myAnswer
          case 'Clear'
            app.results = [];
          case 'Save & Clear'
            app.SaveImageClicked();
            app.results = [];
          case 'Cancel'
            return;
        end
      end
      figure(app.Diviner);
    end
    
    function updateProgressTracker(app,step)
      tempProgressData = app.ProgressHTML.Data;
      tempProgressData.Data(step) = 1;
      app.ProgressHTML.Data = tempProgressData;
      
      currentStep = step(end);
      numSteps = length(app.steps);
      
      if currentStep <= (numSteps - 2)
        app.CurrentStepText.Text = app.steps{currentStep + 1};
        app.NextStepText.Text = app.steps{currentStep + 2};
        app.NextStepText.Visible = 'on';
        app.NextStepLabel.Visible = 'on';
      elseif currentStep == (numSteps - 1)
        app.CurrentStepText.Text = app.steps{currentStep + 1};
        app.NextStepText.Text = '';
        app.NextStepText.Visible = 'off';
        app.NextStepLabel.Visible = 'off';
      elseif currentStep == numSteps
        app.CurrentStepText.Text = app.steps{end};
        app.NextStepText.Text = '';
        app.NextStepText.Visible = 'off';
        app.NextStepLabel.Visible = 'off';
      end      
      drawnow;
    end
  end
  
  methods (Access = private)

    function [xpp_cleaned,ypp_cleaned]= cleanPrePro(app)    
      xpp = app.preprocessingInfo.preprocessing(:,1);
      xpp_empty = cellfun(@isempty,xpp);
      xpp_cleaned = xpp(~xpp_empty);
      ypp = app.preprocessingInfo.preprocessing(:,2);
      ypp_empty = cellfun(@isempty,ypp);
      ypp_cleaned = ypp(~ypp_empty);
    end
    
    function resultsTableSaveModels(app,fig)
      myTable = fig.findobj('type','uitable');
      mySelection = myTable.Selection;
      for i=mySelection
        mySelectedModel = recreatemodel(app.results,i);
        svdlgpls(mySelectedModel,'Save Diviner Results',['selectedModel' num2str(i)]);
      end
    end
    
    function resultsTableOpenModels(app,fig)
      myTable = fig.findobj('type','uitable');
      mySelection = myTable.Selection;
      for i=mySelection
        mySelectedModel = recreatemodel(app.results,i);
        plsobj = evrigui('analysis');
        plsobj.setModel(mySelectedModel);
      end     
    end
    
    function resetProgessTracker(app)
%       lmps = findall(app.Diviner,'Type','uilamp');
%       set(lmps,'Color','r');
      x = getshareddata(app.CalXButton.UserData.sharedID);
      y = getshareddata(app.CalYButton.UserData.sharedID);
      app.ProgressHTML.Data.Data = zeros(1,length(app.steps));
%       lmp1 = lmps.findobj('tag','1');
      if ~isempty(x) && ~isempty(y) && ~isempty(app.preprocessingInfo) 
        app.updateProgressTracker(1:2);
      elseif ~isempty(x) || ~isempty(y) || ~isempty(app.preprocessingInfo)
        app.CurrentStepText.Text = app.steps(1);
        app.NextStepText.Text = app.steps(2);
      end
      app.NextStepLabel.Visible = 'on';
      app.NextStepText.Visible = 'on';
    end
    
    function resultsListenerCallback(app,src,event)
      toFix = [app.SaveImageButton app.OutlierImageButton ...
        app.ResultsTableImageButton app.ScoresImageButton...
        app.VariableConsensusImageButton app.SaveResultsMenu];
      
      tempResultsData = app.ResultsHTML.Data;
      tempResultsData.Data = zeros(1,9);
      if isempty(app.results)
        app.resetProgessTracker;
        set(toFix,'enable','off');
        app.ResultsHTML.Data = tempResultsData;
      else
        if isempty(app.results.calibrationoutliers)
          %remove app.OutlierImageButton
          toFix = [app.SaveImageButton ...
            app.ResultsTableImageButton app.ScoresImageButton ...
            app.VariableConsensusImageButton app.SaveResultsMenu];
        end
        
        errorDSO = app.results.errordata;
        tempResultsData.Data(1) = size(errorDSO,1);
        
        if ~isempty(app.results.calibrationoutliers)
          tempResultsData.Data(2) = length(app.results.calibrationoutliers);
        end
        %xcal = getshareddata(app.CalXButton.UserData.sharedID);
        xcal = app.results.x_cal;
        tempResultsData.Data(3) = size(xcal,1);

        tempResultsData.Data(4) = min(errorDSO.data(:,1));
        tempResultsData.Data(5) = max(errorDSO.data(:,1));
        tempResultsData.Data(6) = min(errorDSO.data(:,2));
        tempResultsData.Data(7) = max(errorDSO.data(:,2));
        if size(errorDSO,2) == 5
          %then have RMSEP
          tempResultsData.Data(8) = min(errorDSO.data(:,5));
          tempResultsData.Data(9) = max(errorDSO.data(:,5));
        end
        app.ResultsHTML.Data = tempResultsData;
        set(toFix,'enable','on');
        app.preprocessingInfo.preprocessing = ...
          app.results.options.preprocessing;
        if ~isempty(app.results.library_name)
          app.preprocessingInfo.libraryname = app.results.library_name;          
        end
        if ~isempty(app.results.outlier_recipes)
          app.preprocessingInfo.outlierrecipes = ...
            app.results.outlier_recipes;
        end
      end
    end
    
    function menusToFix = getMenusToFix(app,thisTag)
      switch thisTag
        case 'xcal'
          menusToFix = [app.CalibrationMenu app.OpenXblockMenu ...
            app.PlotXblockMenu app.ClearCalibrationXMenu ...
            app.ClearCalXandYMenu];
        case 'ycal'
          menusToFix = [app.CalibrationMenu app.OpenYblockMenu ...
            app.PlotYblockMenu app.EmenuSelectYColumnMenu ...
            app.ClearCalibrationYMenu app.ClearCalXandYMenu];
        case 'xval'
          menusToFix = [app.ValidationMenu app.OpenXblockMenu_2 ...
            app.PlotXblockMenu_2 app.ClearValidationXMenu ...
            app.ClearValXandYMenu];
        case 'yval'
          menusToFix = [app.ValidationMenu app.OpenYblockMenu_2 ...
            app.PlotYblockMenu_2 app.ClearValidationYMenu ...
            app.ClearValXandYMenu];
      end
      
    end

    function themeListenerCallback(app,src,event)
      
      tempProgressData = app.ProgressHTML.Data;
      if isempty(tempProgressData)
        tempProgressData.Data = zeros(1,length(app.steps));
      end
      tempProgressData.Theme = app.currentTheme;
      app.ProgressHTML.Data = tempProgressData;
      
      tempResultsData = app.ResultsHTML.Data;
      if isempty(tempResultsData)
        tempResultsData.Data = zeros(1,9);
      end
      tempResultsData.Theme = app.currentTheme;
      app.ResultsHTML.Data = tempResultsData;
      
      drawnow;
    end
    
    function [x,y] = setInclude(app,x,y,keyword)
      switch keyword
        case 'cal'
          yBlockString = "CalYButton";
          xBlockString = "CalXButton";
        case 'val'
          yBlockString = "ValYButton";
          xBlockString = "ValXButton";
      end
      isect = intersect(y.include{1},x.include{1}); %intersect samples includ from x- and y-blocks
      if length(x.include{1})~=length(isect) | length(y.include{1})~=length(isect)  %did either change?
        y.include{1} = isect;
        setshareddata(app.(yBlockString).UserData.sharedID,y);
        x.include{1} = isect;
        setshareddata(app.(xBlockString).UserData.sharedID,x);
      end
      
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      app.Diviner.Visible = 'off';
      movegui(app.Diviner,'center')
      app.Diviner.Visible = 'on';
      
      %the below code allows knowing what button the context menu was
      %opened on. This a hack and this functionality is available in 2023b
      toFixContextMenus = {app.CalXButton app.CalYButton app.ValXButton app.ValYButton};
      app.buttonsWithContextMenu = diviner_contextMenu.empty(0, numel(toFixContextMenus));
      %have to assign each context menu item individually so a nested for
      %loop inside the below for loop will not work
      mLoadData = app.ContextMenu.findobj('tag','loaddata_cmenu');
      mPlotData = app.ContextMenu.findobj('tag','plotdata_cmenu');
      mEditData = app.ContextMenu.findobj('tag','editdata_cmenu');
      mSaveData = app.ContextMenu.findobj('tag','savedata_cmenu');
      mclearData = app.ContextMenu.findobj('tag','cleardata_cmenu');
      mLoadDataBoth = app.ContextMenu.findobj('tag','loaddataboth_cmenu');
      mClearDataBoth = app.ContextMenu.findobj('tag','cleardataboth_cmenu');
      mSelectYCol = app.ContextMenu.findobj('tag','selectycol_cmenu');
      % add context menu with tag
      for i = 1:numel(toFixContextMenus)
        thisButton = toFixContextMenus{i};
        app.buttonsWithContextMenu(i) = diviner_contextMenu(thisButton);
        app.buttonsWithContextMenu(i).addMenuItem(mLoadData.copy(), @(buttonHandle) app.loaddata(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mPlotData.copy(), @(buttonHandle) app.plotdata(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mEditData.copy(), @(buttonHandle) app.editdata(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mSaveData.copy(), @(buttonHandle) app.savedata(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mclearData.copy(), @(buttonHandle) app.cleardata(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mLoadDataBoth.copy(), @(buttonHandle) app.loaddataboth(buttonHandle));
        app.buttonsWithContextMenu(i).addMenuItem(mClearDataBoth.copy(), @(buttonHandle) app.cleardataboth(buttonHandle));
        if i == 2
          app.buttonsWithContextMenu(i).addMenuItem(mSelectYCol.copy(), @(buttonHandle) app.selectycol(buttonHandle));
        end
      end
      %initialize UserData.data and UserData.infoText fields for each data block button
      buttons = [app.CalXButton, app.CalYButton, app.ValXButton,...
        app.ValYButton,app.PreprocessingButton,app.CrossValidationButton];
      infoTexts = {'CalX Info', 'CalY Info', 'ValX Info', 'ValY Info',...
        'Prepro Info', 'Cross-Val Info'};
      
      for i = 1:numel(buttons)
        buttons(i).UserData.sharedID = [];
        buttons(i).UserData.infoText = infoTexts{i};
      end
      listenerXCal = addlistener(app.CalXButton, 'UserData', 'PostSet', @(src,event) app.blockListenerCallback(src,event));
      listenerYCal = addlistener(app.CalYButton, 'UserData', 'PostSet', @(src,event) app.blockListenerCallback(src,event));
      listenerXVal = addlistener(app.ValXButton, 'UserData', 'PostSet', @(src,event) app.blockListenerCallback(src,event));
      listenerYVal = addlistener(app.ValYButton, 'UserData', 'PostSet', @(src,event) app.blockListenerCallback(src,event));
      listenerPrepro = addlistener(app, 'preprocessingInfo', 'PostSet', @(src,event) app.blockListenerCallback(src,event));
      listenerResults = addlistener(app, 'results', 'PostSet', @(src,event) app.resultsListenerCallback(src,event));
      listenerResults = addlistener(app, 'currentTheme', 'PostSet', @(src,event) app.themeListenerCallback(src,event));
      
      app.ResultsHTML.HTMLSource = 'resultsTable.html';
      app.user_options = diviner('options');
      
      % initialize cross-validation settings
      app.cvguioptions.cvi = app.user_options.cvi;
      app.cvguioptions.maxlvs = app.user_options.maxlvs;
      app.cvguioptions.method = 'venetian blinds';
      %app.BuildMLRModelsMenu.Checked = 'off';
      app.OutlierDetectionMenu.Checked = 'off';
      app.calLoadedBackgroundColor = [0.05,0.16,0.25];
      app.calUnloadedBackgroundColor = [0.15,0.46,0.72];
      app.valLoadedBackgroundColor = [0.26,0.43,0.60];
      app.valUnloadedBackgroundColor = [0.41,0.69,0.96];
      
      %if adding or removing to this string array, need to also add or 
      %remove a section(s) in the progressTracker.html file
      app.steps = ["Load Data"; ...
        "Set Preprocessing Library"; ...
        "Waiting to Run";...
        "Outlier Detection (*Off)"; ...
        "Initial Gridsearch";...
        "Survey Latent Variables"; ...
        "Analyze Models"; ...
        "Refine Models";...
        "Analyze Refined Models"; ...
        "Run Complete - Save Results"];
      
     
      app.CurrentStepText.Text = app.steps(1);
      app.NextStepText.Text = app.steps(2);

      prefs = getplspref;
      app.currentTheme = "dark";
      if isfieldcheck(prefs,'.diviner.theme')
        if ~isempty(prefs.diviner.theme)
          app.currentTheme = prefs.diviner.theme;
        end
      end
      app.ThemeMenuSelected([]);
    end

    % Callback function
    function LoadDataContextMenuSelected(app, event)
      loaddata(app,event);
    end

    % Callback function: PreprocessingButton, PreprocessingMenu
    function PreprocessingButtonPushed(app, event)
      x = getshareddata(app.CalXButton.UserData.sharedID);
      y = getshareddata(app.CalYButton.UserData.sharedID);
      
      if isempty(app.preprocessingInfo)
        preproInstance = diviner_prepro(x,y);
      else
        preproInstance = diviner_prepro(x,y,app.preprocessingInfo);
      end
      waitfor(preproInstance.DivinerPreprocessingSettingsUIFigure);

      if ~isempty(app.preprocessingInfo)
        app.updateProgressTracker(2);
        [status,~] = comparevars(app.preprocessingInfo,...
          preproInstance.outputStruct);
        if ~status
          app.askToClear;
          app.preprocessingInfo = preproInstance.outputStruct;
        else
          app.preprocessingInfo = preproInstance.outputStruct;
        end
      else
        app.preprocessingInfo = preproInstance.outputStruct;
      end
      
      if ~isempty(app.preprocessingInfo)
        if isempty(app.preprocessingInfo.outlierrecipes)
          app.OutlierSwitch.Value = '0';
          app.OutlierSwitch.Enable = 'off';
          app.OutlierDetectionMenu.Enable = 'off';
          app.OutlierSwitchValueChanged([]);
        else
          app.OutlierSwitch.Enable = 'on';
          app.OutlierDetectionMenu.Enable = 'on';
        end
      end

      figure(app.Diviner);
    end

    % Window button motion function: Diviner
    function DivinerWindowButtonMotion(app, event)
      mousePos = app.Diviner.CurrentPoint;
      sessionPanelPos = app.SessionSetupPanel.Position;
      
      % Assuming you have an array of button objects, e.g., app.Buttons = [button1, button2, button3, button4];
      buttons = [app.CalXButton,app.CalYButton,app.ValXButton,app.ValYButton...
        app.PreprocessingButton, app.CrossValidationButton];
      
      currentButtonIndex = 0;
      
      % Iterate over each button
      for i = 1:numel(buttons)
        button = buttons(i);
        buttonPos = button.Position;
        
        % Check if the cursor is over the current button
        if mousePos(1) >= (buttonPos(1) + sessionPanelPos(1)) && ...
            mousePos(1) <= (buttonPos(1) + sessionPanelPos(1) + buttonPos(3)) && ...
            mousePos(2) >= (buttonPos(2) + sessionPanelPos(2)) && ...
            mousePos(2) <= (buttonPos(2) + sessionPanelPos(2) + buttonPos(4))
        currentButtonIndex = i;
        break; % Exit the loop once the cursor is over any button
        end
      end
      
      % Update the TextArea based on the cursor position
      if currentButtonIndex > 0
        
        infoToShow = ["";
          "Name: ";
          "Size: ";
          "Included: ";
          "Warning: "];
        switch button.Tag
          case {'xcal' 'ycal' 'xval' 'yval'}
            %out = getshareddata(myid);
            dso = getshareddata(button.UserData.sharedID);
            if ~isempty(dso)
              name = dso.name;
              if isempty(name)
                name = 'N/A';
              end
              numRows = num2str(dso.size(1));
              numCols = num2str(dso.size(2));
              sizeText = numRows + " x " + numCols;
              includeInfo = dso.include;
              [~,includedRows] = size(includeInfo{1});
              [~,includedCols] = size(includeInfo{2});
              includedSizeText = includedRows + " x " + includedCols;
              
              switch button.Tag
                case 'xcal'
                  infoToShow(1) = "Calibration X";
                case 'ycal'
                  infoToShow(1) = "Calibration Y";
                case 'xval'
                  infoToShow(1) = "Validation X";
                case 'yval'
                  infoToShow(1) = "Validation Y";
              end
              
              infoToShow(2) = replaceBetween(infoToShow(2),...
                strfind(infoToShow(2), ': ')+2,...
                strlength(infoToShow(2)), name);
              
              infoToShow(3) = replaceBetween(infoToShow(3),...
                strfind(infoToShow(3), ': ')+2,...
                strlength(infoToShow(3)), sizeText);
              
              infoToShow(4) = replaceBetween(infoToShow(4),...
                strfind(infoToShow(4), ': ')+2,...
                strlength(infoToShow(4)), includedSizeText);
            else
              if any(strcmp(button.Tag,{'xval' 'yval'}))
                %no validation loaded, provide warning
                infoToShow(5) = replaceBetween(infoToShow(5),...
                  strfind(infoToShow(5), ': ')+2,...
                  strlength(infoToShow(5)), "Load test data before " + ...
                  "running Diviner. Loading it afterward " + ... 
                  "will require a re-run.");
 
              end
            end
          case 'preprobtn'
            
            infoToShow = ["Preprocessing";
              "Library: ";
              "Num X-block Recipes: ";
              "Num Y-block Recipes: ";
              "Num Outlier Recipes: ";];
            
            preproInfo = app.preprocessingInfo;
            if ~isempty(preproInfo)
              
              [xpp_cleaned,ypp_cleaned] = app.cleanPrePro;
              
              if isfield(preproInfo,'libraryname')
                preproLibraryName =  preproInfo.libraryname;
              else
                preproLibraryName = 'User Library';
              end
              
              infoToShow(2) = replaceBetween(infoToShow(2),...
                strfind(infoToShow(2), ': ')+2,...
                strlength(infoToShow(2)), preproLibraryName);
              
              infoToShow(3) = replaceBetween(infoToShow(3),...
                strfind(infoToShow(3), ': ')+2,...
                strlength(infoToShow(3)), num2str(size(xpp_cleaned,1)));
              
              infoToShow(4) = replaceBetween(infoToShow(4),...
                strfind(infoToShow(4), ': ')+2,...
                strlength(infoToShow(4)), num2str(size(ypp_cleaned,1)));
              
              
              if isfield(preproInfo,'outlierrecipes')
                numOfOutlierRecipes = size(preproInfo.outlierrecipes,1);
              else
                numOfOutlierRecipes = 0;
              end
              infoToShow(5) = replaceBetween(infoToShow(5),...
                strfind(infoToShow(5), ': ')+2,...
                strlength(infoToShow(5)), num2str(numOfOutlierRecipes));
            end
          case 'crossvalbtn'
            infoToShow = [button.Text;...
              strcat("Method: ", string(app.cvguioptions.method));...
              strcat("Number of Splits: ", num2str(app.cvguioptions.cvi{2})); ...
              ];
            if strcmp(app.cvguioptions.cvi{1},'vet')
              infoToShow = [infoToShow; 'Samples per Blind: ' num2str(app.cvguioptions.cvi{3})];
            else
              infoToShow = [infoToShow; 'Number of Iterations: ' num2str(app.cvguioptions.cvi{3})];
            end
            infoToShow = [infoToShow; 'Maximum Latent Variables: ' num2str(app.cvguioptions.maxlvs)];
        end
      else
        if isempty(app.results)
          infoToShow = ["Number of Models to build: ";...
            "(# X Prepros x # Y Prepros x Max LVs x # Variable Selection Methods x # Model Types)";...
            "";...
            "Steps:";...
            "1.Load data  " + ...
            "2.Set preprocessing library  " + ...
            "3.Outlier detection/selection (*if checked)  " + ...
            "4.Initial gridsearch  " + ...
            "5.Survey Latent Variables  " + ...
            "6.Analyze Models  " + ...
            "7.Refine models  " + ...
            "8.Analyze refined models"];
          
          if ~isempty(app.preprocessingInfo)
            [xpp_cleaned,ypp_cleaned] = app.cleanPrePro;
            numOfXPP = length(xpp_cleaned);
            numofYPP = length(ypp_cleaned);
          else
            numOfXPP = 0;
            numofYPP = 0;
          end
          %the *2 at the end is for the variable selection method
          mlrValue = 1;
          if strcmp(app.user_options.includemlr,'yes')
            mlrValue = 2;
          end
          numberOfModels = numOfXPP*numofYPP*app.user_options.maxlvs*2*mlrValue;
          infoToShow(1) = replaceBetween(infoToShow(1),...
            strfind(infoToShow(1), ': ')+2,...
            strlength(infoToShow(1)), num2str(numberOfModels));


        else
          if isfieldcheck(app.results,'.times')
            infoToShow = ['Outlier Detection runtime:        ' besttime(app.results.times.module1) newline...
              'Initial Gridsearch runtime:        ' besttime(app.results.times.module2) newline...
              'Latent Variable runtime:           ' besttime(app.results.times.module21) newline...
              'Model Analyzation runtime:     ' besttime(app.results.times.analyze) newline...
              'Model Refinement runtime:     ' besttime(app.results.times.refinement)];
          else
            infoToShow = '';
          end
        end
      end
      app.TextArea.Value = infoToShow;
 
    end

    % Button pushed function: StartImageButton
    function StartImageClicked(app, event)
      
      divinerOpts = diviner('options');
%                    alpha: 0.9000
%                      cvi: {'vet'  [5]  [1]}
%            preprocessing: {34×2 cell}
%     outlierpreprocessing: {6×1 cell}
%                   maxlvs: 10
%               includemlr: 'yes'
%              compression: 'none'
%            compressncomp: 1
%      exhaustivevarselect: 'no'
%               savemodels: 'yes'
%                    plots: 'final'
%             createvalset: 'yes'
%      splitcaltestoptions: [1×1 struct]
%             functionname: 'diviner'
      %divinerOpts.alpha = outlier alpha setting here;
      %divinerOpts.cvi = cvi here;
      %divinerOpts.preprocessing = fixed prepro here;
      app.updateProgressTracker(3);
      
      divinerOpts.includemlr = app.user_options.includemlr;
      divinerOpts.createvalset = 'no';
      divinerOpts.maxlvs = app.user_options.maxlvs;
      
      divinerOpts.outlierdetection = app.user_options.outlierdetection;
      divinerOpts.alpha = app.user_options.alpha;
      divinerOpts.outlierpreprocessing = ...
        app.preprocessingInfo.outlierrecipes;

      
      divinerOpts.exhaustivevarselect = app.user_options.exhaustivevarselect;
      divinerOpts.cvi = app.user_options.cvi;
      
      x = getshareddata(app.CalXButton.UserData.sharedID);
      y = getshareddata(app.CalYButton.UserData.sharedID);
      xval = getshareddata(app.ValXButton.UserData.sharedID);
      yval = getshareddata(app.ValYButton.UserData.sharedID);

      %check and fix any differences in included samples between x and y
      [x,y] = app.setInclude(x,y,'cal');
      if ~isempty(xval) & ~isempty(yval)
        [xval, yval] = app.setInclude(xval,yval,'val');
      end

      divinerOpts.preprocessing = app.preprocessingInfo.preprocessing;
      divinerOpts.plots = 'none';
      app.user_options = divinerOpts;
      try
        t = num2str(now);
        divinerOpts.t = t;
        set(app.Diviner,'UserData',t);
        if ~isempty(xval) && ~isempty(yval)
          [xval,yval,status] = getreconciledvalidation(x,y,xval,yval);
          if ~status
            return
          end
          setshareddata(app.ValXButton.UserData.sharedID,xval);
          setshareddata(app.ValYButton.UserData.sharedID,yval);
          divinerRun = diviner(x,y,xval,yval,divinerOpts);
        else
          divinerRun = diviner(x,y,divinerOpts);
        end
        if ~isempty(divinerRun)
          divinerRun.library_name = app.preprocessingInfo.libraryname;
          divinerRun.outlier_recipes = app.preprocessingInfo.outlierrecipes;
          y.include{2} = divinerRun.y_cal.include{2};
          setshareddata(app.CalYButton.UserData.sharedID,y);
          app.results = divinerRun;
        end
      catch E
        evrierrordlg([encode(E.stack,struct('includeclear','off')) newline newline E.message]);
        figure(app.Diviner);
        return
      end
      if ~isempty(app.results)
        app.ScoresImageClicked([]);
        app.updateProgressTracker(1:10)
      end
      figure(app.Diviner);
    end

    % Callback function: OptionsImageButton, OptionsMenu
    function OptionsImageClicked(app, event)
      o.remove = {'plots' 'cvi' 'preprocessing' 'outlierpreprocessing'...
        'maxlvs' 'compression' 'compressncomp' ...
        'exhaustivevarselect' 'savemodels' 'createvalset' ...
        'splitcaltestoptions'};
      if ~isempty(app.results)
        opts = app.results.options;
      else
        opts = app.user_options;
      end
      myopts = optionsgui(opts,o);
      
      if ~isempty(myopts)
        [status,~] = comparevars(app.user_options,myopts);
      else
        status = 1;
        myopts = app.user_options;
      end
      
      if ~status
        app.askToClear;
      end
      app.user_options = myopts;
      if strcmp(app.user_options.outlierdetection,'on')
        valueToUse = '1';
        isChecked = 'on';
      else
        valueToUse = '0';
        isChecked = 'off';
      end
      app.OutlierSwitch.Value = valueToUse;
      app.OutlierDetectionMenu.Checked = isChecked;
      figure(app.Diviner);
    end

    % Button pushed function: PCAImageButton
    function PCAImageClicked(app, event)
      %open data in PCA analysis window
      xcal = getshareddata(app.CalXButton.UserData.sharedID);
      ycal = getshareddata(app.CalYButton.UserData.sharedID);
      xval = getshareddata(app.ValXButton.UserData.sharedID);
      yval = getshareddata(app.ValYButton.UserData.sharedID);
      
      pcaobj = evrigui('analysis');
      pcaobj.setXblock(xcal);
      pcaobj.setYblock(ycal);
      if ~isempty(xval)
        pcaobj.setXblockVal(xval);
      end
      if ~isempty(yval)
        pcaobj.setYblockVal(yval);
      end
      pcaobj.setMethod('pca'); 
    end

    % Callback function: SaveImageButton, SaveResultsMenu
    function SaveImageClicked(app, event)
      %open dialog window to save results
      name = lower(['divinerresults_' datestr(now,30)]);
      svdlgpls(app.results,'Save Diviner Results',name);
    end

    % Button pushed function: VariableConsensusImageButton
    function VariableConsensusImageClicked(app, event)
      x = getshareddata(app.CalXButton.UserData.sharedID);
      variablecompare(app.results,x);
      %call variablecompare.m function
    end

    % Button pushed function: ScoresImageButton
    function ScoresImageClicked(app, event)
      xcal = getshareddata(app.CalXButton.UserData.sharedID);
      ycal = getshareddata(app.CalYButton.UserData.sharedID);
      xval = getshareddata(app.ValXButton.UserData.sharedID);
      yval = getshareddata(app.ValYButton.UserData.sharedID);
      data = {xcal ycal xval yval []};
      app.results.errortable.Properties.UserData = app.results;
      
      [~,~,~,~] = selectbestmodels(app.results.errortable,...
        app.results.errordata,data,app.user_options,app.results.preprocesslookup,[]);
    end

    % Button pushed function: ResultsTableImageButton
    function ResultsTableImageClicked(app, event)
      divinertable(app.results,app.currentTheme);
    end

    % Button pushed function: OutlierImageButton
    function OutlierImageClicked(app, event)
      x_cal = getshareddata(app.CalXButton.UserData.sharedID);
      y_cal = getshareddata(app.CalYButton.UserData.sharedID);
      x_val = getshareddata(app.ValXButton.UserData.sharedID);
      y_val = getshareddata(app.ValYButton.UserData.sharedID);
      
      [results,cancelled,wb] = module1(x_cal,y_cal,x_val,y_val,app.user_options,event);
    end

    % Button pushed function: BrowseImageButton
    function BrowseImageClicked(app, event)
      browse
    end

    % Callback function: OutlierDetectionMenu, OutlierSwitch
    function OutlierSwitchValueChanged(app, event)
      if ~isempty(event)
        if isprop(event,'Value')
          if str2num(event.Value)
            isChecked = 'on';
          else
            isChecked = 'off';
          end
          app.OutlierDetectionMenu.Checked = isChecked;
        else
          if strcmp(event.Source.Checked,'on')
            valueToUse = '0';
            app.OutlierDetectionMenu.Checked = 'off';
          elseif strcmp(event.Source.Checked,'off')
            valueToUse = '1';
            app.OutlierDetectionMenu.Checked = 'on';
          end
          app.OutlierSwitch.Value = valueToUse;
        end
      end
      if str2num(app.OutlierSwitch.Value)
        optsSetting = 'on';
        app.steps = ["Load Data"; ...
          "Set Preprocessing Library"; ...
          "Waiting to Run";...
          "Outlier Detection"; ...
          "Initial Gridsearch";...
          "Survey Latent Variables"; ...
          "Analyze Models"; ...
          "Refine Models";...
          "Analyze Refined Models"; ...
          "Run Complete - Save Results"];
      else
        optsSetting = 'off';
        app.steps = ["Load Data"; ...
          "Set Preprocessing Library"; ...
          "Waiting to Run";...
          "Outlier Detection (*Off)"; ...
          "Initial Gridsearch";...
          "Survey Latent Variables"; ...
          "Analyze Models"; ...
          "Refine Models";...
          "Analyze Refined Models"; ...
          "Run Complete - Save Results"];
      end
      app.user_options.outlierdetection = optsSetting;
      app.updateProgressTracker(1:length(find(app.ProgressHTML.Data.Data)))
      app.askToClear;
    end

    % Callback function: CrossValidationButton, 
    % CrossValidationMenu
    function CrossValidationButtonPushed(app, event)
      cvoptions = app.cvguioptions;
      x = getshareddata(app.CalXButton.UserData.sharedID);
      hmaccvgui = hmacCvGui(x,mfilename,cvoptions);
      cvhandle = hmaccvgui.fig;
      % wait until cv settings are completed or gui is closed
      waitfor(cvhandle,'UserData',1)
      if ishandle(cvhandle)
        cvi = getappdata(cvhandle,'cvi');
        maxlvs = getappdata(cvhandle,'maxlvs');
        method = getappdata(cvhandle,'method');
        cvoptions.cvi = cvi;
        cvoptions.maxlvs = maxlvs;
        cvoptions.method = method;
        delete(cvhandle);
      end
      app.user_options.cvi = cvoptions.cvi;
      app.user_options.maxlvs = cvoptions.maxlvs;
      app.cvguioptions.cvi = cvoptions.cvi;
      app.cvguioptions.maxlvs = cvoptions.maxlvs;
      app.cvguioptions.method = cvoptions.method;
    end

    % Callback function: LoadImageButton, LoadResultsMenu
    function LoadButtonPushed(app, event)
      app.askToClear;
      dlgsetting = {'struct'};
      [resultsTemp,name,location] = lddlgpls(dlgsetting,['Select Diviner results structure']);
      if isempty(resultsTemp)
        return
      end
      cleardataboth(app,app.CalXButton);
      cleardataboth(app,app.ValXButton);
      app.results = resultsTemp;
      clear resultsTemp
      if ~isempty(app.results.x_cal)
        app.loaddata('xcal');
      end
      if ~isempty(app.results.y_cal)
        app.loaddata('ycal');
      end
      if ~isempty(app.results.x_val)
        app.loaddata('xval');
      end
      if ~isempty(app.results.y_val)
        app.loaddata('yval');
      end
      % load cv settings from results structure
      app.user_options.cvi = app.results.options.cvi;
      app.user_options.maxlvs = app.results.options.maxlvs;
      app.cvguioptions.cvi = app.results.options.cvi;
      app.cvguioptions.maxlvs = app.results.options.maxlvs;
      %app.cvguioptions.method = cvoptions.method;
      app.ProgressHTML.Data.Data = ones(1,length(app.steps));
      app.NextStepLabel.Visible = 'off';
      app.NextStepText.Visible = 'off';
      app.CurrentStepText.Text = 'Results loaded';
    end

    % Menu selected function: CalibrationXMenu, 
    % CalibrationYMenu, LoadCalXandYMenu, LoadValXandYMenu, 
    % ValidationXMenu, ValidationYMenu
    function CalibrationXMenuSelected(app, event)
      switch event.Source.Tag
        case {'fmenu_xcal' 'fmenu_bothcal'}
          buttonHandle = app.CalXButton;
        case 'fmenu_ycal'
          buttonHandle = app.CalYButton;
        case {'fmenu_xval' 'fmenu_bothval'}
          buttonHandle = app.ValXButton;
        case 'fmenu_yval'
          buttonHandle = app.ValYButton;
      end
      if contains(event.Source.Tag,'both')
        app.loaddataboth(buttonHandle)
      else
        app.loaddata(buttonHandle);
      end
    end

    % Menu selected function: EmenuSelectYColumnMenu, 
    % OpenXblockMenu, OpenXblockMenu_2, OpenYblockMenu, 
    % OpenYblockMenu_2, PlotXblockMenu, PlotXblockMenu_2, 
    % PlotYblockMenu, PlotYblockMenu_2
    function OpenXblockMenuSelected(app, event)
      toParse = strsplit(event.Source.Tag,'_');
      menuAction = toParse{2};
      blockTag = toParse{3};
      buttonHandle = findobj(app.Diviner,'tag',blockTag);
      switch menuAction
        case 'open'
          app.editdata(buttonHandle);
        case 'plot'
          app.plotdata(buttonHandle);
        case 'select'
          app.selectycol(buttonHandle);
      end
    end

    % Menu selected function: ClearCalXandYMenu, 
    % ClearCalibrationXMenu, ClearCalibrationYMenu, 
    % ClearValXandYMenu, ClearValidationXMenu, 
    % ClearValidationYMenu
    function ClearCalibrationXMenuSelected(app, event)
      switch event.Source.Tag
        case {'fmenu_clear_xcal' 'fmenu_clear_bothcal'}
          buttonHandle = app.CalXButton;
        case 'fmenu_clear_ycal'
          buttonHandle = app.CalYButton;
        case {'fmenu_clear_xval' 'fmenu_clear_bothval'}
          buttonHandle = app.ValXButton;
        case 'fmenu_clear_yval'
          buttonHandle = app.ValYButton;
      end
      if contains(event.Source.Tag,'both')
        app.cleardataboth(buttonHandle)
      else
        app.cleardata(buttonHandle);
      end
    end

    % Menu selected function: longrunYesMenu
    function longrunYesMenuSelected(app, event)
      setplspref('diviner','showlongrun',1);
    end

    % Menu selected function: longrunNoMenu
    function longrunNoMenuSelected(app, event)
      setplspref('diviner','showlongrun',0);
    end

    % Menu selected function: DisplayWarningforLongRunMenu
    function DisplayWarningforLongRunMenuSelected(app, event)
      prefs = getplspref;
      if ~isfieldcheck(prefs,'.diviner.showlongrun')
        setplspref('diviner','showlongrun',1);
      end
      prefs = getplspref;
      if prefs.diviner.showlongrun
        app.longrunYesMenu.Text = '*Yes';
        app.longrunNoMenu.Text = 'No';
      else
        app.longrunYesMenu.Text = 'Yes';
        app.longrunNoMenu.Text = '*No';
      end
    end

    % Menu selected function: DivinerCacheFolderMenu
    function DivinerCacheFolderMenuSelected(app, event)
      if isunix
        system(['open ' evridir filesep 'diviner']);
      elseif ispc
        system(['explorer ' evridir filesep 'diviner']);
      end
    end

    % Menu selected function: 
    % DivinerCacheFolderforCurrentRunMenu
    function DivinerCacheFolderforCurrentRunMenuSelected(app, event)
      if isempty(app.results)
        uialert(app.Diviner,'Diviner has not been ran yet, no results to show.','No Diviner Results')
      else
        if ~isempty(app.results.logfolder)
          % if it doesn't exist, create it
          if ~exist(app.results.logfolder,'dir') 
            uiconfirm(app.Diviner,['Log folder does not exist for ' app.results.logfolder ' run. Creating empty cache folder.'],'Diviner Cache Folder Missing')
            worked = mkdir(app.results.logfolder);
            worked = mkdir([app.results.logfolder filesep 'models']);
          end
          if isunix
            system(['open ' app.results.logfolder]);
          elseif ispc
            system(['explorer ' app.results.logfolder]);
          end
        else
          uialert(app.Diviner,'Log folder cannot be found.','No Diviner Results')
        end
      end
    end

    % Button pushed function: CalXButton, CalYButton, 
    % ValXButton, ValYButton
    function CalXButtonPushed(app, event)
      thisButton = event.Source;
      app.loaddata(thisButton);
    end

    % Button pushed function: HelpImageButton
    function HelpImageClicked(app, event)
      evrihelp('Diviner_analysis')
    end

    % Menu selected function: DarkMenu, LightMenu
    function ThemeMenuSelected(app, event)
      if ~isempty(event)
        if strcmp(event.Source.Tag,'theme_light')
          app.currentTheme = "light";
        elseif strcmp(event.Source.Tag,'theme_dark')
          app.currentTheme = "dark";
        end
      end
      
      setplspref('diviner','theme',app.currentTheme);
      
      if strcmp(app.currentTheme,"light")
        group1 = [1 1 1]; %white
        group2 = [0.95 0.95 0.95]; %grey
        group3 = [0.05 0.16 0.25]; %dark blue
        group4 = [0.8 0.8 0.8]; %dark grey
        group8 = [0.94 0.94 0.94]; %grey
        
        %set background and grid color for output panel
        app.OutputPanel.BackgroundColor = group8;
        app.GridLayout2.BackgroundColor = group8;
        
        %set font color for panel titles
        app.OutputPanel.ForegroundColor = group3;
        app.SessionSetupPanel.ForegroundColor = group3;
        app.InfoPanel.ForegroundColor = group3;
        app.ProgressPanel.ForegroundColor = group3;
        
        %set font color for DIVINER text
        app.DIVINERLabel.FontColor = group3;
        
        %set font color for info panel text area
        app.TextArea.FontColor = group3;
        
        %set colors to use for cal and val blocks
        app.calLoadedBackgroundColor = [0.1725,0.3137,0.6235];
        app.calUnloadedBackgroundColor = [0.6941,0.7608,0.8510];
        app.valLoadedBackgroundColor = [0.4353,0.2353,0.6627];
        app.valUnloadedBackgroundColor = [0.7922,0.7373,0.8745];
        app.preproAndCVButtonColor = [0.6941,0.7608,0.8510];
        
      elseif strcmp(app.currentTheme,"dark")
        group1 = [1 1 1]; %white
        group2 = [0.8392 0.9294 1]; %light blue 1
        group3 = [0.051 0.1608 0.251];%dark blue 1
        group4 = [0.1294 0.2706 0.4]; %dark blue 2
        group7 =[0.2588 0.4314 0.6]; %dark blue 3
        group8 = [0.4784 0.6 0.702]; %light blue 2
        
        %set background and grid color for output panel
        app.OutputPanel.BackgroundColor = group3;
        app.GridLayout2.BackgroundColor = group3;
        
        %set font color for panel titles
        app.OutputPanel.ForegroundColor = group2;
        app.SessionSetupPanel.ForegroundColor = group3;
        app.InfoPanel.ForegroundColor = group3;
        app.ProgressPanel.ForegroundColor = group3;
        
        %set font color for DIVINER text
        app.DIVINERLabel.FontColor = group2;
        
        %set font color for info panel text area
        app.TextArea.FontColor = group7;
        
        %set colors to use for cal and val blocks
        app.calLoadedBackgroundColor = [0.05,0.16,0.25];
        app.calUnloadedBackgroundColor = [0.15,0.46,0.72];
        app.valLoadedBackgroundColor = [0.26,0.43,0.60];
        app.valUnloadedBackgroundColor = [0.41,0.69,0.96];
        app.preproAndCVButtonColor = [0.051 0.1608 0.251];
        
      end
      %overall color
      app.Diviner.Color = group1;
      %grid layout color for overall figure
      app.GridLayout.BackgroundColor = group4;
      %preprocessing button font color
      app.PreprocessingButton.FontColor = group1;
      %cross-validation button font color
      app.CrossValidationButton.FontColor = group1;
      
      %font color for Cal X, Cal Y, Val X, Val Y buttons
      app.CalXButton.FontColor = group1;
      app.CalYButton.FontColor = group1;
      app.ValXButton.FontColor = group1;
      app.ValYButton.FontColor = group1;
      
      %background color for info panel
      app.InfoPanel.BackgroundColor = group1;

      %background color for session setup panel and grid for session setup
      %panel
      app.SessionSetupPanel.BackgroundColor = group2;
      app.GridLayout3.BackgroundColor = group2;
      
      %background color for buttons
      app.VariableConsensusImageButton.BackgroundColor = group2;
      app.ScoresImageButton.BackgroundColor = group2;
      app.ResultsTableImageButton.BackgroundColor = group2;
      app.OutlierImageButton.BackgroundColor = group2;
      app.HelpImageButton.BackgroundColor = group2;
      app.BrowseImageButton.BackgroundColor = group2;
      app.StartImageButton.BackgroundColor = group2;
      app.LoadImageButton.BackgroundColor = group2;
      app.PCAImageButton.BackgroundColor = group2;
      app.OptionsImageButton.BackgroundColor = group2;
      app.SaveImageButton.BackgroundColor = group2;
      
      %background color for preprocessing and cross-val buttons
      app.PreprocessingButton.BackgroundColor = app.preproAndCVButtonColor;
      app.CrossValidationButton.BackgroundColor = app.preproAndCVButtonColor;
      
      %progress panel font colors
      app.NextStepText.FontColor = group3;
      app.NextStepLabel.FontColor = group3;
      app.CurrentStepText.FontColor = group3;
      app.CurrentStepLabel.FontColor = group3;
      
      %background color and grid color for progress panel
      app.ProgressPanel.BackgroundColor = group8;
      app.GridLayout5.BackgroundColor = group8;

      %update cal and val buttons based on if data is loaded or not
      calXData = getshareddata(app.CalXButton.UserData.sharedID);
      calYData = getshareddata(app.CalYButton.UserData.sharedID);

      if isempty(calXData)
        app.CalXButton.BackgroundColor = app.calUnloadedBackgroundColor;
      else
        app.CalXButton.BackgroundColor = app.calLoadedBackgroundColor;
      end
      if isempty(calYData)
        app.CalYButton.BackgroundColor = app.calUnloadedBackgroundColor;
      else
        app.CalYButton.BackgroundColor = app.calLoadedBackgroundColor;
      end
      
      valXData = getshareddata(app.ValXButton.UserData.sharedID);
      valYData = getshareddata(app.ValYButton.UserData.sharedID);
      if isempty(valXData)
        app.ValXButton.BackgroundColor = app.valUnloadedBackgroundColor;
      else
        app.ValXButton.BackgroundColor = app.valLoadedBackgroundColor;
      end
      if isempty(valYData)
        app.ValYButton.BackgroundColor = app.valUnloadedBackgroundColor;
      else
        app.ValYButton.BackgroundColor = app.valLoadedBackgroundColor;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.Diviner = uifigure('Visible', 'off');
      app.Diviner.Name = 'Diviner';
      app.Diviner.WindowButtonMotionFcn = createCallbackFcn(app, @DivinerWindowButtonMotion, true);
      app.Diviner.Tag = 'diviner_analysis';
      app.FileMenu = uimenu(app.Diviner);
      app.FileMenu.Text = 'File';
      app.LoadDataMenu = uimenu(app.FileMenu);
      app.LoadDataMenu.Text = 'Load Data';
      app.CalibrationXMenu = uimenu(app.LoadDataMenu);
      app.CalibrationXMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.CalibrationXMenu.Text = 'Calibration X';
      app.CalibrationXMenu.Tag = 'fmenu_xcal';
      app.CalibrationYMenu = uimenu(app.LoadDataMenu);
      app.CalibrationYMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.CalibrationYMenu.Text = 'Calibration Y';
      app.CalibrationYMenu.Tag = 'fmenu_ycal';
      app.LoadCalXandYMenu = uimenu(app.LoadDataMenu);
      app.LoadCalXandYMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.LoadCalXandYMenu.Text = 'Load Cal X and Y';
      app.LoadCalXandYMenu.Tag = 'fmenu_bothcal';
      app.ValidationXMenu = uimenu(app.LoadDataMenu);
      app.ValidationXMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.ValidationXMenu.Separator = 'on';
      app.ValidationXMenu.Text = 'Validation X';
      app.ValidationXMenu.Tag = 'fmenu_xval';
      app.ValidationYMenu = uimenu(app.LoadDataMenu);
      app.ValidationYMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.ValidationYMenu.Text = 'Validation Y';
      app.ValidationYMenu.Tag = 'fmenu_yval';
      app.LoadValXandYMenu = uimenu(app.LoadDataMenu);
      app.LoadValXandYMenu.MenuSelectedFcn = createCallbackFcn(app, @CalibrationXMenuSelected, true);
      app.LoadValXandYMenu.Text = 'Load Val X and Y';
      app.LoadValXandYMenu.Tag = 'fmenu_bothval';
      app.LoadResultsMenu = uimenu(app.FileMenu);
      app.LoadResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
      app.LoadResultsMenu.Text = 'Load Results';
      app.LoadResultsMenu.Tag = 'fmenu_loadResults';
      app.SaveResultsMenu = uimenu(app.FileMenu);
      app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveImageClicked, true);
      app.SaveResultsMenu.Enable = 'off';
      app.SaveResultsMenu.Separator = 'on';
      app.SaveResultsMenu.Text = 'Save Results';
      app.ClearMenu = uimenu(app.FileMenu);
      app.ClearMenu.Text = 'Clear';
      app.ClearCalibrationXMenu = uimenu(app.ClearMenu);
      app.ClearCalibrationXMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearCalibrationXMenu.Enable = 'off';
      app.ClearCalibrationXMenu.Text = 'Calibration X';
      app.ClearCalibrationXMenu.Tag = 'fmenu_clear_xcal';
      app.ClearCalibrationYMenu = uimenu(app.ClearMenu);
      app.ClearCalibrationYMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearCalibrationYMenu.Enable = 'off';
      app.ClearCalibrationYMenu.Text = 'Calibration Y';
      app.ClearCalibrationYMenu.Tag = 'fmenu_clear_ycal';
      app.ClearCalXandYMenu = uimenu(app.ClearMenu);
      app.ClearCalXandYMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearCalXandYMenu.Enable = 'off';
      app.ClearCalXandYMenu.Text = 'Clear Cal X and Y';
      app.ClearCalXandYMenu.Tag = 'fmenu_clear_bothcal';
      app.ClearValidationXMenu = uimenu(app.ClearMenu);
      app.ClearValidationXMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearValidationXMenu.Enable = 'off';
      app.ClearValidationXMenu.Separator = 'on';
      app.ClearValidationXMenu.Text = 'Validation X';
      app.ClearValidationXMenu.Tag = 'fmenu_clear_xval';
      app.ClearValidationYMenu = uimenu(app.ClearMenu);
      app.ClearValidationYMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearValidationYMenu.Enable = 'off';
      app.ClearValidationYMenu.Text = 'Validation Y';
      app.ClearValidationYMenu.Tag = 'fmenu_clear_yval';
      app.ClearValXandYMenu = uimenu(app.ClearMenu);
      app.ClearValXandYMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalibrationXMenuSelected, true);
      app.ClearValXandYMenu.Enable = 'off';
      app.ClearValXandYMenu.Text = 'Clear Val X and Y';
      app.ClearValXandYMenu.Tag = 'fmenu_clear_bothval';
      app.EditMenu = uimenu(app.Diviner);
      app.EditMenu.Text = 'Edit';
      app.CalibrationMenu = uimenu(app.EditMenu);
      app.CalibrationMenu.Enable = 'off';
      app.CalibrationMenu.Text = 'Calibration';
      app.OpenXblockMenu = uimenu(app.CalibrationMenu);
      app.OpenXblockMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.OpenXblockMenu.Text = 'Open X-block';
      app.OpenXblockMenu.Tag = 'emenu_open_xcal';
      app.PlotXblockMenu = uimenu(app.CalibrationMenu);
      app.PlotXblockMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.PlotXblockMenu.Text = 'Plot X-block';
      app.PlotXblockMenu.Tag = 'emenu_plot_xcal';
      app.OpenYblockMenu = uimenu(app.CalibrationMenu);
      app.OpenYblockMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.OpenYblockMenu.Separator = 'on';
      app.OpenYblockMenu.Text = 'Open Y-block';
      app.OpenYblockMenu.Tag = 'emenu_open_ycal';
      app.PlotYblockMenu = uimenu(app.CalibrationMenu);
      app.PlotYblockMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.PlotYblockMenu.Text = 'Plot Y-block';
      app.PlotYblockMenu.Tag = 'emenu_plot_ycal';
      app.EmenuSelectYColumnMenu = uimenu(app.CalibrationMenu);
      app.EmenuSelectYColumnMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.EmenuSelectYColumnMenu.Text = 'Select Y-Columns';
      app.EmenuSelectYColumnMenu.Tag = 'emenu_select_ycal';
      app.ValidationMenu = uimenu(app.EditMenu);
      app.ValidationMenu.Enable = 'off';
      app.ValidationMenu.Text = 'Validation';
      app.OpenXblockMenu_2 = uimenu(app.ValidationMenu);
      app.OpenXblockMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.OpenXblockMenu_2.Text = 'Open X-block';
      app.OpenXblockMenu_2.Tag = 'emenu_open_xval';
      app.PlotXblockMenu_2 = uimenu(app.ValidationMenu);
      app.PlotXblockMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.PlotXblockMenu_2.Text = 'Plot X-block';
      app.PlotXblockMenu_2.Tag = 'emenu_plot_xval';
      app.OpenYblockMenu_2 = uimenu(app.ValidationMenu);
      app.OpenYblockMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.OpenYblockMenu_2.Separator = 'on';
      app.OpenYblockMenu_2.Text = 'Open Y-block';
      app.OpenYblockMenu_2.Tag = 'emenu_open_yval';
      app.PlotYblockMenu_2 = uimenu(app.ValidationMenu);
      app.PlotYblockMenu_2.MenuSelectedFcn = createCallbackFcn(app, @OpenXblockMenuSelected, true);
      app.PlotYblockMenu_2.Text = 'Plot Y-block';
      app.PlotYblockMenu_2.Tag = 'emenu_plot_yval';
      app.OptionsMenu = uimenu(app.EditMenu);
      app.OptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @OptionsImageClicked, true);
      app.OptionsMenu.Enable = 'off';
      app.OptionsMenu.Text = 'Options';
      app.OptionsMenu.Tag = 'emenu_options';
      app.PreferencesMenu = uimenu(app.EditMenu);
      app.PreferencesMenu.Text = 'Preferences';
      app.DisplayWarningforLongRunMenu = uimenu(app.PreferencesMenu);
      app.DisplayWarningforLongRunMenu.MenuSelectedFcn = createCallbackFcn(app, @DisplayWarningforLongRunMenuSelected, true);
      app.DisplayWarningforLongRunMenu.Text = 'Display Warning for Long Run';
      app.longrunYesMenu = uimenu(app.DisplayWarningforLongRunMenu);
      app.longrunYesMenu.MenuSelectedFcn = createCallbackFcn(app, @longrunYesMenuSelected, true);
      app.longrunYesMenu.Text = 'Yes';
      app.longrunNoMenu = uimenu(app.DisplayWarningforLongRunMenu);
      app.longrunNoMenu.MenuSelectedFcn = createCallbackFcn(app, @longrunNoMenuSelected, true);
      app.longrunNoMenu.Text = 'No';
      app.ToolsMenu = uimenu(app.Diviner);
      app.ToolsMenu.Text = 'Tools';
      app.PreprocessingMenu = uimenu(app.ToolsMenu);
      app.PreprocessingMenu.MenuSelectedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
      app.PreprocessingMenu.Enable = 'off';
      app.PreprocessingMenu.Text = 'Preprocessing';
      app.CrossValidationMenu = uimenu(app.ToolsMenu);
      app.CrossValidationMenu.MenuSelectedFcn = createCallbackFcn(app, @CrossValidationButtonPushed, true);
      app.CrossValidationMenu.Enable = 'off';
      app.CrossValidationMenu.Text = 'Cross-Validation';
      app.OutlierDetectionMenu = uimenu(app.ToolsMenu);
      app.OutlierDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
      app.OutlierDetectionMenu.Separator = 'on';
      app.OutlierDetectionMenu.Checked = 'on';
      app.OutlierDetectionMenu.Text = 'Outlier Detection';
      app.ViewMenu = uimenu(app.Diviner);
      app.ViewMenu.Text = 'View';
      app.DivinerCacheFolderMenu = uimenu(app.ViewMenu);
      app.DivinerCacheFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @DivinerCacheFolderMenuSelected, true);
      app.DivinerCacheFolderMenu.Text = 'Diviner Cache Folder';
      app.DivinerCacheFolderforCurrentRunMenu = uimenu(app.ViewMenu);
      app.DivinerCacheFolderforCurrentRunMenu.MenuSelectedFcn = createCallbackFcn(app, @DivinerCacheFolderforCurrentRunMenuSelected, true);
      app.DivinerCacheFolderforCurrentRunMenu.Text = 'Diviner Cache Folder for Current Run';
      app.ThemeMenu = uimenu(app.Diviner);
      app.ThemeMenu.Text = 'Theme';
      app.LightMenu = uimenu(app.ThemeMenu);
      app.LightMenu.MenuSelectedFcn = createCallbackFcn(app, @ThemeMenuSelected, true);
      app.LightMenu.Text = 'Light';
      app.LightMenu.Tag = 'theme_light';
      app.DarkMenu = uimenu(app.ThemeMenu);
      app.DarkMenu.MenuSelectedFcn = createCallbackFcn(app, @ThemeMenuSelected, true);
      app.DarkMenu.Text = 'Dark';
      app.DarkMenu.Tag = 'theme_dark';
      app.GridLayout = uigridlayout(app.Diviner);
      app.SessionSetupPanel = uipanel(app.GridLayout);
      app.SessionSetupPanel.Title = 'Session Setup';
      app.GridLayout3 = uigridlayout(app.SessionSetupPanel);
      app.CalibrationLabel = uilabel(app.GridLayout3);
      app.CalibrationLabel.Text = 'Calibration';
      app.ValidationLabel = uilabel(app.GridLayout3);
      app.ValidationLabel.Text = 'Validation';
      app.PreprocessingButton = uibutton(app.GridLayout3, 'push');
      app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
      app.PreprocessingButton.Tag = 'preprobtn';
      app.PreprocessingButton.Enable = 'off';
      app.PreprocessingButton.Tooltip = {'Data must be loaded'};
      app.PreprocessingButton.Text = 'Preprocessing';
      app.CrossValidationButton = uibutton(app.GridLayout3, 'push');
      app.CrossValidationButton.ButtonPushedFcn = createCallbackFcn(app, @CrossValidationButtonPushed, true);
      app.CrossValidationButton.Tag = 'crossvalbtn';
      app.CrossValidationButton.Enable = 'off';
      app.CrossValidationButton.Tooltip = {'Data must be loaded'};
      app.CrossValidationButton.Text = 'Cross-Validation';
      app.CalXButton = uibutton(app.GridLayout3, 'push');
      app.CalXButton.ButtonPushedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.CalXButton.Tag = 'xcal';
      app.CalXButton.Tooltip = {'Left click to load data or right click for options'};
      app.CalXButton.Text = 'X';
      app.CalYButton = uibutton(app.GridLayout3, 'push');
      app.CalYButton.ButtonPushedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.CalYButton.Tag = 'ycal';
      app.CalYButton.Tooltip = {'Left click to load data or right click for options'};
      app.CalYButton.Text = 'Y';
      app.ValXButton = uibutton(app.GridLayout3, 'push');
      app.ValXButton.ButtonPushedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.ValXButton.Tag = 'xval';
      app.ValXButton.Tooltip = {'Left click to load data or right click for options'};
      app.ValXButton.Text = 'X';
      app.ValYButton = uibutton(app.GridLayout3, 'push');
      app.ValYButton.ButtonPushedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.ValYButton.Tag = 'yval';
      app.ValYButton.Tooltip = {'Left click to load data or right click for options'};
      app.ValYButton.Text = 'Y';
      app.OutlierDetectionLabel = uilabel(app.GridLayout3);
      app.OutlierDetectionLabel.Text = 'Outlier Detection';
      app.OutlierSwitch = uiswitch(app.GridLayout3, 'slider');
      app.OutlierSwitch.ItemsData = {'0', '1'};
      app.OutlierSwitch.ValueChangedFcn = createCallbackFcn(app, @OutlierSwitchValueChanged, true);
      app.OutlierSwitch.Enable = 'off';
      app.OutlierSwitch.Value = '0';
      app.OutputPanel = uipanel(app.GridLayout);
      app.OutputPanel.Title = 'Output';
      app.GridLayout2 = uigridlayout(app.OutputPanel);
      app.ResultsHTML = uihtml(app.GridLayout2);
      app.OutlierImageButton = uibutton(app.GridLayout2, 'push');
      app.OutlierImageButton.ButtonPushedFcn = createCallbackFcn(app, @OutlierImageClicked, true);
      app.OutlierImageButton.Icon = 'Outliers.png';
      app.OutlierImageButton.Enable = 'off';
      app.OutlierImageButton.Tooltip = {'Plot outlier results'};
      app.OutlierImageButton.Text = '';
      app.ResultsTableImageButton = uibutton(app.GridLayout2, 'push');
      app.ResultsTableImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsTableImageClicked, true);
      app.ResultsTableImageButton.Icon = 'table_results.png';
      app.ResultsTableImageButton.Enable = 'off';
      app.ResultsTableImageButton.Tooltip = {'Open results table'};
      app.ResultsTableImageButton.Text = '';
      app.ScoresImageButton = uibutton(app.GridLayout2, 'push');
      app.ScoresImageButton.ButtonPushedFcn = createCallbackFcn(app, @ScoresImageClicked, true);
      app.ScoresImageButton.Icon = 'Results.png';
      app.ScoresImageButton.Enable = 'off';
      app.ScoresImageButton.Tooltip = {'Plot results'};
      app.ScoresImageButton.Text = '';
      app.VariableConsensusImageButton = uibutton(app.GridLayout2, 'push');
      app.VariableConsensusImageButton.ButtonPushedFcn = createCallbackFcn(app, @VariableConsensusImageClicked, true);
      app.VariableConsensusImageButton.Icon = 'Variable.png';
      app.VariableConsensusImageButton.Enable = 'off';
      app.VariableConsensusImageButton.Tooltip = {'Plot variable consensus plot'};
      app.VariableConsensusImageButton.Text = '';
      app.InfoPanel = uipanel(app.GridLayout);
      app.InfoPanel.Title = 'Info';
      app.GridLayout4 = uigridlayout(app.InfoPanel);
      app.TextArea = uitextarea(app.GridLayout4);
      app.TextArea.Editable = 'off';
      app.DIVINERLabel = uilabel(app.GridLayout);
      app.DIVINERLabel.Text = 'DIVINER';
      app.ProgressPanel = uipanel(app.GridLayout);
      app.ProgressPanel.Title = 'Progress';
      app.GridLayout5 = uigridlayout(app.ProgressPanel);
      app.NextStepText = uilabel(app.GridLayout5);
      app.NextStepText.Text = 'Set Preprocessing Library';
      app.NextStepLabel = uilabel(app.GridLayout5);
      app.NextStepLabel.Text = 'Next Step:';
      app.CurrentStepText = uilabel(app.GridLayout5);
      app.CurrentStepText.Text = 'Load Data';
      app.CurrentStepLabel = uilabel(app.GridLayout5);
      app.CurrentStepLabel.Text = 'Current Step:';
      app.ProgressHTML = uihtml(app.GridLayout5);
      app.ProgressHTML.HTMLSource = 'progressTracker.html';
      app.ProgressHTML.Tooltip = {'Click to show all steps'};
      app.HelpImageButton = uibutton(app.GridLayout, 'push');
      app.HelpImageButton.ButtonPushedFcn = createCallbackFcn(app, @HelpImageClicked, true);
      app.HelpImageButton.Icon = 'Help.png';
      app.HelpImageButton.Tooltip = {'Help'};
      app.HelpImageButton.Text = '';
      app.BrowseImageButton = uibutton(app.GridLayout, 'push');
      app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageClicked, true);
      app.BrowseImageButton.Icon = 'EigenV.png';
      app.BrowseImageButton.Tooltip = {'Open Workspace Browser'};
      app.BrowseImageButton.Text = '';
      app.LoadImageButton = uibutton(app.GridLayout, 'push');
      app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
      app.LoadImageButton.Icon = 'Open.png';
      app.LoadImageButton.Tooltip = {'Load Diviner results'};
      app.LoadImageButton.Text = '';
      app.SaveImageButton = uibutton(app.GridLayout, 'push');
      app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageClicked, true);
      app.SaveImageButton.Icon = 'Save.png';
      app.SaveImageButton.Enable = 'off';
      app.SaveImageButton.Tooltip = {'Save results'};
      app.SaveImageButton.Text = '';
      app.OptionsImageButton = uibutton(app.GridLayout, 'push');
      app.OptionsImageButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsImageClicked, true);
      app.OptionsImageButton.Icon = 'Options.png';
      app.OptionsImageButton.Enable = 'off';
      app.OptionsImageButton.Tooltip = {'Options settings'};
      app.OptionsImageButton.Text = '';
      app.PCAImageButton = uibutton(app.GridLayout, 'push');
      app.PCAImageButton.ButtonPushedFcn = createCallbackFcn(app, @PCAImageClicked, true);
      app.PCAImageButton.Icon = 'PCA.png';
      app.PCAImageButton.Enable = 'off';
      app.PCAImageButton.Tooltip = {'Send data to PCA Analysis'};
      app.PCAImageButton.Text = '';
      app.StartImageButton = uibutton(app.GridLayout, 'push');
      app.StartImageButton.ButtonPushedFcn = createCallbackFcn(app, @StartImageClicked, true);
      app.StartImageButton.Icon = 'Run.png';
      app.StartImageButton.Enable = 'off';
      app.StartImageButton.Tooltip = {'Start Diviner run'};
      app.StartImageButton.Text = '';
      app.ContextMenu = uicontextmenu(app.Diviner);
      % Assign app.ContextMenu
      app.CalXButton.ContextMenu = app.ContextMenu;
      app.CalYButton.ContextMenu = app.ContextMenu;
      app.ValXButton.ContextMenu = app.ContextMenu;
      app.ValYButton.ContextMenu = app.ContextMenu;
      app.LoadDataContextMenu = uimenu(app.ContextMenu);
      app.LoadDataContextMenu.Text = 'Load Data';
      app.LoadDataContextMenu.Tag = 'loaddata_cmenu';
      app.PlotDataMenu = uimenu(app.ContextMenu);
      app.PlotDataMenu.Enable = 'off';
      app.PlotDataMenu.Text = 'Plot Data';
      app.PlotDataMenu.Tag = 'plotdata_cmenu';
      app.EditDataMenu = uimenu(app.ContextMenu);
      app.EditDataMenu.Enable = 'off';
      app.EditDataMenu.Text = 'Edit Data';
      app.EditDataMenu.Tag = 'editdata_cmenu';
      app.SaveDataMenu = uimenu(app.ContextMenu);
      app.SaveDataMenu.Enable = 'off';
      app.SaveDataMenu.Separator = 'on';
      app.SaveDataMenu.Text = 'Save Data';
      app.SaveDataMenu.Tag = 'savedata_cmenu';
      app.ClearDataMenu = uimenu(app.ContextMenu);
      app.ClearDataMenu.Enable = 'off';
      app.ClearDataMenu.Text = 'Clear Data';
      app.ClearDataMenu.Tag = 'cleardata_cmenu';
      app.LoadXandYMenu = uimenu(app.ContextMenu);
      app.LoadXandYMenu.Separator = 'on';
      app.LoadXandYMenu.Text = 'Load X and Y';
      app.LoadXandYMenu.Tag = 'loaddataboth_cmenu';
      app.ClearXandYMenu = uimenu(app.ContextMenu);
      app.ClearXandYMenu.Enable = 'off';
      app.ClearXandYMenu.Text = 'Clear X and Y';
      app.ClearXandYMenu.Tag = 'cleardataboth_cmenu';
      app.SelectYColumnMenu = uimenu(app.ContextMenu);
      app.SelectYColumnMenu.Enable = 'off';
      app.SelectYColumnMenu.Separator = 'on';
      app.SelectYColumnMenu.Text = 'Select Y Column';
      app.SelectYColumnMenu.Tag = 'selectycol_cmenu';
      % Show the figure after all components are created
      app.Diviner.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = diviner_guifcn

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.Diviner)

        % Execute the startup function
        runStartupFcn(app, @startupFcn)
      else

        % Focus the running singleton app
        figure(runningApp.Diviner)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.Diviner)
    end
  end
end