classdef divinertable < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    DivinerResultsTableUIFigure    matlab.ui.Figure
    FileMenu                       matlab.ui.container.Menu
    ExportTableMenu                matlab.ui.container.Menu
    GridLayout                     matlab.ui.container.GridLayout
    UITable                        matlab.ui.control.Table
    FilterResultsPanel             matlab.ui.container.Panel
    GridLayout2                    matlab.ui.container.GridLayout
    NumberofLVsListBoxLabel        matlab.ui.control.Label
    NumberofLVsListBox             matlab.ui.control.ListBox
    RMSECLabel                     matlab.ui.control.Label
    RMSECVLabel                    matlab.ui.control.Label
    OverfitRMSECVRMSECLabel        matlab.ui.control.Label
    RMSEPLabel                     matlab.ui.control.Label
    rmsecoperator                  matlab.ui.control.DropDown
    rmsecvoperator                 matlab.ui.control.DropDown
    overfitoperator                matlab.ui.control.DropDown
    rmsepoperator                  matlab.ui.control.DropDown
    rmsecvalue                     matlab.ui.control.NumericEditField
    rmsecvvalue                    matlab.ui.control.NumericEditField
    overfitvalue                   matlab.ui.control.NumericEditField
    rmsepvalue                     matlab.ui.control.NumericEditField
    ClearFiltersButton             matlab.ui.control.Button
    ModelTypeDropDown              matlab.ui.control.ListBox
    ModelTypeListBoxLabel          matlab.ui.control.Label
    VariableSelectionListBoxLabel  matlab.ui.control.Label
    VariableSelectionDropDown      matlab.ui.control.ListBox
    ModuleListBoxLabel             matlab.ui.control.Label
    ModuleDropDown                 matlab.ui.control.ListBox
    TextArea                       matlab.ui.control.TextArea
    UpdateButton                   matlab.ui.control.Button
    HelpButton                     matlab.ui.control.Button
  end

  
  properties (Access = public)
    results % diviner results
    tbl % original table object
    styles % colormapping of rows
    hasvalid % has rmsep flag
    columnsToShow % columns from table to display
    colorhash % lookup for cell coloring
    cmap % colormap
    rmsecind
    rmsecvind
    overfitind
    rmsepind
  end
  
  methods (Access = private)
    
    function setupWidgets(app,step)
      if strcmpi(step,'setup')
        unq = cellstr(unique(app.tbl.Module))';
        app.ModuleDropDown.Items = {'--All--' unq{:}};
        unq = cellstr(unique(app.tbl.VarSel))';
        app.VariableSelectionDropDown.Items = {'--All--' unq{:}};
        unq = cellstr(unique(app.tbl.Modeltype))';
        app.ModelTypeDropDown.Items = {'--All--' unq{:}};
        nlvs = app.tbl.Nlvs;
        if any(isnan(nlvs))
          nlvs(find(isnan(nlvs))) = [];
          nlvs(end+1) = NaN;
        end
        unq = cellstr(num2str(unique(nlvs)))';
        app.NumberofLVsListBox.Items = {'--All--' unq{:}};
      elseif strcmpi(step,'filter')
%         if ~isempty(app.UITable.Data)
%           unq = cellstr(unique(app.UITable.Data.Module))';
%           
%           app.ModuleDropDown.Items = {'--All--' unq{:}};
%           unq = cellstr(unique(app.UITable.Data.VarSel))';
%           app.VariableSelectionDropDown.Items = {'--All--' unq{:}};
%           unq = cellstr(unique(app.UITable.Data.Modeltype))';
%           app.ModelTypeDropDown.Items = {'--All--' unq{:}};
%           nlvs = app.UITable.Data.Nlvs;
%           if any(isnan(nlvs))
%             nlvs(find(isnan(nlvs))) = [];
%             nlvs(end+1) = NaN;
%           end
%           unq = cellstr(num2str(unique(nlvs)))';
%           app.NumberofLVsListBox.Items = {'--All--' unq{:}};
%         end
      end
    end
    
    function filter(app)
      %profile on
      filteredtable = app.tbl;
      txt = {};
      
      %filter modeltype
      if ~strcmpi(app.ModelTypeDropDown.Value,'--all--')
        thismodeltype = find(ismember(filteredtable.Modeltype,app.ModelTypeDropDown.Value));
        filteredtable = filteredtable(thismodeltype,:);
        txt = {txt{:} ['Model Type: ' app.prettyprint(app.ModelTypeDropDown.Value)]};
        txt = {txt{:} newline};
      end
      
      %filter variable selection
      if ~strcmpi(app.VariableSelectionDropDown.Value,'--all--')
        thisvarsel = find(ismember(filteredtable.VarSel,app.VariableSelectionDropDown.Value));
        filteredtable = filteredtable(thisvarsel,:);
        txt = {txt{:} ['Variable Selection: ' app.prettyprint(app.VariableSelectionDropDown.Value)]};
        txt = {txt{:} newline};
      end
      
      %filter module
      if ~strcmpi(app.ModuleDropDown.Value,'--all--')
        thismodule = find(ismember(filteredtable.Module,app.ModuleDropDown.Value));
        filteredtable = filteredtable(thismodule,:);
        txt = {txt{:} ['Module: ' app.prettyprint(app.ModuleDropDown.Value) newline]};
        txt = {txt{:} newline};
      end
      
      % filter lvs
      if ~strcmpi(app.NumberofLVsListBox.Value,'--all--')
        ismembernan = @(a,b) ismember(a,b) | (isnan(a) & any(isnan(b)));
        theselvs = find(ismembernan(filteredtable.Nlvs,cellfun(@str2num,app.NumberofLVsListBox.Value)));
        filteredtable = filteredtable(theselvs,:);
        txt = {txt{:} ['LVs: ' app.prettyprint(app.NumberofLVsListBox.Value)]};
        txt = {txt{:} newline};
      end
      
      % filter rmsec
      func = str2func(app.rmsecoperator.Value);
      if ~isinf(app.rmsecvalue.Value)
        thesermsecs = find(feval(func,filteredtable.RMSEC,app.rmsecvalue.Value));
        filteredtable = filteredtable(thesermsecs,:);
        txt = {txt{:} ['RMSEC: ' app.rmsecoperator.Value ' ' num2str(app.rmsecvalue.Value)]};
        txt = {txt{:} newline};
      end
      
      % filter rmsecv
      func = str2func(app.rmsecvoperator.Value);
      if ~isinf(app.rmsecvvalue.Value)
        thesermsecvs = find(feval(func,filteredtable.RMSECV,app.rmsecvvalue.Value));
        filteredtable = filteredtable(thesermsecvs,:);
        txt = {txt{:} ['RMSECV: ' app.rmsecvoperator.Value ' ' num2str(app.rmsecvvalue.Value)]};
        txt = {txt{:} newline};
      end
      
      % filter overfit
      func = str2func(app.overfitoperator.Value);
      if ~isinf(app.overfitvalue.Value)
        theseoverfits = find(feval(func,filteredtable.RMSECV_RMSEC,app.overfitvalue.Value));
        filteredtable = filteredtable(theseoverfits,:);
        txt = {txt{:} ['Overfit: ' app.overfitoperator.Value ' ' num2str(app.overfitvalue.Value)]};
        txt = {txt{:} newline};
      end
      
      % filter rmsep
      if app.hasvalid
        func = str2func(app.rmsepoperator.Value);
        if ~isinf(app.rmsepvalue.Value)
          thesermseps = find(feval(func,filteredtable.RMSEP,app.rmsepvalue.Value));
          filteredtable = filteredtable(thesermseps,:);
          txt = {txt{:} ['RMSEP: ' app.rmsepoperator.Value ' ' num2str(app.rmsepvalue.Value)]};
          txt = {txt{:} newline};
        end
      end
      
      
      if isempty(txt)
        txt = {'No filters'};
      end
      txt = {txt{:} ['Number of models: ' num2str(size(filteredtable,1))]}';
      app.TextArea.Value = txt;
      % set the current table to filtered table
      prog = uiprogressdlg(app.DivinerResultsTableUIFigure,'Indeterminate','on','Message','Filtering Table. Please wait...','Title','Filtering Results');
      app.UITable.Visible = 'off';
      if size(filteredtable,1)~=0
        % did table change?
        if size(filteredtable,1)==size(app.UITable.Data,1) && isequal(app.TextArea.Value,txt)
          % don't change table, this call isTableDataEqual, which can be
          % slow
          pause(0.3)
        else
          removeStyle(app.UITable);
          app.UITable.Data = filteredtable;
          % figure out coloring
          if size(app.UITable.Data,1)==size(app.tbl,1)
            % no filters
            app.doOriginalColoring();
          else
            % now do coloring by unique colors in filtered map
            if size(app.UITable.Data,1)~=0
              for i=1:size(app.cmap,1)
                colorrows = [];
                colorcols = [];
                % find rmsec cells that have this color
                inds = find(ismember(cell2mat(app.colorhash(app.UITable.Data.Index,1)),app.cmap(i,:),'rows'));
                if ~isempty(inds)
                  colorrows = [colorrows; inds];
                  colorcols = [colorcols; repmat(app.rmsecind,length(inds),1)];
                end
                % find rmsecv cells that have this color
                inds = find(ismember(cell2mat(app.colorhash(app.UITable.Data.Index,2)),app.cmap(i,:),'rows'));
                if ~isempty(inds)
                  colorrows = [colorrows; inds];
                  colorcols = [colorcols; repmat(app.rmsecvind,length(inds),1)];
                end
                % find overfit cells that have this color
                inds = find(ismember(cell2mat(app.colorhash(app.UITable.Data.Index,3)),app.cmap(i,:),'rows'));
                if ~isempty(inds)
                  colorrows = [colorrows; inds];
                  colorcols = [colorcols; repmat(app.overfitind,length(inds),1)];
                end
                if app.hasvalid
                  % find rmsep cells that have this color
                  inds = find(ismember(cell2mat(app.colorhash(app.UITable.Data.Index,4)),app.cmap(i,:),'rows'));
                  if ~isempty(inds)
                    colorrows = [colorrows; inds];
                    colorcols = [colorcols; repmat(app.rmsepind,length(inds),1)];
                  end
                end
                colormatrix = [colorrows colorcols];
                if ~isempty(colormatrix)
                  addStyle(app.UITable,uistyle('BackgroundColor',app.cmap(i,:)),'cell',colormatrix);
                end
              end
            end
          end
        end
      else
        % this is much faster
        app.UITable.Data = table();
      end
      
      
      app.setupWidgets('filter');
      delete(prog)
      if size(app.UITable.Data,1)==0
        uiconfirm(app.DivinerResultsTableUIFigure,'No Models fit the query.','No Models to Display','Options',{'OK'})
      end
      app.UITable.Visible = 'on';
      %profile viewer
    end
    
    function displaystring = prettyprint(app,values)
      displaystring = char(values{1});
      if length(values)==1
        return
      else
        for i=2:length(values)
          displaystring = [displaystring ', ' values{i}];
        end
      end
    end
    
    function resultsTableOpenModels(app,varargin)
      mySelection = app.UITable.Data.Index(app.UITable.Selection);
      prog = uiprogressdlg(app.DivinerResultsTableUIFigure,'Cancelable','on','ShowPercentage','on','Message','Retrieving Diviner model(s), please wait...','Title','Diviner Model Retrieval Progress');
      for i=1:length(mySelection)
        if prog.CancelRequested
          break
        end
        mySelectedModel = recreatemodel(app.results,mySelection(i));
        plsobj = evrigui('analysis');
        plsobj.setModel(mySelectedModel);
        prog.Value = i/length(mySelection);
      end
      if prog.CancelRequested
        uiconfirm(app.DivinerResultsTableUIFigure,'The model retrieval was cancelled.','Retrieval Cancelled','Options',{'OK'})
      end
      close(prog)
    end
    
    function resultsTableSaveModels(app,varargin)
      mySelection = app.UITable.Data.Index(app.UITable.Selection);
      prog = uiprogressdlg(app.DivinerResultsTableUIFigure,'Cancelable','on','ShowPercentage','on','Message','Retrieving Diviner model(s), please wait...','Title','Diviner Model Retrieval Progress');
      for i=1:length(mySelection)
        if prog.CancelRequested
          break
        end
        mySelectedModel = recreatemodel(app.results,mySelection(i));
        prog.Value = i/length(mySelection);
        svdlgpls(mySelectedModel,'Save Diviner Results',['selectedModel' num2str(mySelection(i))]);
      end
      if prog.CancelRequested
        uiconfirm(app.DivinerResultsTableUIFigure,'The model retrieval was cancelled.','Retrieval Cancelled','Options',{'OK'})
      end
      close(prog)
    end
    
    function doOriginalColoring(app)
      % build colormap
      n = min(size(app.tbl,1),40);   %// number of colors
      app.cmap = [];
      app.cmap(1,:) = [1 0 0];   %// color first row - red
      app.cmap(2,:) = [1 1 0];   %// color middle row - yellow
      app.cmap(3,:) = [0 1 0];   %// color nth row - green
      [X,Y] = meshgrid([1:3],[1:n]);  %// mesh of indices
      app.cmap = interp2(X([1,floor(n/2),n],:),Y([1,floor(n/2),n],:),app.cmap,X,Y);
      % color RMSEC
      [~,rmsecorder] = sort(app.tbl.RMSEC,'descend');
      [~,rmsecvorder] = sort(app.tbl.RMSECV,'descend');
      [~,overfitorder] = sort(app.tbl.RMSECV_RMSEC,'descend');
      app.rmsecind = find(cellfun(@(x) strcmpi(x,'RMSEC'),app.columnsToShow));
      app.rmsecvind = find(cellfun(@(x) strcmpi(x,'RMSECV'),app.columnsToShow));
      app.overfitind = find(cellfun(@(x) strcmpi(x,'RMSECV_RMSEC'),app.columnsToShow));
      ncols = 3;
      if app.hasvalid
        [~,rmseporder] = sort(app.tbl.RMSEP,'descend');
        app.rmsepind = find(cellfun(@(x) strcmpi(x,'RMSEP'),app.columnsToShow));
        ncols = 4;
      end
      increment = max(ceil(size(app.tbl,1)/size(app.cmap,1)),1);
      app.colorhash = cell(size(app.tbl,1),ncols);
      for j = 1:size(app.cmap,1)
        inds = (j-1)*increment+1:(min(j*increment,size(app.tbl,1)));
        %rmsec coloring
        app.colorhash(rmsecorder(inds),1) = {app.cmap(j,:)};
        %rmsecv coloring
        app.colorhash(rmsecvorder(inds),2) = {app.cmap(j,:)};
        % overfit coloring
        app.colorhash(overfitorder(inds),3) = {app.cmap(j,:)};
        if ~app.hasvalid
          addStyle(app.UITable,uistyle('BackgroundColor',app.cmap(j,:)),...
            'cell',[[rmsecorder(inds); rmsecvorder(inds); overfitorder(inds)],...
            [repmat(app.rmsecind,length(inds),1);repmat(app.rmsecvind,length(inds),1);repmat(app.overfitind,length(inds),1)]]);
        else
          % rmsep coloring
          app.colorhash(rmseporder(inds),4) = {app.cmap(j,:)};
          addStyle(app.UITable,uistyle('BackgroundColor',app.cmap(j,:)),...
            'cell',[[rmsecorder(inds); rmsecvorder(inds); overfitorder(inds);rmseporder(inds)],...
            [repmat(app.rmsecind,length(inds),1);repmat(app.rmsecvind,length(inds),1);repmat(app.overfitind,length(inds),1);repmat(app.rmsepind,length(inds),1)]]);
        end
      end
      app.styles = app.UITable.StyleConfigurations;
    end
  end
  
  methods (Access = public)
    
    function resultsTableSaveModelsCell(app,varargin)
      mySelection = app.UITable.Data.Index(app.UITable.Selection);
      modelcellarray = cell(1,length(mySelection));
      prog = uiprogressdlg(app.DivinerResultsTableUIFigure,'Cancelable','on','ShowPercentage','on','Message','Retrieving Diviner model(s), please wait...','Title','Diviner Model Retrieval Progress');
      for i=1:length(mySelection)
        if prog.CancelRequested
          break
        end
        modelcellarray{i} = recreatemodel(app.results,mySelection(i));
        prog.Value = i/length(mySelection);
      end
      if ~prog.CancelRequested
        svdlgpls(modelcellarray,'Save Diviner Results','modelscell');
      else
        uiconfirm(app.DivinerResultsTableUIFigure,'The model retrieval was cancelled.','Retrieval Cancelled','Options',{'OK'})
      end
      close(prog)
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, results, varargin)
      % handle color theme
      if ~isempty(varargin)
        switch varargin{1}
          case 'dark'
            app.GridLayout.BackgroundColor = [0.13,0.27,0.40];
            app.FilterResultsPanel.ForegroundColor = [0.05,0.16,0.25];
            app.FilterResultsPanel.BackgroundColor = [0.84,0.93,1.00];
            app.GridLayout2.BackgroundColor = [0.84,0.93,1.00];
          case 'light'
            app.GridLayout.BackgroundColor = [0.8 0.8 0.8];
            app.FilterResultsPanel.ForegroundColor = [0 0 0];
            app.FilterResultsPanel.BackgroundColor = [0.94 0.94 0.94];
            app.GridLayout2.BackgroundColor = [0.94 0.94 0.94];
        end
      end
      
      
      app.results = results;
      app.tbl = app.results.errortable;
      app.hasvalid = ismember('RMSEP',app.tbl.Properties.VariableNames);
      if app.hasvalid
        app.tbl = movevars(app.tbl,{'RMSEC' 'RMSECV' 'RMSECV_RMSEC' 'RMSEP'},"After",'Nlvs');
      else
        app.tbl = movevars(app.tbl,{'RMSEC' 'RMSECV' 'RMSECV_RMSEC'},"After",'Nlvs');
        % disable rmsepoverator and numeric field
        app.rmsepoperator.Enable = 'off';
        app.rmsepvalue.Enable = 'off';
      end
      app.columnsToShow = {'Index' 'Modeltype' 'Nlvs' 'RMSEC' 'RMSECV' 'RMSECV_RMSEC' 'RMSEP' 'VarSel' 'NumVars' 'Module' '# of Outliers Reincluded' ...
        'XppDescription' 'YppDescription'};
      presentColumns = ismember(app.tbl.Properties.VariableNames,app.columnsToShow);
      sortableVector = false(size(app.tbl,2),1);
      if app.hasvalid
        sortableVector(4:7) = true;
      else
        sortableVector(4:6) = true;
      end
      set(app.UITable,'tag','results_table','ColumnSortable',sortableVector');
      cm = uicontextmenu('Parent', app.DivinerResultsTableUIFigure);
      uimenu(cm,'Label','Open Model in Analysis','Callback',@app.resultsTableOpenModels,'tag','diviner_analysis_openmodel');
      uimenu(cm,'Label','Save Model to workspace','Callback',@app.resultsTableSaveModels,'tag','diviner_analysis_savemodel');
      uimenu(cm,'Label','Save Models as cell array','Callback',@app.resultsTableSaveModelsCell,'tag','diviner_analysis_savemodelcell');
      app.tbl = app.tbl(:,presentColumns);
      app.UITable.Data = app.tbl;
      app.UITable.ColumnName = app.tbl.Properties.VariableNames;
      app.UITable.ContextMenu = cm;
      app.UITable.SelectionType = 'row';
      app.UITable.Multiselect = 'on';
      
      % color table
      app.doOriginalColoring();
      
      % setup widgets with values
      app.setupWidgets('setup');
      app.TextArea.Value = {'No filters'; ['Number of models: ' num2str(size(app.tbl,1))]};
    end

    % Button pushed function: ClearFiltersButton
    function ClearFiltersButtonPushed(app, event)
      app.ModelTypeDropDown.Value = '--All--';
      scroll(app.ModelTypeDropDown,'--All--');
      app.ModuleDropDown.Value = '--All--';
      scroll(app.ModuleDropDown,'--All--');
      app.VariableSelectionDropDown.Value = '--All--';
      scroll(app.VariableSelectionDropDown,'--All--');
      app.NumberofLVsListBox.Value = {'--All--'};
      scroll(app.NumberofLVsListBox,'--All--');
      app.rmsecoperator.Value = '<';
      app.rmsecvoperator.Value = '<';
      app.overfitoperator.Value = '<';
      app.rmsepoperator.Value = '<';
      app.rmsecvalue.Value = inf;
      app.rmsecvvalue.Value = inf;
      app.overfitvalue.Value = inf;
      app.rmsepvalue.Value = inf;
      app.filter;
    end

    % Button pushed function: UpdateButton
    function UpdateButtonPushed(app, event)
      app.filter;
    end

    % Button pushed function: HelpButton
    function HelpButtonPushed(app, event)
      divinerhelp('table');
    end

    % Menu selected function: ExportTableMenu
    function ExportTableMenuSelected(app, event)
      t = app.UITable.Data;
      [file,location] = uiputfile({'*.csv';'*.txt';'*.xlsx'});
      if ~ischar(file) || ~ischar(location)
        return
      else
        writetable(t,[location file]);
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.DivinerResultsTableUIFigure = uifigure('Visible', 'off');
      app.DivinerResultsTableUIFigure.Name = 'Diviner Results Table';
      app.FileMenu = uimenu(app.DivinerResultsTableUIFigure);
      app.FileMenu.Text = 'File';
      app.ExportTableMenu = uimenu(app.FileMenu);
      app.ExportTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTableMenuSelected, true);
      app.ExportTableMenu.Text = 'Export Table';
      app.GridLayout = uigridlayout(app.DivinerResultsTableUIFigure);
      app.UITable = uitable(app.GridLayout);
      app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
      app.UITable.RowName = {};
      app.FilterResultsPanel = uipanel(app.GridLayout);
      app.FilterResultsPanel.Title = 'Filter Results';
      app.GridLayout2 = uigridlayout(app.FilterResultsPanel);
      app.NumberofLVsListBoxLabel = uilabel(app.GridLayout2);
      app.NumberofLVsListBoxLabel.Text = 'Number of LVs';
      app.NumberofLVsListBox = uilistbox(app.GridLayout2);
      app.NumberofLVsListBox.Multiselect = 'on';
      app.NumberofLVsListBox.Tooltip = {'Filter Diviner results by how many Latent Variables are in the model'};
      app.NumberofLVsListBox.Value = {'Item 1'};
      app.RMSECLabel = uilabel(app.GridLayout2);
      app.RMSECLabel.Text = 'RMSEC';
      app.RMSECVLabel = uilabel(app.GridLayout2);
      app.RMSECVLabel.Text = 'RMSECV';
      app.OverfitRMSECVRMSECLabel = uilabel(app.GridLayout2);
      app.OverfitRMSECVRMSECLabel.Text = 'Overfit (RMSECV/RMSEC)';
      app.RMSEPLabel = uilabel(app.GridLayout2);
      app.RMSEPLabel.Text = 'RMSEP';
      app.rmsecoperator = uidropdown(app.GridLayout2);
      app.rmsecoperator.Items = {'<', '<=', '>', '>='};
      app.rmsecoperator.Value = '<';
      app.rmsecvoperator = uidropdown(app.GridLayout2);
      app.rmsecvoperator.Items = {'<', '<=', '>', '>='};
      app.rmsecvoperator.Value = '<';
      app.overfitoperator = uidropdown(app.GridLayout2);
      app.overfitoperator.Items = {'<', '<=', '>', '>='};
      app.overfitoperator.Value = '<';
      app.rmsepoperator = uidropdown(app.GridLayout2);
      app.rmsepoperator.Items = {'<', '<=', '>', '>='};
      app.rmsepoperator.Value = '<';
      app.rmsecvalue = uieditfield(app.GridLayout2, 'numeric');
      app.rmsecvalue.Value = Inf;
      app.rmsecvvalue = uieditfield(app.GridLayout2, 'numeric');
      app.rmsecvvalue.Value = Inf;
      app.overfitvalue = uieditfield(app.GridLayout2, 'numeric');
      app.overfitvalue.Value = Inf;
      app.rmsepvalue = uieditfield(app.GridLayout2, 'numeric');
      app.rmsepvalue.Value = Inf;
      app.ClearFiltersButton = uibutton(app.GridLayout2, 'push');
      app.ClearFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFiltersButtonPushed, true);
      app.ClearFiltersButton.Text = 'Clear Filters';
      app.ModelTypeDropDown = uilistbox(app.GridLayout2);
      app.ModelTypeDropDown.Multiselect = 'on';
      app.ModelTypeDropDown.Tooltip = {'Filter Diviner results by Model Type'};
      app.ModelTypeDropDown.Value = {'Item 1'};
      app.ModelTypeListBoxLabel = uilabel(app.GridLayout2);
      app.ModelTypeListBoxLabel.Text = 'Model Type';
      app.VariableSelectionListBoxLabel = uilabel(app.GridLayout2);
      app.VariableSelectionListBoxLabel.Text = 'Variable Selection';
      app.VariableSelectionDropDown = uilistbox(app.GridLayout2);
      app.VariableSelectionDropDown.Multiselect = 'on';
      app.VariableSelectionDropDown.Tooltip = {'Filter models by which Variable Selection was used'};
      app.VariableSelectionDropDown.Value = {'Item 1'};
      app.ModuleListBoxLabel = uilabel(app.GridLayout2);
      app.ModuleListBoxLabel.Text = 'Module';
      app.ModuleDropDown = uilistbox(app.GridLayout2);
      app.ModuleDropDown.Multiselect = 'on';
      app.ModuleDropDown.Tooltip = {'Filter Diviner results by which Module the model was created from'};
      app.ModuleDropDown.Value = {'Item 1'};
      app.TextArea = uitextarea(app.GridLayout2);
      app.TextArea.Editable = 'off';
      app.TextArea.Value = {'No filters'};
      app.UpdateButton = uibutton(app.GridLayout2, 'push');
      app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
      app.UpdateButton.Text = 'Update';
      app.HelpButton = uibutton(app.GridLayout2, 'push');
      app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
      app.HelpButton.Text = 'Help';
      % Show the figure after all components are created
      app.DivinerResultsTableUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = divinertable(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.DivinerResultsTableUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.DivinerResultsTableUIFigure)
    end
  end
end