classdef divinerhelp < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    HelpUIFigure  matlab.ui.Figure
    GridLayout    matlab.ui.container.GridLayout
    CloseButton   matlab.ui.control.Button
    Image         matlab.ui.control.Image
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, helptype)
      switch helptype
        case 'outliers'
          imgsource = 'outlierselection.gif';
        case 'table'
          imgsource = 'resultstable.gif';
        otherwise
          evrierrordlg('Unrecognized help tag.')
          close(app.HelpUIFigure)
          return
      end
      app.Image.ImageSource = imgsource;
    end

    % Button pushed function: CloseButton
    function CloseButtonPushed(app, event)
      delete(app.HelpUIFigure)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.HelpUIFigure = uifigure('Visible', 'off');
      app.HelpUIFigure.Name = 'Results Table Help';
      app.GridLayout = uigridlayout(app.HelpUIFigure);
      app.CloseButton = uibutton(app.GridLayout, 'push');
      app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
      app.CloseButton.Text = 'Close';
      app.Image = uiimage(app.GridLayout);
      % Show the figure after all components are created
      app.HelpUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = divinerhelp(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.HelpUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.HelpUIFigure)
    end
  end
end