classdef hmacCvGui < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                        matlab.ui.Figure
    GridLayout2                     matlab.ui.container.GridLayout
    Panel_15                        matlab.ui.container.Panel
    GridLayout3                     matlab.ui.container.GridLayout
    DropDown                        matlab.ui.control.DropDown
    CrossValMethodLabel             matlab.ui.control.Label
    CrossValByLabel                 matlab.ui.control.Label
    DropDown_2                      matlab.ui.control.DropDown
    Panel_16                        matlab.ui.container.Panel
    GridLayout4                     matlab.ui.container.GridLayout
    LoadButton                      matlab.ui.control.Button
    SelectClassSetButton            matlab.ui.control.Button
    HelpButton                      matlab.ui.control.Button
    Panel_17                        matlab.ui.container.Panel
    GridLayout5                     matlab.ui.container.GridLayout
    MaximumNumberofLVsSlider        matlab.ui.control.Slider
    MaximumNumberofLVsSliderLabel   matlab.ui.control.Label
    NumberofSplitsSlider            matlab.ui.control.Slider
    NumberofSplitsSliderLabel       matlab.ui.control.Label
    SamplesperBlindThicknessSlider  matlab.ui.control.Slider
    SamplesperBlindThicknessSliderLabel  matlab.ui.control.Label
    Label                           matlab.ui.control.Label
    Button_2                        matlab.ui.control.Button
    maxLabel                        matlab.ui.control.Label
    Button_3                        matlab.ui.control.Button
    Label_2                         matlab.ui.control.Label
    Button_4                        matlab.ui.control.Button
    Button_6                        matlab.ui.control.Button
    Button_5                        matlab.ui.control.Button
    Button_7                        matlab.ui.control.Button
    Label_4                         matlab.ui.control.Label
    Label_5                         matlab.ui.control.Label
    Label_6                         matlab.ui.control.Label
    maxLabel_3                      matlab.ui.control.Label
    maxLabel_7                      matlab.ui.control.Label
    Label_3                         matlab.ui.control.Label
    ViewSplitsinDataSetEditorButton  matlab.ui.control.Button
    Panel_18                        matlab.ui.container.Panel
    GridLayout                      matlab.ui.container.GridLayout
    ResetButton                     matlab.ui.control.Button
    OKButton                        matlab.ui.control.Button
    CancelButton                    matlab.ui.control.Button
    ApplyButton                     matlab.ui.control.Button
    HTML                            matlab.ui.control.HTML
    UIAxes                          matlab.ui.control.UIAxes
  end

  
  properties (Access = public)
    fig     % Gui for CV
    cv      % Previous CV settings to be populated in GUI, if provided
    x       % XBlock
    file    % File calling this object (mfilename)
  end
  
  methods (Access = private)
    
    function adjustSliders(app)
      nsamps = size(app.x,1);
      lastcvoptions = getappdata(app.fig,'lastcvoptions');
      % Adjust max lvs
      
      % Get ranks
      if isdataset(app.x)
        r = rank(app.x.data);
      else
        r = rank(app.x);
      end
      val = min(r,20);
      sld1 = findobj(app.fig,'tag','lvslider');
      if r > 1
        set(sld1,'Limits',[1 val]);
        curval = min(val,lastcvoptions.maxlvs);
      else
        curval = 1;
      end
      set(sld1,'Value',curval);
      sld1lblval = findobj(app.fig,'tag','sldlblval1');
      set(sld1lblval,'Text',num2str(curval));
      sldmaxlbl1 = findobj(app.fig,'tag','sldmaxlbl1');
      set(sldmaxlbl1,'Text',num2str(val));
      
      % Adjust number of splits
      
      sld2 = findobj(app.fig,'tag','spltslider');
      if floor(nsamps/2)>2
        smax = min(100,floor(nsamps/2));
      else
        smax = 2;
      end
      if smax > 10
        curval = lastcvoptions.cvi{2};
      else
        curval = floor(smax/2);
      end
      set(sld2,'Limits',[1,smax]);
      set(sld2,'Value',curval);
      sld2lblval = findobj(app.fig,'Tag','sldlblval2');
      set(sld2lblval,'Text',num2str(curval));
      setappdata(app.fig,'sldlblval2',curval);
      sldmaxlbl2 = findobj(app.fig,'Tag','sldmaxlbl2');
      set(sldmaxlbl2,'Text',num2str(smax));
      
      % Adjust iterations slider
      
      sld3 = findobj(app.fig,'Tag','spbslider');
      set(sld3,'Limits',[1,10]);
      set(sld3,'Value',lastcvoptions.cvi{3});
      sld3lblval = findobj(app.fig,'Tag','sldlblval3');
      set(sld3lblval,'Text',num2str(lastcvoptions.cvi{3}));
      setappdata(app.fig,'sldlblval3',lastcvoptions.cvi{3});
      sldmaxlbl3 = findobj(app.fig,'Tag','sldmaxlbl3');
      set(sldmaxlbl3,'Text',num2str(10));
    end
    
    function add_Callback(app,varargin)
      handletag = varargin{1};
      lbltag = varargin{2};
      sliderh = findobj(app.fig,'tag',handletag);
      lblh = findobj(app.fig,'tag',lbltag);
      newval = get(sliderh,'Value') + 1;
      lim = get(sliderh,'Limits');
      if(newval > lim(2))
        % slider is above upper limit, don't wanna change anything here, invalid input
      else
        newlbl = num2str(newval);
        set(sliderh,'Value',newval);
        set(lblh,'Text',newlbl);
        %setappdata(app.fig,lbltag,newval);
        
        % Enable Apply, Reset, and OK buttons
        applybtn = findall(app.fig,'Tag','applybtn');
        set(applybtn,'Enable','on');
        resetbtn = findall(app.fig,'Tag','resetbtn');
        set(resetbtn,'Enable','on');
        okbtn = findall(app.fig,'Tag','okbtn');
        set(okbtn,'Enable','on');
        cancelbtn = findobj(app.fig,'Tag','cancelbtn');
        set(cancelbtn,'Text','Cancel','Tooltip','Cancel and reset settings');
      end
      % update the cvi
      switch lbltag(end)
        case '1'
          app.cv.maxlvs = get(sliderh,'Value');
        case '2'
          app.cv.cvi{2} = get(sliderh,'Value');
        case '3'
          app.cv.cvi{3} = get(sliderh,'Value');
      end
      setappdata(app.fig,'cvi',app.cv.cvi);
      setappdata(app.fig,'maxlvs',app.cv.maxlvs);
      app.updateAxes();
    end
    
    function subtract_Callback(app,varargin)
      handletag = varargin{1};
      lbltag = varargin{2};
      sliderh = findobj(app.fig,'tag',handletag);
      lblh = findobj(app.fig,'tag',lbltag);
      newval = get(sliderh,'Value') - 1;
      lim = get(sliderh,'Limits');
      if(newval < lim(1))
        % slider is above upper limit, don't wanna change anything here, invalid input
      else
        newlbl = num2str(newval);
        set(sliderh,'Value',newval);
        set(lblh,'Text',newlbl);
        setappdata(app.fig,lbltag,newval);
        
        % Enable Apply, Reset, and OK buttons
        applybtn = findall(app.fig,'Tag','applybtn');
        set(applybtn,'Enable','on');
        resetbtn = findall(app.fig,'Tag','resetbtn');
        set(resetbtn,'Enable','on');
        okbtn = findall(app.fig,'Tag','okbtn');
        set(okbtn,'Enable','on');
        cancelbtn = findobj(app.fig,'Tag','cancelbtn');
        set(cancelbtn,'Text','Cancel','Tooltip','Cancel and reset settings');

      end
      % update the cvi
      switch lbltag(end)
        case '1'
          app.cv.maxlvs = get(sliderh,'Value');
        case '2'
          app.cv.cvi{2} = get(sliderh,'Value');
        case '3'
          app.cv.cvi{3} = get(sliderh,'Value');
      end
      setappdata(app.fig,'cvi',app.cv.cvi);
      setappdata(app.fig,'maxlvs',app.cv.maxlvs);
      app.updateAxes();
    end
    
    function sld_Callback(app,varargin)
      lbltag = varargin{1};
      slidertag = varargin{2};
      slider = findobj(app.fig,'tag',slidertag);
      % adjust the slider so it falls on integer
      set(slider,'Value',double(int64(get(slider,'Value'))));
      lbl = findobj(app.fig,'Tag',lbltag);
      set(lbl,'Text',num2str(get(slider,'Value')));
      setappdata(app.fig,lbltag,get(slider,'Value'));
      
      % Enable Apply, Reset, and OK buttons
      applybtn = findall(app.fig,'Tag','applybtn');
      set(applybtn,'Enable','on');
      resetbtn = findall(app.fig,'Tag','resetbtn');
      set(resetbtn,'Enable','on');
      okbtn = findall(app.fig,'Tag','okbtn');
      set(okbtn,'Enable','on');
      
      % update the cvi
      switch lbltag(end)
        case '1'
          app.cv.maxlvs = get(slider,'Value');
        case '2'
          app.cv.cvi{2} = get(slider,'Value');
        case '3'
          app.cv.cvi{3} = get(slider,'Value');
      end
      setappdata(app.fig,'cvi',app.cv.cvi);
      setappdata(app.fig,'maxlvs',app.cv.maxlvs);
      app.updateAxes();
    end
    
    function updateAxes(app)
      ax = findobj(app.fig,'type','axes');
      imh = findobj(ax,'type','image');
      translate = {
        'none','none';
        'loo','leave one out';
        'vet','venetian blinds';
        'con','contiguous blocks';
        'rnd','random subsets'};
      methodname = get(findobj(app.fig,'tag','methoddropdown'),'Value');
      method = translate{find(ismember(translate(:,2),methodname)),1};
      splts = str2num(get(findobj(app.fig,'tag','sldlblval2'),'Text'));
      spb = str2num(get(findobj(app.fig,'tag','sldlblval3'),'Text'));
      %app.cv.cvi = {method splts spb};
      %app.cv.method = methodname;
      % TODO: condition by CVby, either by sample or class
      % create cvi
      
      if ~isempty(app.x)
        nsamps = max(2,min(100,size(app.x,1)));
        if strcmpi(app.cv.method,'none')
        elseif strcmpi(app.cv.method,'custom') | isnumeric(app.cv.cvi)
          
        else
          enc = encodemethod(nsamps,app.cv.cvi);
        end
      end
      set(imh,'cdata',enc');
      clrs = classcolors;
      colormap(app.UIAxes,clrs(mod((1:41)-1,size(clrs,1))+1,:));
      caxis(app.UIAxes,[0 40]);
      grid(app.UIAxes,'on')
      set(app.UIAxes,'xtick',[0.5:39.5],'xticklabel','','ytick',[],'layer','top');
      set(get(imh,'parent'),'XLim',[0.5 nsamps+0.5],'Xtick',[0.5:nsamps+0.5]);
      
      % change text in window
      [issue, splitinfo] = reviewhmaccrossval(app);
      str = '';
      shown = '0';
      color = 'white';
      drawnow;
      if isempty(issue)
        app.HTML.Data = {splitinfo,sprintf('rgb(%i,%i,%i)',int64(analysis('statuscolor','green').*256))};
      else
        for issueindex = 1:length(issue);
          harddismiss = getplspref('analysis',['dismiss_' issue(issueindex).issueid]);
          if isempty(harddismiss) | ~harddismiss
            if 1 %~ismember(issue(issueindex).issueid,dismissed);
              %we got a warning, use it and it's color
              if isempty(str);
                str = issue(issueindex).issue;
              else
                str = [issue(issueindex).issue '<br> <hr width="100%" size="2" color="black" noshade>' str];
              end
              color = issue(issueindex).color;
              %setappdata(handles.warnings,'warningid',issue(issueindex).issueid);
              shown = shown+1;  %don't SHOW any more warnings
            else
              %previous warning happened again, keep it in dismissed
              %setappdata(handles.warnings,'dismissed',[getappdata(handles.warnings,'dismissed') {issue(issueindex).issueid}]);
            end
          end
        end
        if ~isempty(str);
          %add split info (if present)
          if ~isempty(splitinfo)
            str = [str '<br> <hr width="100%" size="2" color="black" noshade>' splitinfo];
          end
        else
          if isempty(splitinfo) | strcmp(app.cv.method, 'none')
            str = 'No Warnings';
          else
            str = splitinfo;
          end
          color = 'green';
        end
        app.HTML.Data = {str,sprintf('rgb(%i,%i,%i)',int64(analysis('statuscolor',color).*256))};
      end
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, x, file, cvoptions)
      % Class constructor
      if isempty(cvoptions)
        app.cv.cvi = {'vet' 10 1};
        app.cv.maxlvs = 6;
        app.cv.method = 'venetian blinds';
      else
        app.cv.cvi = cvoptions.cvi;
        app.cv.maxlvs = cvoptions.maxlvs;
        app.cv.method = cvoptions.method;
      end
      app.fig = app.UIFigure;
      set(app.fig,'Name','Cross-Validation Settings');
      set(app.fig,'Tag','HmacCV');
      app.x = x;
      app.file = file;
      % populate methoddropdown
      dd = findobj(app.fig,'tag','methoddropdown');
      switch app.file
        case 'hmacGui'
          methods = {'leave one out' 'venetian blinds' 'contiguous blocks' 'random subsets'};
        case {'diviner_guifcn' 'diviner'}
          methods = {'leave one out' 'venetian blinds' 'contiguous blocks' 'random subsets'};
        otherwise
          evrierrordlg('Method unsupported for Cross-Validation');
          return
      end
      set(dd,'Items',methods);
      set(dd,'Value',app.cv.method);
      lastcvoptions.method = app.cv.method;
      lastcvoptions.cvi = app.cv.cvi;
      lastcvoptions.maxlvs = app.cv.maxlvs;
      setappdata(app.fig,'lastcvoptions',lastcvoptions);
      setappdata(app.fig,'cvi',app.cv.cvi);
      setappdata(app.fig,'maxlvs',app.cv.maxlvs);
      setappdata(app.fig,'method',app.cv.method);
      
      %TODO update the labels
      
      
      % update the text
      sldlbl3 = findobj(app.fig,'tag','thirdsliderlabel');
      if strcmpi(lastcvoptions.method,'venetian blinds')
        set(sldlbl3,'Text','Samples per Blind (Thickness)');
      else
        set(sldlbl3,'Text','Number of Iterations');
      end
      
      imagesc(app.UIAxes,zeros(1,40));
      app.updateAxes();
      
      app.adjustSliders();
    end

    % Value changed function: DropDown
    function DropDownValueChanged(app, event)
      val = app.DropDown.Value;
      %lastcvoptions = getappdata(app.fig,'lastcvoptions');
      %lastcvoptions.method = val;
      translate = {
        'none','none';
        'loo','leave one out';
        'vet','venetian blinds';
        'con','contiguous blocks';
        'rnd','random subsets'};
      method = translate{find(ismember(translate(:,2),val)),1};
      %lastcvoptions.cvi{1} = method;
      %app.cv = lastcvoptions;
      app.cv.cvi{1} = method;
      app.cv.method = val;
      %setappdata(app.fig,'lastcvoptions',lastcvoptions);
      setappdata(app.fig,'cvi',app.cv.cvi);
      setappdata(app.fig,'method',app.cv.method);
      if isequal(val,'custom')
        % need to enable the 'load' and 'select class set button'
        ldbtn = findobj(app.fig,'Tag','loadbtn');
        set(ldbtn,'Enable','on');
        selcsbtn = findobj(app.fig,'Tag','selcsbtn');
        set(selcsbtn,'Enable','on');
      else
        % turn these off
        ldbtn = findobj(app.fig,'Tag','loadbtn');
        set(ldbtn,'Enable','off');
        selcsbtn = findobj(app.fig,'Tag','selcsbtn');
        set(selcsbtn,'Enable','off');
      end
      
      % update image
      app.updateAxes();
      
      % change label on third slider properly
      thirdsliderlabel = findobj(app.fig,'Tag','thirdsliderlabel');
      if isequal(val,'venetian blinds')
        set(thirdsliderlabel,'Text','Samples per Blind (Thickness)');
      else
        set(thirdsliderlabel,'Text','Number of Iterations');
      end
      
      % Enable Apply, Reset, and OK buttons
      applybtn = findobj(app.fig,'Tag','applybtn');
      set(applybtn,'Enable','on');
      resetbtn = findobj(app.fig,'Tag','resetbtn');
      set(resetbtn,'Enable','on');
      okbtn = findobj(app.fig,'Tag','okbtn');
      set(okbtn,'Enable','on');
      cancelbtn = findobj(app.fig,'Tag','cancelbtn');
      set(cancelbtn,'Text','Cancel','Tooltip','Cancel and reset settings');
    end

    % Button pushed function: ApplyButton
    function ApplyButtonPushed(app, event)
      % Enable Apply, Reset, and OK buttons
      applybtn = findobj(app.fig,'Tag','applybtn');
      set(applybtn,'Enable','off');
      resetbtn = findobj(app.fig,'Tag','resetbtn');
      set(resetbtn,'Enable','off');
      okbtn = findobj(app.fig,'Tag','okbtn');
      set(okbtn,'Enable','off');
      cancelbtn = findobj(app.fig,'Tag','cancelbtn');
      set(cancelbtn,'Text','Close','Tooltip','Close Cross-Validation Settings')
      
      % Save new cvoptions
      handles = getappdata(app.fig);
      lastcvoptions = getappdata(app.fig,'lastcvoptions');
%       translate = {
%         'none','none';
%         'loo','leave one out';
%         'vet','venetian blinds';
%         'con','contiguous blocks';
%         'rnd','random subsets'};
%       method = translate{find(ismember(translate(:,2),handles.method)),1};
      lastcvoptions.maxlvs = handles.maxlvs;
      lastcvoptions.cvi = handles.cvi;
      lastcvoptions.method = handles.method;
      setappdata(app.fig,'lastcvoptions',lastcvoptions);
%       setappdata(app.fig,'sldlblval1',lastcvoptions.maxlvs);
%       setappdata(app.fig,'sldlblval2',lastcvoptions.cvi{2});
%       setappdata(app.fig,'sldlblval3',lastcvoptions.cvi{3});
      
%       maxlvs = handles.sldlblval1;
%       splits = handles.sldlblval2;
%       iter   = handles.sldlblval3;
%       app.cv.cvi = {method splits iter};
%       app.cv.method = handles.method;
%       app.cv.maxlvs = maxlvs;
%       setappdata(app.fig,'cvi',app.cv.cvi);
%       setappdata(app.fig,'maxlvs',app.cv.maxlvs);
    end

    % Button pushed function: ResetButton
    function ResetButtonPushed(app, event)
      lastcvoptions = getappdata(app.fig,'lastcvoptions');
      
      % Need to reset dropdown value
      ddwn = findall(app.fig,'Tag','methoddropdown');
      set(ddwn,'Value',lastcvoptions.method);
      setappdata(app.fig,'method',lastcvoptions.method);
      
      % Need to reset the Slider labels and Slider values
      % max lvs slider
      lvslider = findall(app.fig,'Tag','lvslider');
      set(lvslider,'Value',lastcvoptions.maxlvs);
      %max lvs label
      sldlblval1 = findall(app.fig,'Tag','sldlblval1');
      set(sldlblval1,'Text',num2str(lastcvoptions.maxlvs));
      setappdata(app.fig,'sldlblval1',lastcvoptions.maxlvs);
      % splits slider
      spltslider = findall(app.fig,'Tag','spltslider');
      set(spltslider,'Value',lastcvoptions.cvi{2});
      % splits label
      sldlblval2 = findall(app.fig,'Tag','sldlblval2');
      set(sldlblval2,'Text',num2str(lastcvoptions.cvi{2}));
      setappdata(app.fig,'sldlblval2',lastcvoptions.cvi{2});
      % iter slider
      spbslider = findall(app.fig,'Tag','spbslider');
      set(spbslider,'Value',lastcvoptions.cvi{3});
      % iter label
      sldlblval3 = findall(app.fig,'Tag','sldlblval3');
      set(sldlblval3,'Text',num2str(lastcvoptions.cvi{3}));
      setappdata(app.fig,'sldlblval3',lastcvoptions.cvi{3});
      
      app.cv.cvi = lastcvoptions.cvi;
      app.cv.maxlvs = lastcvoptions.maxlvs;
      setappdata(app.fig,'cvi',lastcvoptions.cvi);
      setappdata(app.fig,'maxlvs',lastcvoptions.maxlvs);
      
      app.updateAxes()
    end

    % Button pushed function: OKButton
    function OKButtonPushed(app, event)
      handles = getappdata(app.fig);
%       lastcvoptions = getappdata(app.fig,'lastcvoptions');
%       if ~isfield(handles,'customcvi')
%         translate = {
%           'none','none';
%           'loo','leave one out';
%           'vet','venetian blinds';
%           'con','contiguous blocks';
%           'rnd','random subsets';
%           'custom','custom'};
%         method = translate{find(ismember(translate(:,2),app.cv.method)),1};
%         splits = handles.sldlblval2;
%         iter   = handles.sldlblval3;
%         app.cv.cvi = {method splits iter};
%       else
%         method = 'custom';
%         app.cv.cvi = handles.customcvi;
%       end
%       maxlvs = handles.maxlvs;
%       app.cv.maxlvs = maxlvs;
      set(app.fig,'UserData',1);
%       setappdata(app.fig,'method',app.cv.method);
%       setappdata(app.fig,'cvi',app.cv.cvi);
%       setappdata(app.fig,'maxlvs',app.cv.maxlvs);
    end

    % Button pushed function: CancelButton
    function CancelButtonPushed(app, event)
      lastcvoptions = getappdata(app.fig,'lastcvoptions');
      setappdata(app.fig,'cvi',lastcvoptions.cvi);
      setappdata(app.fig,'method',lastcvoptions.method);
      setappdata(app.fig,'maxlvs',lastcvoptions.maxlvs);
      set(app.fig,'UserData',1);
    end

    % Button pushed function: HelpButton
    function HelpButtonPushed(app, event)
      % open web link to wiki url
      evrihelp('using_cross_validation')
    end

    % Button pushed function: Button_3
    function Button_3Pushed(app, event)
      app.add_Callback('lvslider','sldlblval1')
    end

    % Button pushed function: Button_2
    function Button_2Pushed(app, event)
      app.subtract_Callback('lvslider','sldlblval1');
    end

    % Button pushed function: Button_6
    function Button_6Pushed(app, event)
      app.add_Callback('spltslider','sldlblval2')
    end

    % Button pushed function: Button_4
    function Button_4Pushed(app, event)
      app.subtract_Callback('spltslider','sldlblval2');
    end

    % Button pushed function: Button_7
    function Button_7Pushed(app, event)
      app.add_Callback('spbslider','sldlblval3')
    end

    % Button pushed function: Button_5
    function Button_5Pushed(app, event)
      app.subtract_Callback('spbslider','sldlblval3')
    end

    % Value changed function: MaximumNumberofLVsSlider
    function MaximumNumberofLVsSliderValueChanged(app, event)
      app.sld_Callback('sldlblval1','lvslider');
    end

    % Value changed function: NumberofSplitsSlider
    function NumberofSplitsSliderValueChanged(app, event)
      app.sld_Callback('sldlblval2','spltslider');
    end

    % Value changed function: SamplesperBlindThicknessSlider
    function SamplesperBlindThicknessSliderValueChanged(app, event)
      app.sld_Callback('sldlblval3','spbslider');
    end

    % Button pushed function: ViewSplitsinDataSetEditorButton
    function ViewSplitsinDataSetEditorButtonPushed(app, event)
      
      cvi = app.cv.cvi;
      X     = app.x;
      nsamp = size(X,1);
      
      if ~isnumeric(cvi)
        enc = encodemethod(nsamp,cvi);
      else
        if isrow(cvi)
          enc = cvi';
        else
          enc = cvi;
        end
      end
      
      leaveOutSetString = repmat('Leave-Out Set ',nsamp,1);
      encString = num2str(enc);
      classString = strcat(leaveOutSetString, encString);
      
      data = dataset(zeros(length(enc),1));
      %data.class{1} = enc;
      data.classid{1} = classString;
      h=editds(data,'invisible');
      setappdata(h,'editfields',{'class'});
      %setappdata(h,'editfields',{'none'});
      setappdata(h,'disallowedmodes',[-1 0 2]);
      editds('initialize',h);
      editds('setmode',h,1);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.GridLayout2 = uigridlayout(app.UIFigure);
      app.Panel_15 = uipanel(app.GridLayout2);
      app.GridLayout3 = uigridlayout(app.Panel_15);
      app.DropDown = uidropdown(app.GridLayout3);
      app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
      app.DropDown.Tag = 'methoddropdown';
      app.CrossValMethodLabel = uilabel(app.GridLayout3);
      app.CrossValMethodLabel.Text = 'Cross-Val Method';
      app.CrossValByLabel = uilabel(app.GridLayout3);
      app.CrossValByLabel.Text = 'Cross-Val By';
      app.DropDown_2 = uidropdown(app.GridLayout3);
      app.DropDown_2.Items = {'Sample', 'Classes'};
      app.DropDown_2.Enable = 'off';
      app.DropDown_2.Value = 'Sample';
      app.Panel_16 = uipanel(app.GridLayout2);
      app.GridLayout4 = uigridlayout(app.Panel_16);
      app.LoadButton = uibutton(app.GridLayout4, 'push');
      app.LoadButton.Enable = 'off';
      app.LoadButton.Text = 'Load...';
      app.SelectClassSetButton = uibutton(app.GridLayout4, 'push');
      app.SelectClassSetButton.Enable = 'off';
      app.SelectClassSetButton.Text = 'Select Class Set...';
      app.HelpButton = uibutton(app.GridLayout2, 'push');
      app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
      app.HelpButton.Text = 'Help';
      app.Panel_17 = uipanel(app.GridLayout2);
      app.GridLayout5 = uigridlayout(app.Panel_17);
      app.MaximumNumberofLVsSlider = uislider(app.GridLayout5);
      app.MaximumNumberofLVsSlider.Limits = [1 100];
      app.MaximumNumberofLVsSlider.MajorTicks = [];
      app.MaximumNumberofLVsSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumNumberofLVsSliderValueChanged, true);
      app.MaximumNumberofLVsSlider.MinorTicks = [];
      app.MaximumNumberofLVsSlider.Tag = 'lvslider';
      app.MaximumNumberofLVsSlider.Value = 25.4681451612903;
      app.MaximumNumberofLVsSliderLabel = uilabel(app.GridLayout5);
      app.MaximumNumberofLVsSliderLabel.Text = 'Maximum Number of LVs';
      app.NumberofSplitsSlider = uislider(app.GridLayout5);
      app.NumberofSplitsSlider.Limits = [2 100];
      app.NumberofSplitsSlider.MajorTicks = [];
      app.NumberofSplitsSlider.ValueChangedFcn = createCallbackFcn(app, @NumberofSplitsSliderValueChanged, true);
      app.NumberofSplitsSlider.MinorTicks = [];
      app.NumberofSplitsSlider.Tag = 'spltslider';
      app.NumberofSplitsSlider.Value = 2;
      app.NumberofSplitsSliderLabel = uilabel(app.GridLayout5);
      app.NumberofSplitsSliderLabel.Text = 'Number of Splits';
      app.SamplesperBlindThicknessSlider = uislider(app.GridLayout5);
      app.SamplesperBlindThicknessSlider.Limits = [1 100];
      app.SamplesperBlindThicknessSlider.MajorTicks = [];
      app.SamplesperBlindThicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SamplesperBlindThicknessSliderValueChanged, true);
      app.SamplesperBlindThicknessSlider.MinorTicks = [];
      app.SamplesperBlindThicknessSlider.Tag = 'spbslider';
      app.SamplesperBlindThicknessSlider.Value = 40;
      app.SamplesperBlindThicknessSliderLabel = uilabel(app.GridLayout5);
      app.SamplesperBlindThicknessSliderLabel.Tag = 'thirdsliderlabel';
      app.SamplesperBlindThicknessSliderLabel.Text = 'Samples per Blind (Thickness)';
      app.Label = uilabel(app.GridLayout5);
      app.Label.Text = '1';
      app.Button_2 = uibutton(app.GridLayout5, 'push');
      app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
      app.Button_2.Text = '←';
      app.maxLabel = uilabel(app.GridLayout5);
      app.maxLabel.Tag = 'sldmaxlbl1';
      app.maxLabel.Text = 'max';
      app.Button_3 = uibutton(app.GridLayout5, 'push');
      app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
      app.Button_3.Text = '→';
      app.Label_2 = uilabel(app.GridLayout5);
      app.Label_2.Text = '2';
      app.Button_4 = uibutton(app.GridLayout5, 'push');
      app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
      app.Button_4.Text = '←';
      app.Button_6 = uibutton(app.GridLayout5, 'push');
      app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
      app.Button_6.Text = '→';
      app.Button_5 = uibutton(app.GridLayout5, 'push');
      app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
      app.Button_5.Text = '←';
      app.Button_7 = uibutton(app.GridLayout5, 'push');
      app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
      app.Button_7.Text = '→';
      app.Label_4 = uilabel(app.GridLayout5);
      app.Label_4.Tag = 'sldlblval1';
      app.Label_4.Text = '1';
      app.Label_5 = uilabel(app.GridLayout5);
      app.Label_5.Tag = 'sldlblval2';
      app.Label_5.Text = '1';
      app.Label_6 = uilabel(app.GridLayout5);
      app.Label_6.Tag = 'sldlblval3';
      app.Label_6.Text = '1';
      app.maxLabel_3 = uilabel(app.GridLayout5);
      app.maxLabel_3.Tag = 'sldmaxlbl2';
      app.maxLabel_3.Text = 'max';
      app.maxLabel_7 = uilabel(app.GridLayout5);
      app.maxLabel_7.Tag = 'sldmaxlbl3';
      app.maxLabel_7.Text = 'max';
      app.Label_3 = uilabel(app.GridLayout5);
      app.Label_3.Text = '1';
      app.ViewSplitsinDataSetEditorButton = uibutton(app.GridLayout5, 'push');
      app.ViewSplitsinDataSetEditorButton.ButtonPushedFcn = createCallbackFcn(app, @ViewSplitsinDataSetEditorButtonPushed, true);
      app.ViewSplitsinDataSetEditorButton.Text = 'View Splits in DataSet Editor';
      app.Panel_18 = uipanel(app.GridLayout2);
      app.GridLayout = uigridlayout(app.Panel_18);
      app.ResetButton = uibutton(app.GridLayout, 'push');
      app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
      app.ResetButton.Tag = 'resetbtn';
      app.ResetButton.Text = 'Reset';
      app.OKButton = uibutton(app.GridLayout, 'push');
      app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
      app.OKButton.Tag = 'okbtn';
      app.OKButton.Text = 'OK';
      app.CancelButton = uibutton(app.GridLayout, 'push');
      app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
      app.CancelButton.Tag = 'cancelbtn';
      app.CancelButton.Text = 'Cancel';
      app.ApplyButton = uibutton(app.GridLayout, 'push');
      app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
      app.ApplyButton.Tag = 'applybtn';
      app.ApplyButton.Text = 'Apply';
      app.HTML = uihtml(app.GridLayout2);
      app.HTML.HTMLSource = 'render.html';
      app.UIAxes = uiaxes(app.GridLayout2);
      app.UIAxes.XLim = [1 40];
      app.UIAxes.XTickLabel = '';
      app.UIAxes.YTickLabel = '';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = hmacCvGui(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end