classdef clsti_gui < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    CLSTIModelBuilderUIFigure  matlab.ui.Figure
    TabGroup                   matlab.ui.container.TabGroup
    OverviewTab                matlab.ui.container.Tab
    UITable                    matlab.ui.control.Table
    OpenButton                 matlab.ui.control.Button
    PreprocessingButton        matlab.ui.control.Button
    VariableSelectionButton    matlab.ui.control.Button
    AddComponent               matlab.ui.control.Button
    RemoveComponent            matlab.ui.control.Button
    BuildModelButton           matlab.ui.control.Button
    infoTextArea               matlab.ui.control.TextArea
    HelpButton                 matlab.ui.control.Button
    CloseButton                matlab.ui.control.Button
    LoadModelButton            matlab.ui.control.Button
    SaveImageButton            matlab.ui.control.Image
    SendToAnalysis             matlab.ui.control.Image
    LoadMenu                   matlab.ui.container.ContextMenu
    LoadDataMenu               matlab.ui.container.Menu
    DefinitionFileMenu         matlab.ui.container.Menu
    DataSetObjectMenu          matlab.ui.container.Menu
    ChangeComponentNameMenu    matlab.ui.container.Menu
    PreproAndVarSelMenu        matlab.ui.container.ContextMenu
    OpenMenu                   matlab.ui.container.Menu
    ViewMenu                   matlab.ui.container.Menu
  end

  
  properties (Access = private)
    numberOfComps double
    pathsToPureCompsFiles cell
    compNames cell
    curpp % Preprocessing structure
    selectedVars % Selected Variables
    myAxisscale % Description
    xpp % Description
    myModel % Description
    analysisHandles % Description
    hideTimer % Description
  end
  
  methods (Access = protected)
    
    function setNumberOfComps(app)
      app.numberOfComps = length(app.TabGroup.Children)-1;
      TabGroupSelectionChanged(app)
    end
    
    
    function openDSO(app)
      selectedTab = app.TabGroup.SelectedTab;
      myDSO = selectedTab.UserData.DSO;
      editds(myDSO);
    end
  end
  
  methods (Access = private)
    
    function updateOverviewTable(app,varargin)
      myTabGroup = app.TabGroup;
      overviewTab = myTabGroup.findobj('Tag','overviewTab');
      overviewTabTable = overviewTab.Children.findobj('Type','uitable');
      myCompTabs = myTabGroup.Children.findobj('-regexp','tag','comp');
      fixedData = cell(size(myCompTabs,1),2);
      for i = 1:length(myCompTabs)
        currentCompTab = myCompTabs(i);
        currentCompTable = currentCompTab.Children.findobj('type','uitable');
        if ~isempty(currentCompTable.Data)
          fixedData(i,:) = {currentCompTab.Title num2str(currentCompTable.Data.Var2(currentCompTable.Data.myRowInclude)')};
        end
      end
      overviewTabTable.Data = fixedData;
      emptyTest = cellfun(@isempty, fixedData);
      if all(emptyTest)
        app.selectedVars = [];
        app.OpenButton.Enable = 'off';
        app.BuildModelButton.Enable = 'off';
        %app.PreprocessingButton.Enable = 'off';
        app.VariableSelectionButton.Enable = 'off';
        app.RemoveComponent.Enable = 'off';
        app.myModel = [];
        app.updateButtons
      else
        app.BuildModelButton.Enable = 'on';
        %app.PreprocessingButton.Enable = 'on';
        app.VariableSelectionButton.Enable = 'on';
        app.OpenButton.Enable = 'on';
        app.updateButtons
      end
      figure(app.CLSTIModelBuilderUIFigure);
    end
    
    function combDSO = combineDSO(app)
      compTabs = app.TabGroup.Children.findobj('-not', 'tag', 'overviewTab','type','uitab');
      combDSO = [];
      for i = 1:length(compTabs)
        thisTab = compTabs(i);
        thisDSO = thisTab.UserData.DSO;
        
        thisTitle = thisTab.Title;
        thisTable = thisTab.Children.findobj('type', 'uitable');
        temps = thisTable.Data.(thisTable.Data.Properties.VariableNames{2});
        fixedLabels = cell(length(temps),1);  
        for j = 1:length(temps)
          thisLabel = [thisTitle ' at ' num2str(temps(j))];
          fixedLabels{j} = thisLabel;
        end
        thisDSO.label{1} = fixedLabels;
        combDSO = [combDSO; thisDSO];       
      end
      if ~isempty(app.selectedVars)
        combDSO.include{2} = app.selectedVars;   
      end
    end
    
    function fixAllDSOs(app)
      compTabs = app.TabGroup.Children.findobj('-not', 'tag', 'overviewTab','type','uitab');
      for i = 1:length(compTabs)
        thisTab = compTabs(i);
        thisDSO = thisTab.UserData.DSO;
        if ~isempty(app.selectedVars)
          thisDSO.include{2} = app.selectedVars;
        end
        thisTab.UserData.DSO = thisDSO;
      end
    end
    
    function updateButtons(app)
      if ~isempty(app.myModel)
        app.SaveImageButton.Enable = 'on';
        app.BuildModelButton.BackgroundColor = [0.47, 0.67, 0.19];
        app.BuildModelButton.Tooltip = 'Model successfully built.';
        app.SendToAnalysis.Enable = 'on';
      else
        app.SaveImageButton.Enable = 'off';
        app.SendToAnalysis.Enable = 'off';
        app.BuildModelButton.BackgroundColor = [0.89, 1.00, 0.74];
        app.BuildModelButton.Tooltip = 'Model needs to be built.';
      end
    end
    
    function tableIncludeChange(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      thisDSO = selectedTab.UserData.DSO;
      %get row of cell where change was made
      mySample = event.Indices(1);
      
      if event.NewData
        %changing to include a row
        thisDSO.include{1} = union(thisDSO.include{1},mySample);
        s2 = uistyle('BackgroundColor', [1 1 1]);
        addStyle(event.Source,s2,'row',mySample);
      else
        %changing to exclude a row
        thisDSO.include{1} = setdiff(thisDSO.include{1},mySample);
        s1 = uistyle('BackgroundColor', [0.94 0.94 0.94]);
        addStyle(event.Source,s1,'row',mySample);
      end
      selectedTab.UserData.DSO = thisDSO;
        
      
      app.myModel = [];
      updateOverviewTable(app)
    end
    
    function loadDSO(app,thisDSO,compName)
      
      currentTab = app.TabGroup.SelectedTab;
      if ~isempty(compName)
        currentTab.Title = compName;
      else
        currentTab.Title = currentTab.Tag;
      end
      myNames = cellstr(thisDSO.label{1,1});
      myTemps = thisDSO.axisscale{1,1};
      myRowInclude = true(size(myNames,1),1);
      toUncheck = setdiff(1:size(thisDSO,1),thisDSO.include{1});
      myRowInclude(toUncheck) = false;
      
      d_table = table(myNames, myTemps', myRowInclude);
      
      d_table = sortrows(d_table,2);
      currentTabTable = currentTab.Children.findobj('Type','uitable');
      currentTabTable.Data = d_table;
      
      currentTab.UserData.DSO = thisDSO;
      currentTabBtn = currentTab.Children.findobj('Type','uibutton');
      currentTabBtn.Enable = 'on';
      app.AddComponent.Enable = 'on';
    end
    
    function hideTextArea(app)
      app.infoTextArea.Visible = 'off';
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, varargin)
      setNumberOfComps(app);
      app.BuildModelButton.Enable = 'off';
      %app.PreprocessingButton.Enable = 'off';
      app.VariableSelectionButton.Enable = 'off';
      app.SaveImageButton.ImageSource = gettbicons('save');
      app.SendToAnalysis.ImageSource = gettbicons('uparrow_blue');
      
      if ~isempty(varargin)
        if length(varargin) == 1
          [modl, ~] = deal(varargin{1});
          if isstruct(modl)
            %only passed handles to analysis
            handles = modl;
            modl = [];
          elseif ismodel(modl)
            %passed in a model
            if ~strcmpi(modl.modeltype,'clsti')
              evrierrordlg('Input to CLSTI model builder must be a CLSTI model.');
              return;
            end
            handles = [];
          else
            evrierrordlg('Input to CLSTI model builder must be a CLSTI model or handles to Analysis figure');
            return;
          end
        elseif length(varargin) == 2
          [modl, handles] = deal(varargin{1:2});
          %passed in a model and handles from analysis
          if ~strcmpi(modl.modeltype,'clsti')
            evrierrordlg('Input to CLSTI model builder must be a CLSTI model.');
            return;
          end
          if ~isstruct(handles)
            evrierrordlg('Input to CLSTI model builder must be a CLSTI model or handles to Analysis figure');
            return;
          end
        end
        app.myModel= modl;
        app.analysisHandles = handles;
        if ~isempty(app.myModel)
          app.LoadModelButtonPushed;
        end
      end
      app.hideTimer = timer('TimerFcn', @(~,~) hideTextArea(app), 'StartDelay', 4, 'BusyMode', 'queue', 'ExecutionMode', 'singleShot');
    end

    % Menu selected function: DefinitionFileMenu
    function loadData(app, event)
      if ~isempty(app.myModel)
        app.myModel = [];
      end
      
      currentTab = app.TabGroup.SelectedTab; 
      
      %given an experiment file (or an empty filename)
      %no filename? prompt user to locate file
      [file,pathname] = evriuigetfile({'*.csv;*.txt;*.xls;*.xlsx','Readable Files (CSV,TXT,XLS,XLSX)';'*.*','All Files (*.*)'},'multiselect','off');
      if isnumeric(file) & file == 0
        uialert(app.CLSTIModelBuilderUIFigure,'File not found','Invalid File')
        return
      end
      varargin = {fullfile(pathname,file)};
      
      %check file type to decide how to read it
      [FilePath,FileName,FileExt] = fileparts(varargin{1});
      %read file in as a table
      switch FileExt
        case {'.xlsx', '.xls'}
          d_table = readtable(varargin{1},'NumHeaderLines',0);
        otherwise
          delToUse = ',';
          d_table = readtable(varargin{1}, 'Delimiter', delToUse, 'FileType', 'text', 'NumHeaderLines', 0);
      end
      %get the variable names from the table
      d_cols = d_table.Properties.VariableNames;
      
      currentTab.Title = d_cols{1};
      currentTabTable = currentTab.Children.findobj('Type','uitable');
      if any(contains(d_table.(d_cols{1}),pathname))
        %fix filenames in csv file
        [~,allFilenames,allFileExts] = fileparts(d_table.(d_cols{1}));
        fixedNames = strcat(allFilenames,allFileExts);
        d_table.(d_cols{1}) = fixedNames;
      end
      
      d_table = sortrows(d_table,2);
      myRowInclude = true(size(d_table,1),1);
      d_table = [d_table, table(myRowInclude)];
      currentTabTable.Data = d_table;
      currentTabTable.Tooltip = FilePath;
      currentTabTable.UserData.FileName = FileName;
      currentTabTable.UserData.FilePath = FilePath;
      
      filenames = insertBefore(d_table.(d_cols{1}),d_table.(d_cols{1}),pathname);
      try
        myDSO = autoimport(filenames);
      catch
        uialert(app.CLSTIModelBuilderUIFigure,'Error during importing. Check CLSTI data file.','Data Import Error');
        return;
      end
      currentAxisscale = myDSO.axisscale(1);
      currentAxisscaleName = myDSO.axisscalename(1);
      
      myDSO.axisscale{1,1} =  d_table.(d_cols{2});
      myDSO.axisscalename{1,1} =  'Temperature';
      for i = 1:length(currentAxisscale)
        myDSO.axisscale{1,i+1} = currentAxisscale{1,i};
        myDSO.axisscalename{1,i+1} = currentAxisscaleName{1,i};
      end
      currentTab.UserData.DSO = myDSO;
      currentTabBtn = currentTab.Children.findobj('Type','uibutton');
      currentTabBtn.Enable = 'on';
      
      app.AddComponent.Enable = 'on';
      updateOverviewTable(app);
    end

    % Button pushed function: AddComponent
    function addComponent_pushed(app, event)
      newCompTab = uitab(app.TabGroup);
      newCompTab.Title = 'New Comp';
      newCompTab.ContextMenu = app.LoadMenu;
      setNumberOfComps(app);
      newCompTab.Tag = ['comp' num2str(app.numberOfComps)];
      newCompTable = uitable(newCompTab,"CellEditCallback",@(src,event) tableIncludeChange(app,event));
      newCompTable.ColumnName = {'Filename'; 'Temp'; 'Include'};
      newCompTable.ColumnEditable = [false, false, true];
      newCompTable.RowStriping = false;
      newCompTable.RowName = {};
      newCompTable.Position = [10 6 302 185];
      newCompOpenButton = uibutton(newCompTab, 'push',"ButtonPushedFcn",@(btn,event) openDSO(app));
      newCompOpenButton.Tag = 'openDSOBtn';
      newCompOpenButton.Position = [321 6 57 29];
      newCompOpenButton.Text = 'Open';
      newCompOpenButton.Enable = 'Off';
      app.TabGroup.SelectedTab = newCompTab;
      TabGroupSelectionChanged(app, [])
      app.AddComponent.Enable = 'off';
    end

    % Button pushed function: RemoveComponent
    function removeComponent_pushed(app, event)
      currentTab = app.TabGroup.SelectedTab;
      hasBeenLoaded = ~isempty(currentTab.UserData);
      %.UserData will be populated with a DSO if this tab has been loaded
      %with data
      if hasBeenLoaded
        app.myModel = [];
      end
     
      if ~hasBeenLoaded
        %deleting an empty tab
        app.AddComponent.Enable = 'on';
      end
      delete(currentTab);
      updateOverviewTable(app);
      app.setNumberOfComps;
    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      if strcmp(selectedTab.Tag,'overviewTab')
        app.RemoveComponent.Enable = 'off';
      else
        app.RemoveComponent.Enable = 'on';
      end
    end

    % Context menu opening function: LoadMenu
    function LoadMenuOpening(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      if strcmp(selectedTab.Tag,'overviewTab')
        set(app.LoadMenu.Children,'Enable','off');
      else
        set(app.LoadMenu.Children,'Enable','on');
      end
    end

    % Button pushed function: BuildModelButton
    function BuildModelButtonPushed(app, event)
      compTabs = app.TabGroup.Children.findobj('-not', 'tag', 'overviewTab','type','uitab');
      for i = 1:length(compTabs)
        thisTab = compTabs(i);
        thisDSO = thisTab.UserData.DSO;
        cellOfDSOs{i} = thisDSO;
        cellOfCompNames{i} = thisTab.Title;
      end
      model = clsti(cellOfDSOs);
      if isempty(model)
        %returned an empty model due to an error
        figure(app.CLSTIModelBuilderUIFigure);
        return
      end
      model.detail.clsti.componentNames = cellOfCompNames;
      
      %model.include{2,1} = app.selectedVars;
      %model.detail.preprocessing{1} = app.curpp;
      app.myModel = model;
      app.updateButtons
      figure(app.CLSTIModelBuilderUIFigure);
    end

    % Button pushed function: VariableSelectionButton
    function VariableSelectionButtonPushed(app, event)
      currentVars = app.selectedVars;
      btnlist = {
        'selectx'      'selectonly' 'plotgui(''makeselection'',gcbf);plotgui(''menuselection'',''EditExcludeUnselected'')'   'enable' 'Choose Included Range'    'off'   'push'
        'selectxplus'  'selectadd'  'plotgui(''makeselection'',gcbf);plotgui(''menuselection'',''EditIncludeSelection'')'    'enable' 'Add to Included'          'off'   'push'
        'selectxminus' 'selectsub'  'plotgui(''makeselection'',gcbf);plotgui(''menuselection'',''EditExcludeSelection'')'    'enable' 'Subtract from Included'   'off'   'push'
        'ok'           'accept'     'close(gcbf)'                                                                            'enable' 'Accept and close'         'on'    'push'
        };
      
      %share data and get plotgui to modify include field
      %myid = setshareddata(handles.settingsfigure,dat);
      combDSO = combineDSO(app);
      myid = setshareddata(app.CLSTIModelBuilderUIFigure,combDSO);
      fig = plotgui('new',myid,...
        'plotby',0,...
        'viewclasses',1,...
        'selectionmode','xs',...
        'pgtoolbar',0,...
        'viewexcludeddata',1);
      toolbar(fig,'',btnlist,'selectiontoolbar');
      
      while ishandle(fig)
        uiwait(fig);
      end
      
      %get include field after figure is closed
      dat = myid.object;
      win = dat.include{2};
      if ~isequal(currentVars,win)
        app.selectedVars = win;
        combDSO.include{2} = win;
        fixAllDSOs(app);
        if ~isempty(app.myModel)
          app.myModel = [];
        end
        app.updateButtons;
      end
      %[app.xpp,app.curpp] = preprocess('calibrate',app.curpp, combDSO);
    end

    % Button pushed function: PreprocessingButton
    function PreprocessingButtonPushed(app, event)
%       %myDSO = app.TabGroup.SelectedTab.UserData.DSO;
%       combDSO = combineDSO(app);
%       app.xpp = [];
%       preproCat = preprocess('initcatalog');
%       [app.curpp,ppChange] = preprocess('setup',app.CLSTIModelBuilderUIFigure,'catalog', preproCat, app.curpp, combDSO);
%       if ppChange
%         [app.xpp,app.curpp] = preprocess('calibrate',app.curpp, combDSO);
%       end
%       
%       
%       compTabs = app.TabGroup.Children.findobj('-not', 'tag', 'overviewTab','type','uitab');
%       myXPP = cell(size(compTabs,1),1);
%       for i = 1:length(compTabs)
%         thisTab = compTabs(i);
%         thisDSO = thisTab.UserData.DSO;
%         myXPP{i} = preprocess('calibrate',app.curpp,thisDSO);
%         %model.detail.clsti.refData{i} = thisDSO;
%         %model.detail.clsti.componentNames{i} = thisTab.Title;
%       end
%       fixAllDSOs(app);
      
    end

    % Button pushed function: OpenButton
    function OpenButtonPushed(app, event)
      combDSO = combineDSO(app);
      h = editds(combDSO);
    end

    % Window button motion function: CLSTIModelBuilderUIFigure
    function CLSTIModelBuilderUIFigureWindowButtonMotion(app, event)
      %preproBtnPos  = app.PreprocessingButton.Position;
      varSelBtnPos = app.VariableSelectionButton.Position;
      mousePos = app.CLSTIModelBuilderUIFigure.CurrentPoint;
      
%       if mousePos(1) >= preproBtnPos(1) && mousePos(1) <= preproBtnPos(1) + preproBtnPos(3) && ...
%           mousePos(2) >= preproBtnPos(2) && mousePos(2) <= preproBtnPos(2) + preproBtnPos(4)
%         %over prepro button, show uitext area
%         
%         ppToShow = 'Current Preprocessing: ';
%         if isempty(app.curpp)
%           ppToShow = [ppToShow newline 'None'];
%         else
%           thisPP = app.curpp;
%           for i = 1:length(thisPP)
%             ppToShow = [ppToShow newline thisPP(i).description];
%           end
%         end
%         
%         app.infoTextArea.Value = ppToShow;
%         app.infoTextArea.Visible = 'on';
      if mousePos(1) >= varSelBtnPos(1) && mousePos(1) <= varSelBtnPos(1) + varSelBtnPos(3) && ...
          mousePos(2) >= varSelBtnPos(2) && mousePos(2) <= varSelBtnPos(2) + varSelBtnPos(4)
      varsToShow = 'Selected Variables: ';
      if isempty(app.selectedVars)
        varsToShow = [varsToShow newline 'None'];
      else
        varsToShow = [varsToShow newline sprintf(encode(app.selectedVars,'',0))];
      end
      
      app.infoTextArea.Value = varsToShow;
      app.infoTextArea.Visible = 'on';
      if ~strcmp(app.hideTimer.Running, 'on')
        start(app.hideTimer);
      end
      else
        app.infoTextArea.Visible = 'off';
      end
    end

    % Button pushed function: HelpButton
    function HelpButtonPushed(app, event)
      evrihelp('Clsti_model_builder')
    end

    % Button pushed function: CloseButton
    function CloseButtonPushed(app, event)
      close(app.CLSTIModelBuilderUIFigure);
    end

    % Button pushed function: LoadModelButton
    function LoadModelButtonPushed(app, event)
      myTabGroup = app.TabGroup;
      overviewTab = myTabGroup.findobj('Tag','overviewTab');
      overviewTabTable = overviewTab.Children.findobj('Type','uitable');
      
      if ~isempty(overviewTabTable.Data) && ~isempty(app.myModel)
        myans=evriquestdlg('Clear current CLSTI model and continue loading?', ...
          'Clear current model?','Clear','Save','Cancel','Clear');
        switch myans
          case 'Cancel'
            return
          case {'Clear' 'Save'}
            switch myans
              case 'Save'
                app.SaveImageButtonClicked;
            end
            app.myModel = [];
            for i = 1:size(overviewTabTable.Data,1) 
              myCompTabs = myTabGroup.Children.findobj('-regexp','tag','comp');
              delete(myCompTabs);
              app.updateOverviewTable;
            end
            
        end
      end
      
      if isempty(app.myModel)
        
        [modl,name,location] = lddlgpls('struct','Select Model');
        if isempty(modl)
          return;
        end
        
        if ~strcmpi(modl.modeltype,'CLSTI')
          uialert(app.CLSTIModelBuilderUIFigure,['Unable to load ' modl.modeltype ' model into interface.'],'Model Type Not Supported');
          return;
        end
        app.myModel = modl;
      end
      app.selectedVars = app.myModel.include{2,1};
      %app.curpp = app.preprocess
      refDSOs = app.myModel.detail.clsti.refData;
      refCompNames = app.myModel.detail.clsti.componentNames;
      
      for i = 1:length(refDSOs)
%         app.addComponent_pushed;
%         currentTab = app.TabGroup.SelectedTab;
%         currentTab.Title = refCompNames{i};
          app.addComponent_pushed;
          thisDSO = refDSOs{i};
          compName = refCompNames{i};
          app.loadDSO(thisDSO,compName);
%         myNames = cellstr(thisDSO.label{1,1});
%         myTemps = thisDSO.axisscale{1,1};
%         myRowInclude = true(size(myNames,1),1);
%         toUncheck = setdiff(1:size(thisDSO,1),thisDSO.include{1});
%         myRowInclude(toUncheck) = false;
%         
%         d_table = table(myNames, myTemps', myRowInclude);
%         
%         d_table = sortrows(d_table,2);
%         currentTabTable = currentTab.Children.findobj('Type','uitable');
%         currentTabTable.Data = d_table;
%         
%         currentTab.UserData.DSO = thisDSO;
%         currentTabBtn = currentTab.Children.findobj('Type','uibutton');
%         currentTabBtn.Enable = 'on';
      end
      
      updateOverviewTable(app);
      app.myAxisscale = thisDSO.axisscale{2,1};
    end

    % Image clicked function: SaveImageButton
    function SaveImageButtonClicked(app, event)
      svdlgpls(app.myModel);
    end

    % Image clicked function: SendToAnalysis
    function SendToAnalysisImageClicked(app, event)
      if ~isempty(app.analysisHandles)
        obj = evrigui(app.analysisHandles.analysis);
        analysisMethod = obj.getMethod;
        if ~strcmpi(analysisMethod, 'clsti')
          obj = evrigui('analysis');
          obj.setMethod('clsti');
        end
      else
        obj = evrigui('analysis');
        obj.setMethod('clsti');
      end
      obj.setModel(app.myModel);
    end

    % Menu selected function: DataSetObjectMenu
    function DataSetObjectMenuSelected(app, event)
      if ~isempty(app.myModel)
        app.myModel = [];
      end
      
      %load a dataset object with temps in axisscale{1,1}
      [rawdata,name,location] = lddlgpls('dataset',['Select DataSet Object']);
      if isempty(rawdata)
        return
      end
      compName = rawdata.labelname{1};
      app.loadDSO(rawdata,compName);
      
      updateOverviewTable(app);
      app.myAxisscale = rawdata.axisscale{2,1};
    end

    % Menu selected function: ChangeComponentNameMenu
    function ChangeComponentNameMenuSelected(app, event)
      prompt = {'Enter new name for component:'};
      dlgtitle = 'New Component Name';
      dims = [1 35];
      definput = {'New Name'};
      myNewName = inputdlg(prompt,dlgtitle,dims,definput);

      currentTab = app.TabGroup.SelectedTab;
      currentTab.Title = myNewName{1};
      thisDSO = currentTab.UserData.DSO;
      thisDSO.labelname{1} = myNewName{1};
      updateOverviewTable(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.CLSTIModelBuilderUIFigure = uifigure('Visible', 'off');
      app.CLSTIModelBuilderUIFigure.Name = 'CLSTI Model Builder';
      app.CLSTIModelBuilderUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @CLSTIModelBuilderUIFigureWindowButtonMotion, true);
      app.TabGroup = uitabgroup(app.CLSTIModelBuilderUIFigure);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.TabGroup.Tag = 'compTabGroup';
      app.OverviewTab = uitab(app.TabGroup);
      app.OverviewTab.Title = 'Overview';
      app.OverviewTab.Tag = 'overviewTab';
      app.UITable = uitable(app.OverviewTab);
      app.UITable.ColumnName = {'Components'; 'Temps'};
      app.UITable.RowName = {};
      app.OpenButton = uibutton(app.OverviewTab, 'push');
      app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
      app.OpenButton.Tag = 'openAll';
      app.OpenButton.Enable = 'off';
      app.OpenButton.Tooltip = {'Open combined data in DataSet Editor'};
      app.OpenButton.Text = 'Open';
      app.PreprocessingButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.PreprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocessingButtonPushed, true);
      app.PreprocessingButton.Tag = 'preproBtn';
      app.PreprocessingButton.Enable = 'off';
      app.PreprocessingButton.Visible = 'off';
      app.PreprocessingButton.Text = 'Preprocessing';
      app.VariableSelectionButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.VariableSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @VariableSelectionButtonPushed, true);
      app.VariableSelectionButton.Tag = 'varSelBtn';
      app.VariableSelectionButton.Text = 'Variable Selection';
      app.AddComponent = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.AddComponent.ButtonPushedFcn = createCallbackFcn(app, @addComponent_pushed, true);
      app.AddComponent.Tag = 'addCompBtn';
      app.AddComponent.Text = '+';
      app.RemoveComponent = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.RemoveComponent.ButtonPushedFcn = createCallbackFcn(app, @removeComponent_pushed, true);
      app.RemoveComponent.Tag = 'removeCompBtn';
      app.RemoveComponent.Text = '-';
      app.BuildModelButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.BuildModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildModelButtonPushed, true);
      app.BuildModelButton.Tooltip = {'Build and save CLSTI model'};
      app.BuildModelButton.Text = 'Build Model';
      app.infoTextArea = uitextarea(app.CLSTIModelBuilderUIFigure);
      app.infoTextArea.Tag = 'preproTextArea';
      app.infoTextArea.Editable = 'off';
      app.infoTextArea.Visible = 'off';
      app.HelpButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
      app.HelpButton.Text = 'Help';
      app.CloseButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
      app.CloseButton.Text = 'Close';
      app.LoadModelButton = uibutton(app.CLSTIModelBuilderUIFigure, 'push');
      app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
      app.LoadModelButton.Text = 'Load Model';
      app.SaveImageButton = uiimage(app.CLSTIModelBuilderUIFigure);
      app.SaveImageButton.ImageClickedFcn = createCallbackFcn(app, @SaveImageButtonClicked, true);
      app.SaveImageButton.Enable = 'off';
      app.SaveImageButton.Tooltip = {'Save model'};
      app.SendToAnalysis = uiimage(app.CLSTIModelBuilderUIFigure);
      app.SendToAnalysis.ImageClickedFcn = createCallbackFcn(app, @SendToAnalysisImageClicked, true);
      app.SendToAnalysis.Enable = 'off';
      app.SendToAnalysis.Tooltip = {'Send model to Analysis window'};
      app.LoadMenu = uicontextmenu(app.CLSTIModelBuilderUIFigure);
      app.LoadMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @LoadMenuOpening, true);
      app.LoadDataMenu = uimenu(app.LoadMenu);
      app.LoadDataMenu.Separator = 'on';
      app.LoadDataMenu.Text = 'Load Data';
      app.DefinitionFileMenu = uimenu(app.LoadDataMenu);
      app.DefinitionFileMenu.MenuSelectedFcn = createCallbackFcn(app, @loadData, true);
      app.DefinitionFileMenu.Text = 'Definition File';
      app.DataSetObjectMenu = uimenu(app.LoadDataMenu);
      app.DataSetObjectMenu.MenuSelectedFcn = createCallbackFcn(app, @DataSetObjectMenuSelected, true);
      app.DataSetObjectMenu.Text = 'DataSet Object';
      app.ChangeComponentNameMenu = uimenu(app.LoadMenu);
      app.ChangeComponentNameMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeComponentNameMenuSelected, true);
      app.ChangeComponentNameMenu.Text = 'Change Component Name';
      app.PreproAndVarSelMenu = uicontextmenu(app.CLSTIModelBuilderUIFigure);
      % Assign app.PreproAndVarSelMenu
      app.PreprocessingButton.ContextMenu = app.PreproAndVarSelMenu;
      app.VariableSelectionButton.ContextMenu = app.PreproAndVarSelMenu;
      app.OpenMenu = uimenu(app.PreproAndVarSelMenu);
      app.OpenMenu.Enable = 'off';
      app.OpenMenu.Text = 'Open';
      app.ViewMenu = uimenu(app.PreproAndVarSelMenu);
      app.ViewMenu.Enable = 'off';
      app.ViewMenu.Text = 'View';
      % Show the figure after all components are created
      app.CLSTIModelBuilderUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = clsti_gui(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.CLSTIModelBuilderUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.CLSTIModelBuilderUIFigure)
    end
  end
end