classdef shapleygui < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                      matlab.ui.Figure
    FileMenu                      matlab.ui.container.Menu
    LoadResultsMenu               matlab.ui.container.Menu
    SaveResultsMenu               matlab.ui.container.Menu
    ClearResultsMenu              matlab.ui.container.Menu
    ClearDataandModelMenu         matlab.ui.container.Menu
    ClearAllMenu                  matlab.ui.container.Menu
    CloseWindowMenu               matlab.ui.container.Menu
    ViewMenu                      matlab.ui.container.Menu
    SpawnStaticViewMenu           matlab.ui.container.Menu
    ResultsMenu                   matlab.ui.container.Menu
    HelpMenu                      matlab.ui.container.Menu
    ShowHelpMenu                  matlab.ui.container.Menu
    GridLayout                    matlab.ui.container.GridLayout
    ShapleyValueSettingsPanel     matlab.ui.container.Panel
    GridLayout2                   matlab.ui.container.GridLayout
    TabGroup                      matlab.ui.container.TabGroup
    GroupVariablesTab             matlab.ui.container.Tab
    GridLayout3                   matlab.ui.container.GridLayout
    VariablesRbGroup              matlab.ui.container.ButtonGroup
    IntervalWidthButton           matlab.ui.control.RadioButton
    CreateGroupsButton            matlab.ui.control.RadioButton
    GridLayout4                   matlab.ui.container.GridLayout
    intwidth_field                matlab.ui.control.NumericEditField
    GroupVariablesButton          matlab.ui.control.Button
    SelectSamplesTab              matlab.ui.container.Tab
    GridLayout5                   matlab.ui.container.GridLayout
    SelectSamplesButton           matlab.ui.control.Button
    SelectSamplestoExplainModelLabel  matlab.ui.control.Label
    SamplesRbGroup                matlab.ui.container.ButtonGroup
    IncludeAllSamplesButton       matlab.ui.control.RadioButton
    ManualSelectionButton         matlab.ui.control.RadioButton
    HelpTab                       matlab.ui.container.Tab
    GridLayout6                   matlab.ui.container.GridLayout
    settingshelpbutton            matlab.ui.control.Button
    ExecuteButton                 matlab.ui.control.Button
    LoadDataandModelPanel         matlab.ui.container.Panel
    GridLayout7                   matlab.ui.container.GridLayout
    CalXButton                    matlab.ui.control.Button
    XButton                       matlab.ui.control.Button
    ModelButton                   matlab.ui.control.Button
    loadhelpbutton                matlab.ui.control.Button
    OpenModelButton               matlab.ui.control.Button
    PlotSettingsPanel             matlab.ui.container.Panel
    GridLayout8                   matlab.ui.container.GridLayout
    ExplanationTypeLabel          matlab.ui.control.Label
    ExplainButtonGroup            matlab.ui.container.ButtonGroup
    modelrb                       matlab.ui.control.RadioButton
    samplerb                      matlab.ui.control.RadioButton
    SampleDropdown                matlab.ui.control.DropDown
    PredictorDropdown             matlab.ui.control.DropDown
    SelectPredictorDropDownLabel  matlab.ui.control.Label
    PlotType                      matlab.ui.control.DropDown
    PlotTypeDropDownLabel         matlab.ui.control.Label
    SampleOrderDropDown           matlab.ui.control.DropDown
    SampleOrderDropDownLabel      matlab.ui.control.Label
    plothelpbutton                matlab.ui.control.Button
    scalebox                      matlab.ui.control.CheckBox
    XButtonContextMenu            matlab.ui.container.ContextMenu
    LoadXButtonMenu               matlab.ui.container.Menu
    ClearXButtonMenu              matlab.ui.container.Menu
    LoadfromCalibrationXMenu      matlab.ui.container.Menu
    CalXButtonContextMenu         matlab.ui.container.ContextMenu
    LoadCalXMenu                  matlab.ui.container.Menu
    ClearCalXMenu                 matlab.ui.container.Menu
    ModelButtonContextMenu        matlab.ui.container.ContextMenu
    LoadModelMenu                 matlab.ui.container.Menu
    ClearModelMenu                matlab.ui.container.Menu
  end

  
  properties (Access = public)
    calx                      % calibration data
    x                         % explanation x
    xinclude                  % original included samples in explanation x
    model                     % EVRIModel
    ready                     % computation status flag
    shap                      % shapley values
    baseprediction            % average prediction on calibration data
    vargroups                 % variable groups for shapley
    vargrouplabels            % variable group labels for plot
    vargrouplabel             % axis label for variable groups
    xticks                  
    xticklabels
    xaxislabel
    explainpred               % predictions on explanation data
    niter                     % niter for shapley
    nbatches                  % nbatches for shapley
    shapoptions               % shapley options
    shapplot                  % shapley plot figure handle
    ax                        % shapley plot axes
    predax                    % shapley plot axes for heatmap predictions
    timestamp                 % unique identifier
    canscalevals              % flag for beeswarm scaling
    doxjitter                 % flag for beeswarm jittering
    hovertext   
    panels
    highlightedsamples = {};  % highlighted samples in heatmap
    hmh                       % heatmap handle
    order                     % sample order for plotting shapley
    predictor                 % current predictor to plot
    userdefinedgroups         % variable groups defined by user
  end

  
  methods (Access = public)
    
    function Update(app)
      % update components and properties
      
      if ~isempty(app.calx)
        set(app.LoadfromCalibrationXMenu,'Enable','on');
      else
        set(app.LoadfromCalibrationXMenu,'Enable','off');
      end
            
      if ~isempty(app.model)% set predictor dropdown to have outputs from model
        if ~app.model.isclassification
          app.PredictorDropdown.Items = app.model.predictionlabel;
        else
          app.PredictorDropdown.Items = app.model.classification.classids;
        end
        app.PredictorDropdown.ItemsData = 1:length(app.PredictorDropdown.Items);
      end
      
      if ~isempty(app.model)
        set(app.OpenModelButton,'Enable','on');
      else
        set(app.OpenModelButton,'Enable','off');
      end
      
      % determine if app is ready to calculate shapley values
      app.CheckReadiness(); 
      
      if app.ready
        app.ShapleySettingsSwitch('on');
      else
        app.ShapleySettingsSwitch('off');
        app.PlotSettingsSwitch('off');
      end
      % update the dropdown which samples to explain
      if ~isempty(app.x)
        if isdataset(app.x)
          if ~isempty(app.x.include{1})
            % what samples are we including?
            if app.IncludeAllSamplesButton.Value
              app.x.include{1} = 1:size(app.x,1);
            else
              app.x.include{1} = app.xinclude;
            end
            app.SampleDropdown.Items = cellstr(string(app.x.include{1}));
            app.SampleDropdown.ItemsData = 1:length(app.SampleDropdown.Items);
            set(app.SelectSamplesButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
          else
            set(app.SelectSamplesButton,'BackgroundColor',[0.96 0.96 0.96]);
          end
        end
      else
        set(app.SelectSamplesButton,'BackgroundColor',[0.96 0.96 0.96]);
      end
      
      if app.modelrb.Value
        set(app.SampleDropdown,'Enable','off');
      else
        set(app.SampleDropdown,'Enable','on');
      end
      
      % enable the save button and plotting settings
      if ~isempty(app.shap) && app.ready
        set(app.SaveResultsMenu,'Enable','on');
        app.PlotSettingsSwitch('on');
        app.CreateVarGroupLabels();
      else
        set(app.SaveResultsMenu,'Enable','off');
        app.PlotSettingsSwitch('off');
      end
      % bring plot to front
      if ~isempty(app.shapplot)
        if ishandle(app.shapplot)
          figure(app.shapplot);
        end
      end
      
      % update the sample index dropdown menu
      if app.ready
        app.SampleOrderDropDown.Items = {'Linear Index' app.PredictorDropdown.Items{:}};
      end
      
      if ~app.canscalevals
        set(app.scalebox,'Enable','off');
      end
      figure(app.UIFigure)
    end
    
    %--------------------------------------------------------------------------
    
    function ShapleySettingsSwitch(app,flag)
      % controls the ability to use the shapley settings panel
      
      % calculate shapley button
      set(app.ExecuteButton,'Enable',flag);
      % variable settings
      app.VariablesRbGroupSelectionChanged;
      set(app.IntervalWidthButton,'Enable',flag);
      %set(app.intwidth_field,'Enable',flag);
      %set(app.GroupVariablesButton,'Enable',flag);
      set(app.CreateGroupsButton,'Enable',flag);
      % sample settings
      set(app.SelectSamplesButton,'Enable',flag);
      set(app.IncludeAllSamplesButton,'Enable',flag);
      set(app.ManualSelectionButton,'Enable',flag);
    end
    
    %--------------------------------------------------------------------------
    
    function PlotSettingsSwitch(app,flag)
      % controls the ability to use the plot settings panel
      
      set(app.ExplainButtonGroup,'Enable',flag);
      set(app.PredictorDropdown,'Enable',flag);
      set(app.PlotType,'Enable',flag);
      if strcmpi(flag,'off')
        set(app.SampleOrderDropDown,'Enable',flag);
      end
    end
    
    %--------------------------------------------------------------------------
    
    function SpawnPlot(app)
      % spawn a figure if one does not exist
      
      fig = findall(groot,'Type','Figure');
      if ~isempty(fig) && ~isempty(app.timestamp)
        shaph = arrayfun(@(x) contains(get(x,'Tag'),'shapplot_'),fig);
        if any(shaph)
          %pick the first
          inds = find(shaph);
          app.shapplot = fig(inds(1));
        else
          app.CreateNewPlot();
        end
      else
        app.CreateNewPlot();
      end
    end
    
    %--------------------------------------------------------------------------
    
    function CreateNewPlot(app)
      % create figure with timestamp
      
      app.shapplot = figure;
      app.ax = gca;
      app.timestamp = num2str(now);
      app.shapplot.Tag = ['shapplot_' app.timestamp];
      app.shapplot.Name = 'Shapley Values';
    end
    
    %--------------------------------------------------------------------------

    function UpdatePlot(app)
      % update the shapplot based on current settings
      
      if ~all(ishandle(app.ax)) || isempty(app.ax)
        app.SpawnPlot();
      end
      
      delete(app.shapplot.Children);
      app.ax = axes('Parent',app.shapplot);
      figure(app.shapplot);
      
      % what is the predictor?
      app.predictor = app.PredictorDropdown.Value;
      shapsize = size(app.shap);
      
      % turn off the sample order dropdown if not heatmap
      switch app.PlotType.Value
        case 'Line'
          set(app.SampleOrderDropDown,'Enable','off');
          set(app.scalebox,'Enable','off');
        case 'Line+points'
          set(app.SampleOrderDropDown,'Enable','off');
          set(app.scalebox,'Enable','off');
        case 'Scatter'
          set(app.SampleOrderDropDown,'Enable','off');
          set(app.scalebox,'Enable','off');
        case 'Beeswarm'
          set(app.SampleOrderDropDown,'Enable','off');
        case 'Bar'
          set(app.SampleOrderDropDown,'Enable','off');
          set(app.scalebox,'Enable','off');
        case 'Heatmap'
          set(app.scalebox,'Enable','off');
      end
      
      % what are we showing?
      if app.modelrb.Value
        % show model explanation
        % go off of plot type
        switch app.PlotType.Value
          case {'Line' 'Line+points' 'Scatter'}
            switch app.PlotType.Value
              case 'Line'
                mkr = '-';
              case 'Line+points'
                mkr = 'o-';
              case 'Scatter'
                mkr = 'o';
            end
            % this will show a vector which is an aggregation of the
            % Shapley Value for all of the samples
            subplot(1,1,1,app.ax);
            if length(shapsize)==2
              data = mean(abs(app.shap));
            else
              data = squeeze(mean(abs(app.shap(:,:,app.predictor))));
            end
            plot(app.ax,data,mkr);
            ylabel('Mean(|Shapley Values|)')
            colorbar(app.ax,'off');
            box on;
            set(app.ax,'XTick',app.xticks)
            set(app.ax,'XTickLabel',app.xticklabels)
            set(app.ax,'XLim',[0.5 shapsize(2)+0.5]);
            xlabel(app.xaxislabel)
            ylim(app.ax,'auto');
            %--------------------------------------------------------------
            
          case 'Heatmap'
            
            tb = uitoolbar(app.shapplot);
            pt = uipushtool(tb);
            pt.CData = gettbicons('Deselect');
            pt.Tooltip = 'Remove higlighted samples';
            set(pt,'ClickedCallback',@app.ClearHighlightedSamples);
            
            if app.ready
              set(app.SampleOrderDropDown,'Enable','on');
            end
            
            % get sample order
            orderbythis = app.SampleOrderDropDown.Value;
            if strcmpi(orderbythis,'Linear Index')
              app.order = 1:size(app.explainpred,1);
            else
              % sort by some predictor
              indpred = find(strcmpi(orderbythis,app.PredictorDropdown.Items));
              [~,app.order] = sort(app.explainpred(:,indpred),'ascend');
            end
            % subplot of prediction sorted by some value
            app.predax = subplot(4,1,1,app.ax);
            p = plot(app.predax,app.explainpred(app.order,app.predictor));
            set(p,'buttondownfcn',@app.HighlightHeatmap);
            grid on;
            hold on;
            h = hline(app.baseprediction(app.predictor),'--k');
            ylabel(app.predax,['Predicted ' app.PredictorDropdown.Items(app.predictor)]);
            set(app.predax,'Title',[]);
            set(app.predax,'XTickLabel',[]);
            xlim(app.predax,[0.5 shapsize(1)+0.5]);
            colorbar(app.predax,'off');
            xlabel(app.predax,[])
            box off;
            legend(app.predax,h,['Average Prediction for ' app.PredictorDropdown.Items{app.predictor} ': ' ...
                  num2str(round(app.baseprediction(app.predictor),2))],'Location','northoutside','AutoUpdate','off')
            % subplot of the shapley values corresponding to the previous
            % subplot
            app.ax = subplot(4,1,[2,3,4]);
            % this will be an image showing the Shapley Values for each
            % sample and variable group
            if length(shapsize)==2
              data = app.shap(app.order,:);
            else
              data = squeeze(app.shap(app.order,:,app.predictor));
            end
            app.PlotHeatmap(data');
            xlabel(app.ax,['Samples Ordered by ' orderbythis]);
            yticks(app.ax,app.xticks)
            yticklabels(app.ax,app.xticklabels);
            ylabel(app.xaxislabel)
            linkaxes([app.predax,app.ax],'x');
            xlim(app.ax,[0.5 shapsize(1)+0.5]);
            ylim(app.ax,[0.5 shapsize(2)+0.5]);
            %--------------------------------------------------------------
            
          case 'Beeswarm'
            if app.ready && app.canscalevals
              set(app.scalebox,'Enable','on');
            end
            
            subplot(1,1,1,app.ax);
            % this will show a scatter plot of the shapley values for each
            % variable group. each point is a sample, and each sample is
            % colored by the response at that variable
            if length(shapsize)==2
              data = app.shap;
            else
              data = squeeze(app.shap(:,:,app.predictor));
            end
            app.PlotBeeswarm(data);
            ylabel('Shapley Values');
            set(app.ax,'XTick',app.xticks)
            set(app.ax,'XTickLabel',app.xticklabels)
            set(app.ax,'XLim',[0.5 shapsize(2)+0.5]);
            xlabel(app.xaxislabel)
            ylim(app.ax,[-1*abs(max(data,[],'all')) abs(max(data,[],'all'))]);
            %--------------------------------------------------------------
            
          case 'Bar'
            subplot(1,1,1,app.ax);
            % same idea as the line plot
            if length(shapsize)==2
              data = mean(abs(app.shap));
            else
              data = squeeze(mean(abs(app.shap(:,:,app.predictor))));
            end
            bar(app.ax,data);
            ylabel('Mean(|Shapley Values|)')
            colorbar(app.ax,'off');
            box on;
            set(app.ax,'XTickLabel',app.xticklabels)
            set(app.ax,'XLim',[0.5 shapsize(2)+0.5]);
            xlabel(app.xaxislabel)
            ylim(app.ax,'auto');
            set(app.ax,'XTick',app.xticks)
            set(app.ax,'XTickLabel',app.xticklabels)
        end
    
        title(['Shapley Model Explanation on ' app.PredictorDropdown.Items{app.predictor}])
      %----------------------------------------------------------------------
      
      elseif app.samplerb.Value
        subplot(1,1,1,app.ax);
        % show sample explanation
        thissample = app.SampleDropdown.Value;
        thissamplelabel = app.SampleDropdown.Items{thissample};
        if length(shapsize)==2
          data = app.shap(thissample,:);
        else
          data = squeeze(app.shap(thissample,:,app.predictor));
        end
        app.PlotBar(data);
        colorbar(app.ax,'off');
        ylabel('Shapley Values')
        title(['Shapley Values for Sample ' thissamplelabel ' on ' app.PredictorDropdown.Items{app.predictor}])
        set(app.ax,'XTick',app.xticks);
        set(app.ax,'XTickLabel',app.xticklabels)
        set(app.ax,'XLim',[0.5 shapsize(2)+0.5]);
        xlabel(app.xaxislabel)
      end
      
      % add contextmenu to keep variables with the highest shap
      app.SetContextMenu();
      app.Update();
    end
    
    %--------------------------------------------------------------------------
    
    function CreateVarGroupLabels(app)
      % extract variable information for plotting
      
      if isempty(app.vargroups)
        evrierrordlg('Could not find Variable Groups from Shapley.')
      else
        % give priority to axisscale, then to labels from the dataset.
        useaxisscale = 0;
        uselabel = 0;
        uselinspace = 0;
        if ~isempty(app.x.label{2})
          uselabel = 1;
          app.vargrouplabel = 'label';
        elseif ~isempty(app.x.axisscale{2})
          useaxisscale = 1;
          app.vargrouplabel = 'axisscale';
        else
          uselinspace = 1;
          app.vargrouplabel = 'linspace';
        end
        % first, was int_width 1? or is vargroups the same length as the
        % number of variables? if so just use original variables
        if length(app.vargroups)==size(app.x,2) || all(cellfun(@length,app.vargroups)==1)
          %use all original included variables
          app.canscalevals = 1;
          if useaxisscale
            app.vargrouplabels = app.x.axisscale{2}';
          elseif uselabel
            app.vargrouplabels = app.x.label{2};
          elseif uselinspace
            app.vargrouplabels = (1:size(app.x,2))';
          end
          app.xaxislabel = 'Variables';
          if size(app.shap,2)~=size(app.x,2)
            app.PlotType.Value = 'Scatter';
          end
          % infill shap with nans where variables are excluded
          shapsize = size(app.shap);
          if ~isequal(shapsize(2),size(app.x,2))
            if ndims(app.shap)==2
              newshap = nan(size(app.shap,1),size(app.x,2));
              newshap(:,app.model.detail.includ{2}) = app.shap;
              app.shap = newshap;
            elseif ndims(app.shap)==3
              % newshap = nan(size(app.shap,1),size(app.x,2),size(app.x,3));
              % for i=1:size(app.shap,1)
              %   for j=1:size(app.shap,2)
              %     for k=1:size(app.shap,3)
              %       newshap(i,app.model.detail.includ{2}(j),k) = app.shap(i,j,k);
              %     end
              %   end
              % end
              newshap = nan(size(app.shap,1),size(app.x,2),size(app.shap,3));
              for j=1:size(app.shap,2)
                newshap(:,app.model.detail.includ{2}(j),:) = app.shap(:,j,:);
              end
              app.shap = newshap;
            end
          end
        else
          %use grouping of variables
          app.canscalevals = 0;
          app.vargrouplabels = (1:length(app.vargroups))';
          app.xaxislabel = 'Variable Groups';
        end
        %what should the xticks be? only show 10 at a time
        numshowing = min(10,size(app.vargrouplabels,1));
        if size(app.shap,2) <=10
          app.xticklabels = app.vargrouplabels(1:numshowing,:);
          app.xticks = 1:numshowing;
          app.doxjitter = 1;
        else
          spacing = floor(length(app.vargrouplabels)/numshowing);
          app.xticks = 1:spacing:length(app.vargrouplabels);
          app.xticklabels = app.vargrouplabels(1:spacing:length(app.vargrouplabels),:);
          app.doxjitter = 0;
        end
        if size(app.shap,2)<=50
          app.doxjitter = 1;
        else
          app.doxjitter = 0;
        end
      end
    end
    
    %--------------------------------------------------------------------------

    function CheckReadiness(app)
      % see if app is ready to calculate shapley values
      
      if isempty(app.model)
        % no model, not ready
        app.ready = 0;
        return
      else
        % have a model
        if isempty(app.x) || isempty(app.calx)
          app.ready = 0;
          return
        else
          app.ready = 1;
          return
        end
      end
    end
    
    %--------------------------------------------------------------------------
    
    function [buttons,buttontexts] = GetButtonInfo(app)
      buttons = {app.ModelButton 
                 app.XButton
                 app.CalXButton
                 app.intwidth_field
                 app.ExecuteButton
                 app.scalebox
                 app.SelectSamplesButton
                 app.GroupVariablesButton};
      buttontexts = cellfun(@(x) x.UserData,buttons,'UniformOutput',false);
      
    end
    
    %--------------------------------------------------------------------------
    
    function flag = IsHoveringOver(app,mousepos,component)
      flag = 0;
      done = 0;
      componentpos = component.Position([1,2]);
      componentcopy = component;
      while ~done
        [componentpos,done,componentcopy] = app.GetComponentPos(componentcopy,componentpos);
      end
      componentpos = [componentpos component.Position([3,4])];
      if (mousepos(1) >= componentpos(1)) && (mousepos(1) <= componentpos(1)+componentpos(3)) &&...
          (mousepos(2) >= componentpos(2)) && (mousepos(2) <= componentpos(2)+componentpos(4))
        flag = 1;
      end
    end
    
    %--------------------------------------------------------------------------
    
    function SetContextMenu(app)
      %create context menu for shapplot 
      
      cmf = uicontextmenu(app.shapplot);
      m1f = uimenu(cmf,'Label','Save Variables with Highest Shapley','Callback',{@app.SaveVars});
      m2f = uimenu(cmf,'Label','Show Variable Groups','Callback',{@app.ShowVarGroups});
      set(app.shapplot,'ContextMenu',cmf)
      set(app.ax,'ContextMenu',cmf);
    end
    
    %--------------------------------------------------------------------------
    
    function SaveVars(app,varargin)
      % save variables with the highest shapley from plot
      
      prompt = 'Enter the number of Variable Groups to save:';
      dlgtitle = 'Save Variables';
      fieldsize = [1 45];
      answer = inputdlg(prompt,dlgtitle,fieldsize);
      if isempty(answer)
        return
      end
      numvars = str2num(answer{1});
      if ~isnumeric(numvars) || isempty(numvars)
        evrierrordlg('Expecting a numeric value.')
        return
      else
        if ~((1<=numvars) && (numvars<=length(app.vargroups)))
          evrierrordlg(['Expecting number between 1 and ' num2str(length(app.vargroups)) '.']);
          return
        end
      end
      
      if length(size(app.shap))==2
        data = mean(abs(app.shap));
      else
        app.predictor = app.PredictorDropdown.Value;
        data = squeeze(mean(abs(app.shap(:,:,app.predictor))));
      end
      [~,vars] = sort(data,'descend');
      thesevars = sort([app.vargroups{vars(1:numvars)}]);
      varname = ['top_vars_shapley_' app.timestamp];
      svdlgpls(thesevars,'Save Top Variables from Shapley Values',varname);
    end
    
    %--------------------------------------------------------------------------
    
    function ShowVarGroups(app,varargin)
      % show the grouping of variables 
      
      o = encode('options');
      o.includeclear = 'off';
      txt = {};
      for i=1:length(app.vargroups)
        txt = {txt{:} encode(app.vargroups{i},['Group ' num2str(i)],o)};
      end
      o = infobox('options');
      o.figurename = 'Shapley Variable Groups';
      o.maxsize = [inf 360];
      infobox(txt,o);
    end
    
    %--------------------------------------------------------------------------
    
    function [componentpos,done, h] = GetComponentPos(app,h,pos)
      % what is the parent?
      if ishandle(h.Parent)
        componentpos = pos + h.Parent.Position([1,2]);
        
        if any(cellfun(@(x) isequal(h.Parent,x),app.panels))
          done = 1;
        else
          done = 0;
          h = h.Parent;
        end
      end
    end
    
    %--------------------------------------------------------------------------
    
    function LoadXButton(app,rawdata,name)
      % load the x button with the explanation data
      
      if ~isempty(rawdata)
        app.x = rawdata;
        app.xinclude = app.x.include{1};
        % set limits for sample dropdown
        app.SampleDropdown.Items = split(num2str(1:size(app.x,1)));
        app.SampleDropdown.Enable = 'on';
        set(app.XButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
        txt = {};
        txt = {txt{:} name};
        txt = {txt{:} ['Size: [' num2str(size(app.x)) ']']};
        app.XButton.Tooltip = txt;
        app.Update();
      else
        app.XButton.Tooltip = 'Load in a Dataset or Double';
      end
    end
    
    %--------------------------------------------------------------------------
    
    function LoadCalXButton(app,rawdata,name)
      % load the calx button with the calibration data
      
      if ~isempty(rawdata)
        if ~isdataset(rawdata)
          rawdata = dataset(rawdata);
        end
        app.calx = rawdata;
        set(app.CalXButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
        txt = {};
        txt = {txt{:} name};
        txt = {txt{:} ['Size: [' num2str(size(app.calx)) ']']};
        app.CalXButton.Tooltip = txt;
        app.Update();
      else
        app.CalXButton.Tooltip = 'Load in a Dataset or Double';
      end
    end
    
    %--------------------------------------------------------------------------
    
    function LoadModelButton(app,mod)
      % load the model
      
      if ~isempty(mod)
        % set model property
        app.model = mod;
        set(app.ModelButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
        txt = {};
        txt = {txt{:} mod.uniqueid};
        txt = {txt{:} ''};
        txt = {txt{:} ['Modeltype: ' upper(app.model.modeltype)]};
        app.ModelButton.Tooltip = txt;
        app.Update();
      else
        set(app.ModelButton,'Tooltip','Load in a EVRIModel');
      end
    end
    
    %--------------------------------------------------------------------------
    
    function HighlightHeatmap(app,varargin)
      % highlight samples in the heatmap plot and prediction plot 
      
      
      pos = get(gca,'CurrentPoint');
      sample = round(pos(1));
      %plot red rectangle in the heatmap corresponding to this sample
      xcoords = [sample-0.5 sample-0.5 sample+0.5 sample+0.5 sample-0.5];
      ycoords = [size(app.shap,2)+0.5 0.5 0.5 size(app.shap,2)+0.5 size(app.shap,2)+0.5];
      hold(app.ax,'on');
      rec = plot(app.ax,xcoords,ycoords,'green','linewidth',3);
      
      % highlight point in the prediction plot
      sc = scatter(app.predax,sample,app.explainpred(app.order(sample),app.predictor),100,[0.93333 0.5098 0.93333],'filled');
      txt = text(app.predax,sample,app.explainpred(app.order(sample),app.predictor),['Sample ' num2str(app.order(sample))]);
      % add handles to highlightedsamples property
      app.highlightedsamples = {app.highlightedsamples{:} sc rec txt};
    end
    
    %--------------------------------------------------------------------------
    
    function PlotHeatmap(app,data)
      %plot the heatmap with rwb where 0 is always white
      
      app.hmh = imagesc(app.ax,data,'AlphaData',~isnan(data));
      box off;
      set(app.hmh,'buttondownfcn',@app.HighlightHeatmap);
      %set(app.ax,'XLim',[0 size(data,2)]);
      %set(app.ax,'YLim',[0 size(data,1)]);
      vmin = min(data(:));
      vmax = max(data(:));
      M = max(abs(vmin), abs(vmax));
      color_range = linspace(vmin,vmax, 256)';
      R = interp1([-M 0 M],[0 1 1], color_range);
      G = interp1([-M 0 M],[0 1 0], color_range);
      B = interp1([-M 0 M],[1 1 0], color_range);
      C = [R G B];
      colormap(app.ax,C);
      cb = colorbar(app.ax);
      cb.Position(1) = 0.91;
    end
    
    %--------------------------------------------------------------------------
    
    function ClearHighlightedSamples(app,varargin)
      % get rid of highlighted samples
      
      cellfun(@delete,app.highlightedsamples);
      app.highlightedsamples = {};
    end
    
    %--------------------------------------------------------------------------
    
    function PlotBeeswarm(app,data)
      % plot the beeswarm of the shapley values
      
      axisscale = 1:size(app.shap,2);
      docoloring = 0;
      if size(data,2)==size(app.x,2)
        docoloring = 1;
      end
      if app.doxjitter
        xjitter = 0.25;
      else
        xjitter = 0.001;
      end
      for j=1:size(data,2)
        if docoloring
          colormap(app.ax,'cool');
          if app.scalebox.Value
            mydata = auto(app.x.data(app.x.include{1},j));
          else
            mydata = app.x.data(app.x.include{1},j);
          end
          scatter(app.ax,repmat(axisscale(j),size(data,1),1),data(:,j),48,mydata,'filled','XJitter','density','YJitter','none','XJitterWidth',xjitter,'MarkerFaceAlpha',0.5,'MarkerEdgeAlpha',0.5);
        else
          scatter(app.ax,repmat(axisscale(j),size(data,1),1),data(:,j),48,'filled','XJitter','density','YJitter','none','XJitterWidth',xjitter,'MarkerFaceAlpha',0.5,'MarkerEdgeAlpha',0.5);
        end
        hold(app.ax,'on');
      end
      hline(0,'--k');
      xlim(app.ax,[axisscale(1) axisscale(end)])
      ylim(app.ax,'auto');
      ylabel(app.ax,'Shapley Values');      
      if docoloring
        cb = colorbar(app.ax);
        if app.scalebox.Value
          cb.Ticks = cb.Limits;
          cb.TickLabels = {'Low' 'High'};
        end
        cb.Label.String = 'Response';
      else
        colorbar(app.ax,'off');
      end
    end
    
    %--------------------------------------------------------------------------
    
   function [] = PlotBar(app,data)
     % bar plot of shapley values on samples
     
     nvars = size(data,2);
     for i=1:length(data)
       obj = bar(app.ax,i,data(i));
       if data(i)<0
         set(obj,'FaceColor','b');
       else
         set(obj,'FaceColor','r');
       end
       hold(app.ax,'on');
     end
     ylim(app.ax,'auto');
     xlim(app.ax,[1 nvars]);
   end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function StartupFcn(app)
%       app.ModelButton.UserData = 'Load in a EVRIModel';
%       app.CalXButton.UserData = 'Load in a Dataset or Double';
%       app.XButton.UserData = 'Load in a Dataset or Double';
%       app.intwidth_field.UserData = 'Group variables by interval width. Provide the interval width of the variables';
%       app.ExecuteButton.UserData = 'Calculate Shapley Values';
%       app.scalebox.UserData = 'Autoscale values in Beeswarm Plot';
%       app.SelectSamplesButton.UserData = 'Select Samples from Explanation X to generate Shapley Values for';
%       app.GroupVariablesButton.UserData = 'Group Variables manually';
%       app.hovertext = uitextarea(app.UIFigure,'Visible','off','Editable','off');
      %app.panels = {app.LoadDataandModelPanel app.ShapleyValueSettingsPanel app.PlotSettingsPanel};
      app.OpenModelButton.Icon = gettbicons('uparrow_blue');
    end

    % Callback function: LoadXButtonMenu, XButton
    function XButtonValueChanged(app, event)
      app.hovertext.Visible = 'off';
      [rawdata,name,~] = lddlgpls({'double' 'dataset'},'Load X');
      if ~isdataset(rawdata)
        rawdata = dataset(rawdata);
      end
      app.LoadXButton(rawdata,name);
    end

    % Callback function: LoadModelMenu, ModelButton
    function ModelButtonValueChanged(app, event)
      % TODO: add in ability to load function handles?
      [mod,~, ~] = lddlgpls({'evrimodel'}, 'Load Calibration Model');
      if strcmpi(mod.modeltype,'modelselector')
        evrierrordlg('MODELSELECTOR models are not supported.')
        return
      end
      app.LoadModelButton(mod);
    end

    % Button pushed function: ExecuteButton
    function ExecuteButtonPushed(app, event)
      % calculate shapley values
      
      if app.ready
        so = evrishapley('options');
        % check the interval width
        if app.IntervalWidthButton.Value
          if ceil(length(app.x.include{2})/app.intwidth_field.Value)<=1
            evrierrordlg(['Number of Variable groups is greater than or equal to the ' ...
              'number of included variables. Please choose a smaller number for the interval width.']);
            return
          end
          so.int_width = app.intwidth_field.Value;
        % check the manual groupings
        elseif app.CreateGroupsButton.Value
          if ~isempty(app.userdefinedgroups)
            so.variablegroups = app.userdefinedgroups;
          end
        end
        
        % need more than 1 sample to explain
        if length(app.x.include{1})<=1
          evrierrordlg('Need at least 2 samples to explain the model. Please include more samples in Explanation X');
          return
        end
        
        set(app.ExecuteButton,'Enable','off');
        set(app.UIFigure,'Pointer','watch');
        figure(app.UIFigure);
        drawnow;
        try
          [results,niter,nbatch] = evrishapley(app.calx,app.x,app.model,so);
          app.shap = results.shap;
          app.baseprediction = results.baseprediction;
          app.vargroups = results.vargroups;
          app.explainpred = results.explainpred;
          app.niter = niter;
          app.nbatches = nbatch;
          app.shapoptions = so;
          app.SpawnPlot();
          app.Update();
          app.UpdatePlot();
          % Provide tip for looking at results.
          evritip('ShapleyValuesResults',...
            ['Peruse through the results by utilizing the Plot Settings panel. '...
            'Switch between Model and Sample explanations. '...
            'Different Plot Types are available depending on the explanation type. '...
            'Save the Shapley Values by clicking on the ''Save'' button.'],...
            0);
        catch ME
          if contains(ME.message,'Aborted')
            evrierrordlg(ME.message);
          else
            evrierrordlg([encode(ME.stack) newline ME.message]);
          end
          set(app.ExecuteButton,'Enable','on');
          set(app.UIFigure,'Pointer','arrow');
        end
      end
      set(app.ExecuteButton,'Enable','on');
      set(app.UIFigure,'Pointer','arrow');
      app.Update();
    end

    % Value changed function: PlotType
    function PlotTypeValueChanged(app, event)
      app.UpdatePlot();
    end

    % Value changed function: PredictorDropdown
    function PredictorDropdownValueChanged(app, event)
      app.UpdatePlot();
    end

    % Value changed function: SampleDropdown
    function SampleDropdownValueChanged(app, event)
      app.UpdatePlot();
    end

    % Selection changed function: ExplainButtonGroup
    function ExplainButtonGroupSelectionChanged(app, event)
      % set the default plot types
      
      if app.modelrb.Value
        app.PlotType.Items = {'Line' 'Line+points' 'Scatter' 'Heatmap' 'Beeswarm' 'Bar'};
      elseif app.samplerb.Value
        app.PlotType.Items = {'Bar'};
      end
      app.UpdatePlot();
    end

    % Button pushed function: SelectSamplesButton
    function SelectSamplesButtonPushed(app, event)
      % do manual selection of samples to be explained
      
      if ~isempty(app.x)
        addtoolbar = {'plot'       'dseplotdata'        'editds(''viewplot'',gcbf,[],guidata(gcbf))'     'enable' 'View Plot of Data'    'off' 'push'
                      'ok'       'accept'        'evrishapleyGetExplanationSamples(gcbf)'                                    'enable' 'Accept and close'    'on' 'push'
                      };
        %dso = editds(app.x,'mode',1,'toolbar',addtoolbar);
        dso = editds(app.x,'invisible','toolbar',addtoolbar);
        setappdata(dso,'editfields',{'include'});
        setappdata(dso,'disallowedmodes',[-1 0 2]);
        editds('initialize',dso);
        editds('setmode',dso,1);
        sdata = getshareddata(dso);
        evritip('ShapleyValuesSamples',...
                ['Select Samples to explain the Model by modifying the ''Include'' column. '...
                 'Click the checkbox to include individual samples. '...
                 'Right-click on the ''Incl.'' tab to make bulk include changes on samples. '...
                 'Once done selecting samples, click the Green Check button to accept.'],...
                 0);
        waitfor(dso,'Visible','off');
        newdso = sdata{1}.object;
        delete(dso);
        if ~isempty(newdso)
          newinclude = newdso.include{1};
          app.xinclude = newinclude;
          app.Update();
        end
      end
    end

    % Menu selected function: SaveResultsMenu
    function SaveButtonPushed(app, event)
      % save shapley results
      
      shapley.shap = app.shap;
      shapley.explainpred = app.explainpred;
      shapley.baseprediction = app.baseprediction;
      shapley.vargroups = app.vargroups;
      shapley.calx = app.calx;
      shapley.x = app.x;
      shapley.model = app.model;
      shapley.shapoptions = app.shapoptions;
      varname = ['shapley_' app.timestamp];
      svdlgpls(shapley,'Save Shapley Values',varname);
    end

    % Value changed function: SampleOrderDropDown
    function SampleOrderDropDownValueChanged(app, event)
      app.UpdatePlot()
    end

    % Menu selected function: ClearXButtonMenu
    function ClearXButtonMenuSelected(app, event)
      app.x = [];
      set(app.XButton,'BackgroundColor',[0.96 0.96 0.96]);
      app.XButton.Tooltip = 'Load in a Dataset or Double';
      app.Update();
    end

    % Menu selected function: ClearModelMenu
    function ClearModelMenuSelected(app, event)
      app.model = [];
      set(app.ModelButton,'BackgroundColor',[0.96 0.96 0.96]);
      app.ModelButton.Tooltip = 'Load in a EVRIModel';
      app.Update();
    end

    % Value changed function: scalebox
    function ScaleboxValueChanged(app, event)
      app.UpdatePlot();
    end

    % Button pushed function: loadhelpbutton
    function LoadHelpbuttonButtonPushed(app, event)
      web('https://www.wiki.eigenvector.com/index.php?title=Shapleygui#Load_Data_and_Model')
      msg = ['Load in Calibration data, Explanation data, and a calibrated model. Shapley Values explain the predictions'...
            ' on the explanation samples. The Explanation X is used to try' ...
            ' to get a sense as to how the model comes up with its predictions. ' ...
            'These samples can be from either calibration or the validation set.'];
      evrimsgbox(msg);
    end

    % Button pushed function: settingshelpbutton
    function SettingsHelpbuttonButtonPushed(app, event)
      web('https://www.wiki.eigenvector.com/index.php?title=Shapleygui#Shapley_Value_Settings');
    end

    % Button pushed function: plothelpbutton
    function PlotHelpbuttonButtonPushed(app, event)
      web('https://www.wiki.eigenvector.com/index.php?title=Shapleygui#Plot_Settings')
    end

    % Window button motion function: UIFigure
    function UIFigureWindowButtonMotion(app, event)
%       cp = app.UIFigure.CurrentPoint;
%       [buttons,buttontext] = app.GetButtonInfo();
%       ishovering = cellfun(@(x) app.IsHoveringOver(cp,x),buttons);
%       if any(ishovering) && sum(ishovering)==1
%         curhoveroverind = find(ishovering);
%         buttonhovered = buttons{curhoveroverind};
%         hoverpos = app.GetComponentPos(buttonhovered,buttonhovered.Position([1,2]));
%         done = 0;
%         hoverpos = buttonhovered.Position([1,2]);
%         componentcopy = buttonhovered;
%         while ~done
%           [hoverpos,done,componentcopy] = app.GetComponentPos(componentcopy,hoverpos);
%         end
%         
%         hoverpos = [hoverpos app.hovertext.Position([3,4])];
%         % adjust to make the position relative to the entire figure
%         %hoverpos([1,2]) = buttonhovered.Position([1,2]) + buttonhovered.Parent.Position([1,2]);
%         % put the bottom left corner of the text in the bottom right of the component
%         hoverpos(1) = hoverpos(1) + buttonhovered.Position(3) - 4;
%         hoverpos(2) = hoverpos(2) + 4;
%         % y shift needed?
%         hoverheight = hoverpos(2) + hoverpos(4);
%         if hoverheight >= app.UIFigure.Position(4)
%           hoverpos(2) = hoverpos(2) - hoverpos(4);
%         end
%         % x shift needed?
%         hoverwidth = hoverpos(1) + hoverpos(3);
%         if hoverwidth>=app.UIFigure.Position(3)
%           hoverpos(1) = hoverpos(1) - hoverpos(3) - buttonhovered.Position(3) + 8;
%         end
%         app.hovertext.Position = hoverpos;
%         app.hovertext.Value = buttontext{curhoveroverind};
%         app.hovertext.Visible = "on";
%       else
%         app.hovertext.Visible = "off";
%       end
    end

    % Menu selected function: ClearResultsMenu
    function ClearResultsMenuSelected(app, event)
      % set components to default colors and values
      
      set(app.SelectSamplesButton,'BackgroundColor',[0.96 0.96 0.96]);
      set(app.GroupVariablesButton,'BackgroundColor',[0.96 0.96 0.96]);
      set(app.SampleDropdown,'Items',{'1'});
      set(app.PredictorDropdown,'Items',{'Predictor 1'});
      set(app.SampleOrderDropDown,'Items',{'Linear Index'});
      set(app.scalebox,'Value',0);
      
      % set properties to be empty
      app.shap = [];
      app.baseprediction = [];
      app.niter = [];
      app.nbatches = [];
      app.vargroups = [];
      app.vargrouplabels = [];
      app.vargrouplabel = [];
      app.xticks = [];
      app.xticklabels = [];
      app.xaxislabel = [];
      app.explainpred = [];
      if ishandle(app.shapplot)
        close(app.shapplot)
      end
      app.Update()
    end

    % Callback function: CalXButton, LoadCalXMenu
    function CalXButtonPushed(app, event)
      app.hovertext.Visible = 'off';
      [rawdata,name,~] = lddlgpls({'double' 'dataset'},'Load Calibration X');
      if ~isempty(rawdata)
        if ~isdataset(rawdata)
          rawdata = dataset(rawdata);
        end
        app.calx = rawdata;
        set(app.CalXButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
        txt = {};
        txt = {txt{:} name};
        txt = {txt{:} ['Size: [' num2str(size(app.calx)) ']']};
        app.CalXButton.Tooltip = txt;
        app.Update();
      else
      end
    end

    % Menu selected function: ClearCalXMenu
    function ClearCalXButtonMenuSelected(app, event)
      app.calx = [];
      set(app.CalXButton,'BackgroundColor',[0.96 0.96 0.96]);
      app.CalXButton.Tooltip = 'Load in a Dataset or Double';
      app.Update();
    end

    % Menu selected function: CloseWindowMenu
    function CloseWindowMenuSelected(app, event)
      if ~isempty(app.shapplot)
        if ishandle(app.shapplot)
          close(app.shapplot)
        end
      end
      close(app.UIFigure);
    end

    % Menu selected function: ShowHelpMenu
    function ShowHelpMenuSelected(app, event)
      web('https://www.wiki.eigenvector.com/index.php?title=Shapleygui')
    end

    % Menu selected function: LoadResultsMenu
    function LoadResultsMenuSelected(app, event)
      [rawdata,~,~] = lddlgpls({'struct'},'Load Shapley Results');
      % check if it has all fields
      fields = {'shap' 'explainpred' 'baseprediction' 'vargroups' 'calx' 'x' 'model' 'shapoptions'};
      for i=1:length(fields)
        if isfieldcheck(rawdata,['.' fields{i}])
          app.(fields{i}) = rawdata.(fields{i});
        else
          evrierrordlg(['Missing information for ' fields{i} ' in the results structure.']);
          return
        end
      end
      % set buttons to green
      if ~isdataset(rawdata.calx)
        name = 'x';
      else
        name = rawdata.calx.name;
      end
      app.LoadCalXButton(rawdata.calx,name);
      if ~isdataset(rawdata.x)
        name = 'x';
      else
        name = rawdata.x.name;
      end
      app.LoadXButton(rawdata.x,name);
      app.LoadModelButton(rawdata.model);
      set(app.SelectSamplesButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
      
      % fill in info from the shapley options
      if isempty(app.shapoptions.variablegroups)
        %user used interval width
        app.IntervalWidthButton.Value = 1;
        app.intwidth_field.Value = app.shapoptions.int_width;
      else
        app.CreateGroupsButton.Value = 1;
        %TODO
      end
      
      
      app.Update();
      app.UpdatePlot();
    end

    % Button pushed function: OpenModelButton
    function OpenModelButtonClicked(app, event)
      % open model in analysis
      gui = evrigui('analysis');
      gui.setModel(app.model);
    end

    % Menu selected function: ClearDataandModelMenu
    function ClearDataandModelMenuSelected(app, event)
      app.ClearXButtonMenuSelected([]);
      app.ClearCalXButtonMenuSelected([]);
      app.ClearModelMenuSelected([]);
    end

    % Menu selected function: ClearAllMenu
    function ClearAllMenuSelected(app, event)
      app.ClearDataandModelMenuSelected([]);
      app.ClearResultsMenuSelected([])
    end

    % Button pushed function: GroupVariablesButton
    function GroupVariablesButtonPushed(app, event)
      % manually select the variables to be grouped for shapley
      
      btnlist = {
        'selectx'      'selectonly'         'plotgui(''makeselection'',gcbf,''doShapGroups'')'   'enable' 'Choose Variables to Group'      'off'   'push'
        %'selectxplus'  'selectadd'          'plotgui(''makeselection'',gcbf)'   'enable' 'Add to Included'            'off'   'push'
        %'selectxminus' 'selectsub'          'plotgui(''makeselection'',gcbf)'   'enable' 'Subtract from Included'     'off'   'push'
        'viewclasses'  'pgtviewclasses'     'plotgui(''viewclassmenu'',gcbf)'   'enable' 'View Classes/Select Class'  'on'    'push'
        'ok'           'accept'             'close(gcbf)'                       'enable' 'Accept and close'           'on'    'push'
        };
      
      myid = setshareddata(app.UIFigure,app.x);
      fig = plotgui('new',myid,...
        'plotby',0,...
        'viewclasses',1,...
        'selectionmode','xs',...
        'pgtoolbar',0,...
        'viewexcludeddata',1);
      toolbar(fig,'',btnlist,'selectiontoolbar');
      
      while ishandle(fig)
        uiwait(fig);
      end
      dat = myid.object;
      app.x = dat;
      shapGroups = dat.class{2,end};
      if ~isempty(shapGroups)
        uniqueGroupNums = unique(shapGroups);
        for i = uniqueGroupNums
          shapGroupInds{i} = find(shapGroups==uniqueGroupNums(i));
        end
        app.userdefinedgroups = shapGroupInds;
        set(app.GroupVariablesButton,'BackgroundColor',[0.5647 0.9333 0.5647]);
      end
    end

    % Selection changed function: VariablesRbGroup
    function VariablesRbGroupSelectionChanged(app, event)
      if app.ready
        if app.IntervalWidthButton.Value
          app.intwidth_field.Enable = 'on';
          app.GroupVariablesButton.Enable = 'off';
        elseif app.CreateGroupsButton.Value
          app.intwidth_field.Enable = 'off';
          app.GroupVariablesButton.Enable = 'on';
        end
      else
        app.intwidth_field.Enable = 'off';
        app.GroupVariablesButton.Enable = 'off';
      end
    end

    % Close request function: UIFigure
    function UIFigureCloseRequest(app, event)
      if ~isempty(app.shapplot)
        if ishandle(app.shapplot)
          close(app.shapplot);
        end
      end
      delete(app)
    end

    % Menu selected function: LoadfromCalibrationXMenu
    function LoadfromCalibrationXMenuSelected(app, event)
      app.LoadXButton(app.calx,app.calx.name);
      app.Update();
    end

    % Selection changed function: SamplesRbGroup
    function SamplesRbGroupSelectionChanged(app, event)
      app.Update();
    end

    % Menu selected function: SpawnStaticViewMenu
    function SpawnStaticViewMenuSelected(app, event)
      app.UpdatePlot()
      % get position of shapplot to slightly offset the static plot
      pos = app.shapplot.Position;
      % get nonempty axes
      nonemptyaxs = {};
      if ~isempty(app.ax) && ishandle(app.ax); nonemptyaxs = {app.ax nonemptyaxs{:}};end
      if ~isempty(app.predax) && ishandle(app.predax); nonemptyaxs = {app.predax nonemptyaxs{:}};end
      if isempty(nonemptyaxs); return; end
      staticfig = figure('Visible','on');
      % copy contents of original plot to static plot
      cellfun(@(x) copyobj([x x.Legend],staticfig),nonemptyaxs);
      if length(nonemptyaxs)==2
        % link the plots
        axs = findall(staticfig,'type','axes');
        linkaxes(axs(:)','x');
      end
      pos(1) = pos(1) - 10;
      pos(2) = pos(2) - 10;
      set(staticfig,'Position',pos)
      set(staticfig,'Visible','on');
    end

    % Menu selected function: ResultsMenu
    function ResultsMenuSelected(app, event)
      if ~isempty(app.shap)
        app.UpdatePlot()
      else
        evrierrordlg('Shapley Values have not been calculated.')
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'Shapley Values';
      app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
      app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
      app.FileMenu = uimenu(app.UIFigure);
      app.FileMenu.Text = 'File';
      app.LoadResultsMenu = uimenu(app.FileMenu);
      app.LoadResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadResultsMenuSelected, true);
      app.LoadResultsMenu.Text = 'Load Results';
      app.SaveResultsMenu = uimenu(app.FileMenu);
      app.SaveResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
      app.SaveResultsMenu.Enable = 'off';
      app.SaveResultsMenu.Text = 'Save Results';
      app.ClearResultsMenu = uimenu(app.FileMenu);
      app.ClearResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearResultsMenuSelected, true);
      app.ClearResultsMenu.Text = 'Clear Results';
      app.ClearDataandModelMenu = uimenu(app.FileMenu);
      app.ClearDataandModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearDataandModelMenuSelected, true);
      app.ClearDataandModelMenu.Text = 'Clear Data and Model';
      app.ClearAllMenu = uimenu(app.FileMenu);
      app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
      app.ClearAllMenu.Text = 'Clear All';
      app.CloseWindowMenu = uimenu(app.FileMenu);
      app.CloseWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseWindowMenuSelected, true);
      app.CloseWindowMenu.Text = 'Close Window';
      app.ViewMenu = uimenu(app.UIFigure);
      app.ViewMenu.Text = 'View';
      app.SpawnStaticViewMenu = uimenu(app.ViewMenu);
      app.SpawnStaticViewMenu.MenuSelectedFcn = createCallbackFcn(app, @SpawnStaticViewMenuSelected, true);
      app.SpawnStaticViewMenu.Text = 'Spawn Static View';
      app.ResultsMenu = uimenu(app.ViewMenu);
      app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenuSelected, true);
      app.ResultsMenu.Text = 'Results';
      app.HelpMenu = uimenu(app.UIFigure);
      app.HelpMenu.Text = 'Help';
      app.ShowHelpMenu = uimenu(app.HelpMenu);
      app.ShowHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowHelpMenuSelected, true);
      app.ShowHelpMenu.Text = 'Show Help';
      app.GridLayout = uigridlayout(app.UIFigure);
      app.ShapleyValueSettingsPanel = uipanel(app.GridLayout);
      app.ShapleyValueSettingsPanel.Title = '2. Shapley Value Settings';
      app.GridLayout2 = uigridlayout(app.ShapleyValueSettingsPanel);
      app.TabGroup = uitabgroup(app.GridLayout2);
      app.GroupVariablesTab = uitab(app.TabGroup);
      app.GroupVariablesTab.Title = 'Group Variables';
      app.GridLayout3 = uigridlayout(app.GroupVariablesTab);
      app.VariablesRbGroup = uibuttongroup(app.GridLayout3);
      app.VariablesRbGroup.SelectionChangedFcn = createCallbackFcn(app, @VariablesRbGroupSelectionChanged, true);
      app.IntervalWidthButton = uiradiobutton(app.VariablesRbGroup);
      app.IntervalWidthButton.Enable = 'off';
      app.IntervalWidthButton.Tooltip = {'Group variables by interval width. Provide the interval width of the variables'};
      app.IntervalWidthButton.Text = 'Interval Width';
      app.IntervalWidthButton.Value = true;
      app.CreateGroupsButton = uiradiobutton(app.VariablesRbGroup);
      app.CreateGroupsButton.Enable = 'off';
      app.CreateGroupsButton.Tooltip = {'Group variables manually'};
      app.CreateGroupsButton.Text = 'Create Groups';
      app.GridLayout4 = uigridlayout(app.GridLayout3);
      app.intwidth_field = uieditfield(app.GridLayout4, 'numeric');
      app.intwidth_field.Limits = [1 Inf];
      app.intwidth_field.RoundFractionalValues = 'on';
      app.intwidth_field.Enable = 'off';
      app.intwidth_field.Tooltip = {''};
      app.intwidth_field.Value = 10;
      app.GroupVariablesButton = uibutton(app.GridLayout4, 'push');
      app.GroupVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @GroupVariablesButtonPushed, true);
      app.GroupVariablesButton.Enable = 'off';
      app.GroupVariablesButton.Tooltip = {'Manually group variables'};
      app.GroupVariablesButton.Text = 'Group Variables';
      app.SelectSamplesTab = uitab(app.TabGroup);
      app.SelectSamplesTab.Title = 'Select Samples';
      app.GridLayout5 = uigridlayout(app.SelectSamplesTab);
      app.SelectSamplesButton = uibutton(app.GridLayout5, 'push');
      app.SelectSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSamplesButtonPushed, true);
      app.SelectSamplesButton.Enable = 'off';
      app.SelectSamplesButton.Tooltip = {'Select Samples from Explanation X to generate Shapley Values for'};
      app.SelectSamplesButton.Text = 'Select';
      app.SelectSamplestoExplainModelLabel = uilabel(app.GridLayout5);
      app.SelectSamplestoExplainModelLabel.Text = 'Select Samples to Explain Model';
      app.SamplesRbGroup = uibuttongroup(app.GridLayout5);
      app.SamplesRbGroup.SelectionChangedFcn = createCallbackFcn(app, @SamplesRbGroupSelectionChanged, true);
      app.IncludeAllSamplesButton = uiradiobutton(app.SamplesRbGroup);
      app.IncludeAllSamplesButton.Text = 'Include All Samples';
      app.ManualSelectionButton = uiradiobutton(app.SamplesRbGroup);
      app.ManualSelectionButton.Text = 'Manual Selection';
      app.ManualSelectionButton.Value = true;
      app.HelpTab = uitab(app.TabGroup);
      app.HelpTab.Title = 'Help';
      app.GridLayout6 = uigridlayout(app.HelpTab);
      app.settingshelpbutton = uibutton(app.GridLayout6, 'push');
      app.settingshelpbutton.ButtonPushedFcn = createCallbackFcn(app, @SettingsHelpbuttonButtonPushed, true);
      app.settingshelpbutton.Text = 'Help';
      app.ExecuteButton = uibutton(app.GridLayout2, 'push');
      app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
      app.ExecuteButton.Enable = 'off';
      app.ExecuteButton.Tooltip = {'Calculate Shapley Values'};
      app.ExecuteButton.Text = 'Execute';
      app.LoadDataandModelPanel = uipanel(app.GridLayout);
      app.LoadDataandModelPanel.Title = '1. Load Data and Model';
      app.GridLayout7 = uigridlayout(app.LoadDataandModelPanel);
      app.CalXButton = uibutton(app.GridLayout7, 'push');
      app.CalXButton.ButtonPushedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.CalXButton.Tooltip = {'Load in a Dataset or Double'};
      app.CalXButton.Text = 'Calibration X';
      app.XButton = uibutton(app.GridLayout7, 'push');
      app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
      app.XButton.Tooltip = {'Load in a Dataset or Double'};
      app.XButton.Text = 'Explanation X';
      app.ModelButton = uibutton(app.GridLayout7, 'push');
      app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButtonValueChanged, true);
      app.ModelButton.Tooltip = {'Load in a EVRIModel'};
      app.ModelButton.Text = 'Model';
      app.loadhelpbutton = uibutton(app.GridLayout7, 'push');
      app.loadhelpbutton.ButtonPushedFcn = createCallbackFcn(app, @LoadHelpbuttonButtonPushed, true);
      app.loadhelpbutton.Text = '?';
      app.OpenModelButton = uibutton(app.GridLayout7, 'push');
      app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonClicked, true);
      app.OpenModelButton.Enable = 'off';
      app.OpenModelButton.Tooltip = {'Open Model in Analysis'};
      app.OpenModelButton.Text = '';
      app.PlotSettingsPanel = uipanel(app.GridLayout);
      app.PlotSettingsPanel.Title = '3. Plot Settings';
      app.GridLayout8 = uigridlayout(app.PlotSettingsPanel);
      app.ExplanationTypeLabel = uilabel(app.GridLayout8);
      app.ExplanationTypeLabel.Text = 'Explanation Type';
      app.ExplainButtonGroup = uibuttongroup(app.GridLayout8);
      app.ExplainButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExplainButtonGroupSelectionChanged, true);
      app.ExplainButtonGroup.Tooltip = {'Explain predictions for each sample or for the overall model'};
      app.ExplainButtonGroup.Enable = 'off';
      app.modelrb = uiradiobutton(app.ExplainButtonGroup);
      app.modelrb.Tooltip = {'Show overall Model explanation'};
      app.modelrb.Text = 'Model';
      app.modelrb.Value = true;
      app.samplerb = uiradiobutton(app.ExplainButtonGroup);
      app.samplerb.Tooltip = {'Show Sample explanation'};
      app.samplerb.Text = 'Sample';
      app.SampleDropdown = uidropdown(app.ExplainButtonGroup);
      app.SampleDropdown.Items = {'1'};
      app.SampleDropdown.ValueChangedFcn = createCallbackFcn(app, @SampleDropdownValueChanged, true);
      app.SampleDropdown.Tooltip = {'Select a sample to show its explanation of the prediction'};
      app.SampleDropdown.Value = '1';
      app.PredictorDropdown = uidropdown(app.GridLayout8);
      app.PredictorDropdown.Items = {'Predictor 1'};
      app.PredictorDropdown.ValueChangedFcn = createCallbackFcn(app, @PredictorDropdownValueChanged, true);
      app.PredictorDropdown.Enable = 'off';
      app.PredictorDropdown.Tooltip = {'Select the output of the Model to explain'};
      app.PredictorDropdown.Value = 'Predictor 1';
      app.SelectPredictorDropDownLabel = uilabel(app.GridLayout8);
      app.SelectPredictorDropDownLabel.Text = 'Select Predictor';
      app.PlotType = uidropdown(app.GridLayout8);
      app.PlotType.Items = {'Line', 'Line+points', 'Scatter', 'Heatmap', 'Beeswarm', 'Bar'};
      app.PlotType.ValueChangedFcn = createCallbackFcn(app, @PlotTypeValueChanged, true);
      app.PlotType.Enable = 'off';
      app.PlotType.Tooltip = {'Change plot type'};
      app.PlotType.Value = 'Line';
      app.PlotTypeDropDownLabel = uilabel(app.GridLayout8);
      app.PlotTypeDropDownLabel.Text = 'Plot Type';
      app.SampleOrderDropDown = uidropdown(app.GridLayout8);
      app.SampleOrderDropDown.Items = {'Linear Index'};
      app.SampleOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleOrderDropDownValueChanged, true);
      app.SampleOrderDropDown.Enable = 'off';
      app.SampleOrderDropDown.Tooltip = {'Change ordering of samples. Only applied in the Heatmap Plot'};
      app.SampleOrderDropDown.Value = 'Linear Index';
      app.SampleOrderDropDownLabel = uilabel(app.GridLayout8);
      app.SampleOrderDropDownLabel.Text = 'Sample Order';
      app.plothelpbutton = uibutton(app.GridLayout8, 'push');
      app.plothelpbutton.ButtonPushedFcn = createCallbackFcn(app, @PlotHelpbuttonButtonPushed, true);
      app.plothelpbutton.Text = '?';
      app.scalebox = uicheckbox(app.GridLayout8);
      app.scalebox.ValueChangedFcn = createCallbackFcn(app, @ScaleboxValueChanged, true);
      app.scalebox.Enable = 'off';
      app.scalebox.Tooltip = {'Autoscale Samples in Beeswarm Plot'};
      app.scalebox.Text = '';
      app.XButtonContextMenu = uicontextmenu(app.UIFigure);
      % Assign app.XButtonContextMenu
      app.XButton.ContextMenu = app.XButtonContextMenu;
      app.LoadXButtonMenu = uimenu(app.XButtonContextMenu);
      app.LoadXButtonMenu.MenuSelectedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
      app.LoadXButtonMenu.Text = 'Load Data';
      app.ClearXButtonMenu = uimenu(app.XButtonContextMenu);
      app.ClearXButtonMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearXButtonMenuSelected, true);
      app.ClearXButtonMenu.Text = 'Clear Data';
      app.LoadfromCalibrationXMenu = uimenu(app.XButtonContextMenu);
      app.LoadfromCalibrationXMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromCalibrationXMenuSelected, true);
      app.LoadfromCalibrationXMenu.Enable = 'off';
      app.LoadfromCalibrationXMenu.Text = 'Load from Calibration X';
      app.CalXButtonContextMenu = uicontextmenu(app.UIFigure);
      % Assign app.CalXButtonContextMenu
      app.CalXButton.ContextMenu = app.CalXButtonContextMenu;
      app.LoadCalXMenu = uimenu(app.CalXButtonContextMenu);
      app.LoadCalXMenu.MenuSelectedFcn = createCallbackFcn(app, @CalXButtonPushed, true);
      app.LoadCalXMenu.Text = 'Load Data';
      app.ClearCalXMenu = uimenu(app.CalXButtonContextMenu);
      app.ClearCalXMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearCalXButtonMenuSelected, true);
      app.ClearCalXMenu.Text = 'Clear Data';
      app.ModelButtonContextMenu = uicontextmenu(app.UIFigure);
      % Assign app.ModelButtonContextMenu
      app.ModelButton.ContextMenu = app.ModelButtonContextMenu;
      app.LoadModelMenu = uimenu(app.ModelButtonContextMenu);
      app.LoadModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelButtonValueChanged, true);
      app.LoadModelMenu.Text = 'Load Model';
      app.ClearModelMenu = uimenu(app.ModelButtonContextMenu);
      app.ClearModelMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearModelMenuSelected, true);
      app.ClearModelMenu.Text = 'Clear Model';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = shapleygui

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      % Execute the startup function
      runStartupFcn(app, @StartupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end